classdef ExportConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        CancelButton    matlab.ui.control.Button
        ConfirmButton   matlab.ui.control.Button
        LeftNegativeActuatorIDEditField  matlab.ui.control.NumericEditField
        LeftNegativeActuatorIDEditFieldLabel  matlab.ui.control.Label
        RightNegativeActuatorIDEditField  matlab.ui.control.NumericEditField
        RightNegativeActuatorIDEditFieldLabel  matlab.ui.control.Label
        LeftPositiveActuatorIDEditField  matlab.ui.control.NumericEditField
        LeftPositiveActuatorIDEditFieldLabel  matlab.ui.control.Label
        JointNameLabel  matlab.ui.control.Label
        RightPositiveActuatorIDEditField  matlab.ui.control.NumericEditField
        RightPositiveActuatorIDEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
        current_joint % Description
    end
    
    methods (Access = private)
        
        function success = CreateSimConfigFile(app)
            %FUNCTION - Creates a .json file of the current joint with configuration parameters
            %needed for Simulating the Equilibrium Point Controller

            success = true;

            joint.name = app.current_joint.name;
            if app.current_joint.name == "Wrist Pitch"
                joint.motor_model = "MX-64";
            else
                joint.motor_model = "MX-106";
            end
            joint.actuators = 4;
            if app.current_joint.name == "Shoulder Pitch"
                if app.current_joint.right.tendons.pos_tendon == 'r'
                    joint.right.pos_tendon.l_free = app.current_joint.right.tendons.l_free_red;
                    joint.right.neg_tendon.l_free = app.current_joint.right.tendons.l_free_blue;
                else
                    joint.right.pos_tendon.l_free = app.current_joint.right.tendons.l_free_blue;
                    joint.right.neg_tendon.l_free = app.current_joint.right.tendons.l_free_red;
                end
                if app.current_joint.left.tendons.pos_tendon == 'r'
                    joint.left.pos_tendon.l_free = app.current_joint.left.tendons.l_free_red;
                    joint.left.neg_tendon.l_free = app.current_joint.left.tendons.l_free_blue;
                else
                    joint.left.pos_tendon.l_free = app.current_joint.left.tendons.l_free_blue;
                    joint.left.neg_tendon.l_free = app.current_joint.left.tendons.l_free_red;
                end
            else
                joint.right.pos_tendon.l_free = app.current_joint.right.tendons.l_free;
                joint.right.neg_tendon.l_free = app.current_joint.right.tendons.l_free;
                joint.left.pos_tendon.l_free = app.current_joint.left.tendons.l_free;
                joint.left.neg_tendon.l_free = app.current_joint.left.tendons.l_free;
            end

            joint.right.pos_tendon.r_active = app.current_joint.right.tendons.r_active;
            joint.right.neg_tendon.r_active = app.current_joint.right.tendons.r_active;
            joint.right.pos_tendon.r_passive = app.current_joint.right.tendons.r_passive;
            joint.right.neg_tendon.r_passive = app.current_joint.right.tendons.r_passive;
            joint.right.pos_tendon.r_tendon = app.current_joint.right.tendons.tendon_diameter/2;
            joint.right.neg_tendon.r_tendon = app.current_joint.right.tendons.tendon_diameter/2;
            joint.right.pos_tendon.id = app.RightPositiveActuatorIDEditField.Value;
            joint.right.neg_tendon.id = app.RightNegativeActuatorIDEditField.Value;

            
            joint.left.pos_tendon.r_active = app.current_joint.left.tendons.r_active;
            joint.left.neg_tendon.r_active = app.current_joint.left.tendons.r_active;
            joint.left.pos_tendon.r_passive = app.current_joint.left.tendons.r_passive;
            joint.left.neg_tendon.r_passive = app.current_joint.left.tendons.r_passive;
            joint.left.pos_tendon.r_tendon = app.current_joint.left.tendons.tendon_diameter/2;
            joint.left.neg_tendon.r_tendon = app.current_joint.left.tendons.tendon_diameter/2;
            joint.left.pos_tendon.id = app.LeftPositiveActuatorIDEditField.Value;
            joint.left.neg_tendon.id = app.LeftNegativeActuatorIDEditField.Value;

            if app.current_joint.right.tendons.pos_tendon == 'r'
                joint.right.pos_tendon.alpha_passive_zero = app.current_joint.right.tendons.alpha_passive_red;
                joint.right.neg_tendon.alpha_passive_zero = app.current_joint.right.tendons.alpha_passive_blue;
                joint.right.pos_tendon.l_relaxed = app.current_joint.right.tendons.red_tendon_length/(1+app.current_joint.right.inner_pulley.pretensioning/100);
                joint.right.neg_tendon.l_relaxed = app.current_joint.right.tendons.blue_tendon_length/(1+app.current_joint.right.inner_pulley.pretensioning/100);
                if app.current_joint.right.inner_pulley.tendon_id == 'r'
%                     joint.right.pos_tendon.alpha_active_zero = app.current_joint.right.inner_pulley.alpha_active;
%                     joint.right.neg_tendon.alpha_active_zero = app.current_joint.right.outer_pulley.alpha_active;
                    joint.right.pos_tendon.alpha_active_zero = app.current_joint.right.inner_pulley.alpha_active_mounted(app.current_joint.right.inner_pulley.pulley_numbers_switch+1);
                    joint.right.neg_tendon.alpha_active_zero = app.current_joint.right.outer_pulley.alpha_active_mounted(app.current_joint.right.outer_pulley.pulley_numbers_switch+1);
                elseif app.current_joint.right.inner_pulley.tendon_id == 'b'
%                     joint.right.pos_tendon.alpha_active_zero = app.current_joint.right.outer_pulley.alpha_active;
%                     joint.right.neg_tendon.alpha_active_zero = app.current_joint.right.inner_pulley.alpha_active;
                    joint.right.pos_tendon.alpha_active_zero = app.current_joint.right.outer_pulley.alpha_active_mounted(app.current_joint.right.outer_pulley.pulley_numbers_switch+1);
                    joint.right.neg_tendon.alpha_active_zero = app.current_joint.right.inner_pulley.alpha_active_mounted(app.current_joint.right.inner_pulley.pulley_numbers_switch+1);
                end
            elseif app.current_joint.right.tendons.pos_tendon == 'b'
                joint.right.pos_tendon.alpha_passive_zero = app.current_joint.right.tendons.alpha_passive_blue;
                joint.right.neg_tendon.alpha_passive_zero = app.current_joint.right.tendons.alpha_passive_red;
                joint.right.pos_tendon.l_relaxed = app.current_joint.right.tendons.blue_tendon_length/(1+app.current_joint.right.inner_pulley.pretensioning/100);
                joint.right.neg_tendon.l_relaxed = app.current_joint.right.tendons.red_tendon_length/(1+app.current_joint.right.inner_pulley.pretensioning/100);
                if app.current_joint.right.inner_pulley.tendon_id == 'b'
%                     joint.right.pos_tendon.alpha_active_zero = app.current_joint.right.inner_pulley.alpha_active;
%                     joint.right.neg_tendon.alpha_active_zero = app.current_joint.right.outer_pulley.alpha_active;
                    joint.right.pos_tendon.alpha_active_zero = app.current_joint.right.inner_pulley.alpha_active_mounted(app.current_joint.right.inner_pulley.pulley_numbers_switch+1);
                    joint.right.neg_tendon.alpha_active_zero = app.current_joint.right.outer_pulley.alpha_active_mounted(app.current_joint.right.outer_pulley.pulley_numbers_switch+1);
                elseif app.current_joint.right.inner_pulley.tendon_id == 'r'
%                     joint.right.pos_tendon.alpha_active_zero = app.current_joint.right.outer_pulley.alpha_active;
%                     joint.right.neg_tendon.alpha_active_zero = app.current_joint.right.inner_pulley.alpha_active;
                    joint.right.pos_tendon.alpha_active_zero = app.current_joint.right.outer_pulley.alpha_active_mounted(app.current_joint.right.outer_pulley.pulley_numbers_switch+1);
                    joint.right.neg_tendon.alpha_active_zero = app.current_joint.right.inner_pulley.alpha_active_mounted(app.current_joint.right.inner_pulley.pulley_numbers_switch+1);
                end
            end


            if app.current_joint.left.tendons.pos_tendon == 'r'
                joint.left.pos_tendon.alpha_passive_zero = app.current_joint.left.tendons.alpha_passive_red;
                joint.left.neg_tendon.alpha_passive_zero = app.current_joint.left.tendons.alpha_passive_blue;
                joint.left.pos_tendon.l_relaxed = app.current_joint.left.tendons.red_tendon_length/(1+app.current_joint.right.inner_pulley.pretensioning/100);
                joint.left.neg_tendon.l_relaxed = app.current_joint.left.tendons.blue_tendon_length/(1+app.current_joint.right.inner_pulley.pretensioning/100);
                if app.current_joint.left.inner_pulley.tendon_id == 'r'
%                     joint.left.pos_tendon.alpha_active_zero = app.current_joint.left.inner_pulley.alpha_active;
%                     joint.left.neg_tendon.alpha_active_zero = app.current_joint.left.outer_pulley.alpha_active;
                    joint.left.pos_tendon.alpha_active_zero = app.current_joint.left.inner_pulley.alpha_active_mounted(app.current_joint.left.inner_pulley.pulley_numbers_switch+1);
                    joint.left.neg_tendon.alpha_active_zero = app.current_joint.left.outer_pulley.alpha_active_mounted(app.current_joint.left.outer_pulley.pulley_numbers_switch+1);
                elseif app.current_joint.left.inner_pulley.tendon_id == 'b'
%                     joint.left.pos_tendon.alpha_active_zero = app.current_joint.left.outer_pulley.alpha_active;
%                     joint.left.neg_tendon.alpha_active_zero = app.current_joint.left.inner_pulley.alpha_active;
                    joint.left.pos_tendon.alpha_active_zero = app.current_joint.left.outer_pulley.alpha_active_mounted(app.current_joint.left.outer_pulley.pulley_numbers_switch+1);
                    joint.left.neg_tendon.alpha_active_zero = app.current_joint.left.inner_pulley.alpha_active_mounted(app.current_joint.left.inner_pulley.pulley_numbers_switch+1);
                end
            elseif app.current_joint.left.tendons.pos_tendon == 'b'
                joint.left.pos_tendon.alpha_passive_zero = app.current_joint.left.tendons.alpha_passive_blue;
                joint.left.neg_tendon.alpha_passive_zero = app.current_joint.left.tendons.alpha_passive_red;
                joint.left.pos_tendon.l_relaxed = app.current_joint.left.tendons.blue_tendon_length/(1+app.current_joint.right.inner_pulley.pretensioning/100);
                joint.left.neg_tendon.l_relaxed = app.current_joint.left.tendons.red_tendon_length/(1+app.current_joint.right.inner_pulley.pretensioning/100);
                if app.current_joint.left.inner_pulley.tendon_id == 'b'
%                     joint.left.pos_tendon.alpha_active_zero = app.current_joint.left.inner_pulley.alpha_active;
%                     joint.left.neg_tendon.alpha_active_zero = app.current_joint.left.outer_pulley.alpha_active;
                    joint.left.pos_tendon.alpha_active_zero = app.current_joint.left.inner_pulley.alpha_active_mounted(app.current_joint.left.inner_pulley.pulley_numbers_switch+1);
                    joint.left.neg_tendon.alpha_active_zero = app.current_joint.left.outer_pulley.alpha_active_mounted(app.current_joint.left.outer_pulley.pulley_numbers_switch+1);
                elseif app.current_joint.left.inner_pulley.tendon_id == 'r'
%                     joint.left.pos_tendon.alpha_active_zero = app.current_joint.left.outer_pulley.alpha_active;
%                     joint.left.neg_tendon.alpha_active_zero = app.current_joint.left.inner_pulley.alpha_active;
                    joint.left.pos_tendon.alpha_active_zero = app.current_joint.left.outer_pulley.alpha_active_mounted(app.current_joint.left.outer_pulley.pulley_numbers_switch+1);
                    joint.left.neg_tendon.alpha_active_zero = app.current_joint.left.inner_pulley.alpha_active_mounted(app.current_joint.left.inner_pulley.pulley_numbers_switch+1);
                end
            end


            if app.current_joint.name == "Shoulder Yaw"
                joint.motor_init_pos = 0.0;
                joint.fixed_stiffness = 0.7854;
                fid = fopen('sim_config/shoulder_yaw.json', 'w');
            elseif app.current_joint.name == "Shoulder Roll"
                joint.motor_init_pos = 0.0;
                joint.fixed_stiffness = 0.7854;
                fid = fopen('sim_config/shoulder_roll.json', 'w');
            elseif app.current_joint.name == "Shoulder Pitch"
                joint.motor_init_pos = 4.0;
                joint.fixed_stiffness = 0.7854;
                fid = fopen('sim_config/shoulder_pitch.json', 'w');
            elseif app.current_joint.name == "Upperarm Roll"
                joint.motor_init_pos = 2.5;
                joint.fixed_stiffness = 0.7854;
                fid = fopen('sim_config/upperarm_roll.json', 'w');
            elseif app.current_joint.name == "Elbow"
                joint.motor_init_pos = 6.28;
                joint.fixed_stiffness = 0.7854;
                fid = fopen('sim_config/elbow.json', 'w');
            elseif app.current_joint.name == "Wrist Pitch"
                joint.motor_init_pos = 0.0;
                joint.fixed_stiffness = 0.7854;
                fid = fopen('sim_config/wrist_pitch.json', 'w');
            end

            %jsonText = jsonencode(app.appdata, "PrettyPrint",true);
            jsonText = jsonencode(joint, "PrettyPrint",true);
            
            fprintf(fid, '%s', jsonText);
            fclose(fid);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, current_joint)
            app.MainApp = caller;

            app.current_joint = current_joint;

            app.JointNameLabel.Text = app.current_joint.name;

            app.RightPositiveActuatorIDEditField.Value = app.current_joint.right_pos_actuator_id;
            app.RightNegativeActuatorIDEditField.Value = app.current_joint.right_neg_actuator_id;
            app.LeftPositiveActuatorIDEditField.Value = app.current_joint.left_pos_actuator_id;
            app.LeftNegativeActuatorIDEditField.Value = app.current_joint.left_neg_actuator_id;

            %TODO - Fill Edit Fields with saved values in config file
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.CreateSimConfigFile();
            
            app.current_joint.right_pos_actuator_id = app.RightPositiveActuatorIDEditField.Value;
            app.current_joint.right_neg_actuator_id = app.RightNegativeActuatorIDEditField.Value;
            app.current_joint.left_pos_actuator_id = app.LeftPositiveActuatorIDEditField.Value;
            app.current_joint.left_neg_actuator_id = app.LeftNegativeActuatorIDEditField.Value;

            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RightPositiveActuatorIDEditFieldLabel = uilabel(app.UIFigure);
            app.RightPositiveActuatorIDEditFieldLabel.Text = 'Right Positive Actuator ID:';
            app.RightPositiveActuatorIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightPositiveActuatorIDEditField.ValueDisplayFormat = '%.0f';
            app.JointNameLabel = uilabel(app.UIFigure);
            app.JointNameLabel.Text = 'Upperarm Roll';
            app.LeftPositiveActuatorIDEditFieldLabel = uilabel(app.UIFigure);
            app.LeftPositiveActuatorIDEditFieldLabel.Text = 'Left Positive Actuator ID:';
            app.LeftPositiveActuatorIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftPositiveActuatorIDEditField.ValueDisplayFormat = '%.0f';
            app.RightNegativeActuatorIDEditFieldLabel = uilabel(app.UIFigure);
            app.RightNegativeActuatorIDEditFieldLabel.Text = 'Right Negative Actuator ID:';
            app.RightNegativeActuatorIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightNegativeActuatorIDEditField.ValueDisplayFormat = '%.0f';
            app.LeftNegativeActuatorIDEditFieldLabel = uilabel(app.UIFigure);
            app.LeftNegativeActuatorIDEditFieldLabel.Text = 'Left Negative Actuator ID:';
            app.LeftNegativeActuatorIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftNegativeActuatorIDEditField.ValueDisplayFormat = '%.0f';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end