classdef ManufacturingHelp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SleeveCuttingRatioEditField    matlab.ui.control.NumericEditField
        SleeveCuttingRatioLabel        matlab.ui.control.Label
        OuterActivePulleyMountingLengthEditField  matlab.ui.control.NumericEditField
        OuterActivePulleyMountingLengthEditFieldLabel  matlab.ui.control.Label
        InnerActivePulleyMountingLengthEditField  matlab.ui.control.NumericEditField
        InnerActivePulleyMountingLengthEditFieldLabel  matlab.ui.control.Label
        PassivePulleyMountingLengthEditField  matlab.ui.control.NumericEditField
        PassivePulleyMountingLengthEditFieldLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        ExtraSleeveLengthforAssemblyEditField  matlab.ui.control.NumericEditField
        ExtraSleeveLengthforAssemblyLabel  matlab.ui.control.Label
        JointSideLabel                 matlab.ui.control.Label
        OuterPulleyPanel               matlab.ui.container.Panel
        IncludedforAssemblyOuterLabel  matlab.ui.control.Label
        SleeveCuttingLengthOuterLabel  matlab.ui.control.Label
        FilamentCuttingLengthOuterLabel  matlab.ui.control.Label
        InnerPulleyPanel               matlab.ui.container.Panel
        IncludedforAssemblyInnerLabel  matlab.ui.control.Label
        SleeveCuttingLengthInnerLabel  matlab.ui.control.Label
        FilamentCuttingLengthInnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        current_joint % Description
        current_side % Description
        current_inner_pulley % Description
        current_outer_pulley % Description
        inner_length % Description
        inner_pretentioned_length % Description
        inner_sleeve_length % Description
        outer_length % Description
        outer_pretentioned_length % Description
        outer_sleeve_length % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, joint, joint_side)
            app.current_joint = joint;
            app.current_side = joint_side.tendons;
            app.current_inner_pulley = joint_side.inner_pulley;
            app.current_outer_pulley = joint_side.outer_pulley;

            app.JointSideLabel.Text = app.current_joint.name+" - "+app.current_side.name;
            app.InnerActivePulleyMountingLengthEditField.Value = app.current_joint.inner_active_mounting_length;
            app.OuterActivePulleyMountingLengthEditField.Value = app.current_joint.outer_active_mounting_length;
            app.PassivePulleyMountingLengthEditField.Value = app.current_joint.passive_mounting_length;
            app.ExtraSleeveLengthforAssemblyEditField.Value = app.current_joint.sleeve_assembly_extra;
            app.SleeveCuttingRatioEditField.Value = app.current_joint.sleeve_cutting_ratio;

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.current_joint.inner_active_mounting_length = app.InnerActivePulleyMountingLengthEditField.Value;
            app.current_joint.outer_active_mounting_length = app.OuterActivePulleyMountingLengthEditField.Value;
            app.current_joint.passive_mounting_length = app.PassivePulleyMountingLengthEditField.Value;
            app.current_joint.sleeve_assembly_extra = app.ExtraSleeveLengthforAssemblyEditField.Value;
            app.current_joint.sleeve_cutting_ratio = app.SleeveCuttingRatioEditField.Value;
            
            app.inner_pretentioned_length = app.current_inner_pulley.l_min/(1+app.current_inner_pulley.pretensioning/100);
            app.inner_length = app.inner_pretentioned_length + app.InnerActivePulleyMountingLengthEditField.Value + app.PassivePulleyMountingLengthEditField.Value;
            app.inner_sleeve_length = app.inner_pretentioned_length*app.SleeveCuttingRatioEditField.Value + app.InnerActivePulleyMountingLengthEditField.Value + app.PassivePulleyMountingLengthEditField.Value + app.ExtraSleeveLengthforAssemblyEditField.Value;

            app.outer_pretentioned_length = app.current_outer_pulley.l_min/(1+app.current_outer_pulley.pretensioning/100);
            app.outer_length = app.outer_pretentioned_length + app.OuterActivePulleyMountingLengthEditField.Value + app.PassivePulleyMountingLengthEditField.Value;
            app.outer_sleeve_length = app.outer_pretentioned_length*app.SleeveCuttingRatioEditField.Value + app.OuterActivePulleyMountingLengthEditField.Value + app.PassivePulleyMountingLengthEditField.Value + app.ExtraSleeveLengthforAssemblyEditField.Value;
            
            app.FilamentCuttingLengthInnerLabel.Text = "Filament Cutting Length: "+string(app.inner_length)+" mm";
            app.SleeveCuttingLengthInnerLabel.Text = "Sleeve Cutting Length: "+string(app.inner_sleeve_length)+" mm";
            app.IncludedforAssemblyInnerLabel.Text = "Included for Assembly: "+string(app.ExtraSleeveLengthforAssemblyEditField.Value)+" mm";

            app.FilamentCuttingLengthOuterLabel.Text = "Filament Cutting Length: "+string(app.outer_length)+" mm";
            app.SleeveCuttingLengthOuterLabel.Text = "Sleeve Cutting Length: "+string(app.outer_sleeve_length)+" mm";
            app.IncludedforAssemblyOuterLabel.Text = "Included for Assembly: "+string(app.ExtraSleeveLengthforAssemblyEditField.Value)+" mm";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InnerPulleyPanel = uipanel(app.UIFigure);
            app.InnerPulleyPanel.Title = 'Inner Pulley';
            app.FilamentCuttingLengthInnerLabel = uilabel(app.InnerPulleyPanel);
            app.FilamentCuttingLengthInnerLabel.Text = 'Filament Cutting Length: ';
            app.SleeveCuttingLengthInnerLabel = uilabel(app.InnerPulleyPanel);
            app.SleeveCuttingLengthInnerLabel.Text = 'Sleeve Cutting Length:';
            app.IncludedforAssemblyInnerLabel = uilabel(app.InnerPulleyPanel);
            app.IncludedforAssemblyInnerLabel.Text = 'Included for Assembly:';
            app.OuterPulleyPanel = uipanel(app.UIFigure);
            app.OuterPulleyPanel.Title = 'Outer Pulley';
            app.FilamentCuttingLengthOuterLabel = uilabel(app.OuterPulleyPanel);
            app.FilamentCuttingLengthOuterLabel.Text = 'Filament Cutting Length: ';
            app.SleeveCuttingLengthOuterLabel = uilabel(app.OuterPulleyPanel);
            app.SleeveCuttingLengthOuterLabel.Text = 'Sleeve Cutting Length:';
            app.IncludedforAssemblyOuterLabel = uilabel(app.OuterPulleyPanel);
            app.IncludedforAssemblyOuterLabel.Text = 'Included for Assembly:';
            app.JointSideLabel = uilabel(app.UIFigure);
            app.JointSideLabel.Text = 'Uppperarm Roll - Left Side';
            app.ExtraSleeveLengthforAssemblyLabel = uilabel(app.UIFigure);
            app.ExtraSleeveLengthforAssemblyLabel.Text = 'Extra Sleeve Length for Assembly (mm):';
            app.ExtraSleeveLengthforAssemblyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PassivePulleyMountingLengthEditFieldLabel = uilabel(app.UIFigure);
            app.PassivePulleyMountingLengthEditFieldLabel.Text = 'Passive Pulley Mounting Length (mm):';
            app.PassivePulleyMountingLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.InnerActivePulleyMountingLengthEditFieldLabel = uilabel(app.UIFigure);
            app.InnerActivePulleyMountingLengthEditFieldLabel.Text = 'Inner Active Pulley Mounting Length (mm):';
            app.InnerActivePulleyMountingLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.OuterActivePulleyMountingLengthEditFieldLabel = uilabel(app.UIFigure);
            app.OuterActivePulleyMountingLengthEditFieldLabel.Text = 'Outer Active Pulley Mounting Length (mm):';
            app.OuterActivePulleyMountingLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SleeveCuttingRatioLabel = uilabel(app.UIFigure);
            app.SleeveCuttingRatioLabel.Text = 'Sleeve Cutting Ratio:';
            app.SleeveCuttingRatioEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManufacturingHelp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end