classdef PulleyConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SwitchPulleySnapPositionButton  matlab.ui.control.Button
        ManufactoringHelpButton         matlab.ui.control.Button
        BlueTendonmaxAngleLabel         matlab.ui.control.Label
        BlueTendonminAngleLabel         matlab.ui.control.Label
        RedTendonminAngleLabel          matlab.ui.control.Label
        RedTendonmaxAngleLabel          matlab.ui.control.Label
        PretensioningEditField          matlab.ui.control.NumericEditField
        PretensioningEditFieldLabel     matlab.ui.control.Label
        OuterPulleyRevolutionsLabel     matlab.ui.control.Label
        InnerPulleyRevolutionsLabel     matlab.ui.control.Label
        OuterPulleyConfigLabel          matlab.ui.control.Label
        InnerPulleyConfigLabel          matlab.ui.control.Label
        BlueTendonStrechedLengthLabel   matlab.ui.control.Label
        BlueTendonRelaxedLengthLabel    matlab.ui.control.Label
        RedTendonStrechedLengthLabel    matlab.ui.control.Label
        RedTendonRelaxedLengthLabel     matlab.ui.control.Label
        SwitchAssignedTendonButton      matlab.ui.control.Button
        PulleyTypeDropDown              matlab.ui.control.DropDown
        PulleyTypeDropDownLabel         matlab.ui.control.Label
        TopPositionDropDown             matlab.ui.control.DropDown
        TopPositionLabel                matlab.ui.control.Label
        MaxLengthRatioEditField         matlab.ui.control.NumericEditField
        MaxLengthRatioEditFieldLabel    matlab.ui.control.Label
        VisualizeDropDown               matlab.ui.control.DropDown
        VisualizeDropDownLabel          matlab.ui.control.Label
        EditButton                      matlab.ui.control.Button
        JointSideLabel                  matlab.ui.control.Label
        JointLabel                      matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        current_joint % Description
        current_side % Description
        MainApp % Description
        current_inner_pulley % Description
        current_outer_pulley % Description
        current_pulley % Description
        ManufacturingHelpApp % Description
        current_joint_side % Description
        calc_inner_success % Description
        calc_outer_success % Description
    end
    
    methods (Access = private)
        
        function visualizePulley(app)
%             app.UIAxes.Position(3) = app.RightPanel.Position(3)/2;
%             app.UIAxes.Position(4) = app.RightPanel.Position(4);
            cla(app.UIAxes);
            axis(app.UIAxes,'auto')
            axis(app.UIAxes, 'equal')
            axis(app.UIAxes, 'off')

            line_width = 3;
            marker_size = 15;
            font_size = 38;

            active_circle = nsidedpoly(1000, 'Center', [app.current_side.x_2D_active_center app.current_side.y_2D_active_center], 'Radius', (app.current_joint.r_active+app.current_joint.tendon_diameter/2));
            plot(app.UIAxes, active_circle, 'FaceColor', "#EDB120",'LineWidth',line_width)
            plot(app.UIAxes, app.current_side.x_2D_active_center, app.current_side.y_2D_active_center, 'k+','LineWidth',line_width,'MarkerSize',marker_size)

            %config_id_pos = [];
            % Put in init function
            
            %attachment_angle = pulley_number_angle(7) + 2*pi/16;
            % Put in init function
            %app.current_pulley.pulley_numbers_angles
            
            for i = 1:8
                %curr_angle = curr_angle + 2*pi/8;
                if app.current_pulley.pulley_numbers_switch == 0
                    pna = app.current_pulley.pulley_numbers_angles;
                else
                    pna = app.current_pulley.pulley_numbers_angles_2;
                end
           
%                 pulley_number.x = app.current_side.x_2D_active_center + app.current_joint.r_active/2*cos(app.current_pulley.pulley_numbers_angles(i));
%                 pulley_number.y = app.current_side.y_2D_active_center - app.current_joint.r_active/2*sin(app.current_pulley.pulley_numbers_angles(i));
                
                pulley_number.x = app.current_side.x_2D_active_center + (app.current_joint.r_active)/2*cos(pna(i));
                pulley_number.y = app.current_side.y_2D_active_center - (app.current_joint.r_active)/2*sin(pna(i));
                pulley_number_text.x = app.current_side.x_2D_active_center + (app.current_joint.r_active+2)/2*cos(pna(i));
                pulley_number_text.y = app.current_side.y_2D_active_center - (app.current_joint.r_active+2)/2*sin(pna(i));
                text(app.UIAxes, pulley_number_text.x, pulley_number_text.y, string(i), 'Color','k','FontSize',font_size,'HorizontalAlignment','center','VerticalAlignment','middle')
                plot(app.UIAxes, [app.current_side.x_2D_active_center pulley_number.x], [app.current_side.y_2D_active_center pulley_number.y], "Color",'k','LineWidth',line_width/2)
            end

            if app.current_pulley.pulley_type == 's'
                if app.current_pulley.pulley_id == 'i'
                    attachment_pos.x = app.current_side.x_2D_active_center + app.current_joint.r_active/2*cos(pna(5)+2*pi/8/2);
                    attachment_pos.y = app.current_side.y_2D_active_center - app.current_joint.r_active/2*sin(pna(5)+2*pi/8/2);
                else
                    attachment_pos.x = app.current_side.x_2D_active_center + app.current_joint.r_active/2*cos(pna(5)-2*pi/8/2);
                    attachment_pos.y = app.current_side.y_2D_active_center - app.current_joint.r_active/2*sin(pna(5)-2*pi/8/2);
                end
            else
                if app.current_pulley.pulley_id == 'i'
                    attachment_pos.x = app.current_side.x_2D_active_center + app.current_joint.r_active/2*cos(pna(7)+2*pi/8/2);
                    attachment_pos.y = app.current_side.y_2D_active_center - app.current_joint.r_active/2*sin(pna(7)+2*pi/8/2);
                else
                    attachment_pos.x = app.current_side.x_2D_active_center + app.current_joint.r_active/2*cos(pna(7)-2*pi/8/2);
                    attachment_pos.y = app.current_side.y_2D_active_center - app.current_joint.r_active/2*sin(pna(7)-2*pi/8/2);
                end
            end
            plot(app.UIAxes, attachment_pos.x, attachment_pos.y, 'g+','LineWidth',line_width,'MarkerSize',marker_size)
            plot(app.UIAxes, [app.current_side.x_2D_active_center attachment_pos.x], [app.current_side.y_2D_active_center attachment_pos.y], "Color",'k','LineWidth',line_width/2)
            
%             attachment_pos.x = app.current_side.x_2D_active_center + (app.current_joint.r_active+app.current_joint.tendon_diameter/2)*cos(attachment_angle);
%             attachment_pos.y = app.current_side.y_2D_active_center - (app.current_joint.r_active+app.current_joint.tendon_diameter/2)*sin(attachment_angle);
            top_pos.x = app.current_pulley.active_top_pos(1);
            top_pos.y = app.current_pulley.active_top_pos(2);
            plot(app.UIAxes, top_pos.x, top_pos.y, 'ko','LineWidth',line_width,'MarkerSize',marker_size)
            plot(app.UIAxes, [app.current_side.x_2D_active_center top_pos.x], [app.current_side.y_2D_active_center top_pos.y], "Color",'k','LineWidth',line_width/2)

            axis(app.UIAxes,'manual')

            if app.current_pulley.tendon_id == 'r'
                plot(app.UIAxes, app.current_pulley.active_touch_pos(1),app.current_pulley.active_touch_pos(2),'rX','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, [app.current_side.x_free_active_red app.current_side.x_free_passive_red], [app.current_side.y_free_active_red app.current_side.y_free_passive_red], "Color",'r','LineWidth',line_width)
            elseif app.current_pulley.tendon_id == 'b'
                plot(app.UIAxes, app.current_pulley.active_touch_pos(1),app.current_pulley.active_touch_pos(2),'bX','LineWidth',line_width,'MarkerSize',marker_size)
                if app.current_joint.number_pulleys == 3
                    plot(app.UIAxes, [app.current_side.x_free_active_blue app.current_side.x_free_middle_active_blue], [app.current_side.y_free_active_blue app.current_side.y_free_middle_active_blue], "Color",'b','LineWidth',line_width)
                else
                    plot(app.UIAxes, [app.current_side.x_free_active_blue app.current_side.x_free_passive_blue], [app.current_side.y_free_active_blue app.current_side.y_free_passive_blue], "Color",'b','LineWidth',line_width)
                end
            end

            
            zero_pos_angle = 0;
            if app.current_pulley.active_top_indicator == "+y"
                zero_pos_angle = 3*pi/2;
            elseif app.current_pulley.active_top_indicator == "-x"
                zero_pos_angle = pi;
            elseif app.current_pulley.active_top_indicator == "-y"
                zero_pos_angle = pi/2;
            end

            
            r = app.current_side.r_active;% + app.current_side.tendon_diameter/2;
            if app.current_pulley.tendon_id == 'r'
                alpha_touch_zero = zero_pos_angle:pi/900:zero_pos_angle+app.current_pulley.alpha_touch_top;
                a_t_t = (zero_pos_angle+app.current_pulley.alpha_touch_top) - zero_pos_angle

                x_touch_zero = app.current_side.x_2D_active_center + r * cos(alpha_touch_zero);
                y_touch_zero = app.current_side.y_2D_active_center - r * sin(alpha_touch_zero);
                plot(app.UIAxes, x_touch_zero, y_touch_zero, "Color",'r','LineWidth',line_width)

                start = zero_pos_angle
                app.current_pulley.alpha_active
                top = app.current_pulley.alpha_touch_top
                rest = app.current_pulley.active_rotations*2*pi+app.current_pulley.alpha_active_reduced
                mount = app.current_pulley.alpha_active_mounted
                angle_from_top = app.current_pulley.alpha_active - app.current_pulley.alpha_touch_top

                alpha_active = start:-pi/900:start-(app.current_pulley.active_rotations*2*pi+app.current_pulley.alpha_active_reduced);%(app.current_pulley.alpha_active-app.current_pulley.alpha_touch_top);
                a_f_t = (start-(app.current_pulley.active_rotations*2*pi+app.current_pulley.alpha_active_reduced)) - start
                array_size = size(alpha_active,2);
                step_size = pi/9000;
                max_r = 7*r/8;
                b = max_r:-step_size:(max_r-(array_size*step_size)+step_size);
                x_alpha_active = app.current_side.x_2D_active_center + b .* cos(alpha_active);
                y_alpha_active = app.current_side.y_2D_active_center - b .* sin(alpha_active);
                plot(app.UIAxes, x_alpha_active, y_alpha_active, "Color",'r','LineWidth',line_width)
                plot(app.UIAxes, [x_alpha_active(size(alpha_active,2)) app.current_side.x_2D_active_center], [y_alpha_active(size(alpha_active,2)) app.current_side.y_2D_active_center], "Color",'k','LineWidth',line_width/2)
            elseif app.current_pulley.tendon_id == 'b'
                alpha_touch_zero = zero_pos_angle:-pi/900:(zero_pos_angle-app.current_pulley.alpha_touch_top);
                a_t_t = (zero_pos_angle-app.current_pulley.alpha_touch_top) - zero_pos_angle

                x_touch_zero = app.current_side.x_2D_active_center + r * cos(alpha_touch_zero);
                y_touch_zero = app.current_side.y_2D_active_center - r * sin(alpha_touch_zero);
                plot(app.UIAxes, x_touch_zero, y_touch_zero, "Color",'b','LineWidth',line_width)

                start = zero_pos_angle
                app.current_pulley.alpha_active
                top = app.current_pulley.alpha_touch_top
                rest = app.current_pulley.active_rotations*2*pi+app.current_pulley.alpha_active_reduced
                mount = app.current_pulley.alpha_active_mounted
                angle_from_top = app.current_pulley.alpha_active - app.current_pulley.alpha_touch_top
                
                alpha_active = start:pi/900:start+(app.current_pulley.active_rotations*2*pi+app.current_pulley.alpha_active_reduced);%(app.current_pulley.alpha_active-app.current_pulley.alpha_touch_top);
                a_f_t = (start+(app.current_pulley.active_rotations*2*pi+app.current_pulley.alpha_active_reduced)) - start
                array_size = size(alpha_active,2);
                step_size = pi/9000;
                max_r = 7*r/8;
                b = max_r:-step_size:(max_r-(array_size*step_size)+step_size);
                x_alpha_active = app.current_side.x_2D_active_center + b .* cos(alpha_active);
                y_alpha_active = app.current_side.y_2D_active_center - b .* sin(alpha_active);
                plot(app.UIAxes, x_alpha_active, y_alpha_active, "Color",'b','LineWidth',line_width)
                plot(app.UIAxes, [x_alpha_active(size(alpha_active,2)) app.current_side.x_2D_active_center], [y_alpha_active(size(alpha_active,2)) app.current_side.y_2D_active_center], "Color",'k','LineWidth',line_width/2)
            end


        end
        
        function updatePulleys(app)
            app.updateCurrentPulley(app.current_inner_pulley);
            app.updateCurrentPulley(app.current_outer_pulley);
        end
        
        function disableEditFields(app)
            app.MaxLengthRatioEditField.Enable = false;
            app.PretensioningEditField.Enable = false;
            app.TopPositionDropDown.Enable = false;
            app.PulleyTypeDropDown.Enable = false;
            app.SwitchAssignedTendonButton.Enable = false;
            app.SwitchPulleySnapPositionButton.Enable = false;
        end
        
        function enableEditFields(app)
            app.MaxLengthRatioEditField.Enable = true;
            app.PretensioningEditField.Enable = true;
            app.TopPositionDropDown.Enable = true;
            app.PulleyTypeDropDown.Enable = true;
            app.SwitchAssignedTendonButton.Enable = true;
            app.SwitchPulleySnapPositionButton.Enable = true;
        end
        
        function visualize(app)
            %app.calc_success
            if app.calc_inner_success == false || app.calc_outer_success == false
                msgbox("The calculations were unsuccessful! Try adjusting the top position or Max Length Ratio.","Error","error");
                return
            end

            if app.current_inner_pulley.tendon_id == 'r'
                app.RedTendonRelaxedLengthLabel.Text = "Red Tendon Relaxed Length: "+string(app.current_inner_pulley.l_min)+" mm";
                app.RedTendonStrechedLengthLabel.Text = "Red Tendon Streched Length: "+string(app.current_inner_pulley.l_max)+" mm";
                app.BlueTendonRelaxedLengthLabel.Text = "Blue Tendon Relaxed Length: "+string(app.current_outer_pulley.l_min)+" mm";
                app.BlueTendonStrechedLengthLabel.Text = "Blue Tendon Streched Length: "+string(app.current_outer_pulley.l_max)+" mm";

                app.RedTendonmaxAngleLabel.Text = "Red Tendon Max Angle: "+string(app.current_inner_pulley.maxAngle)+" rad";
                app.RedTendonminAngleLabel.Text = "Red Tendon Min Angle: "+string(app.current_inner_pulley.minAngle)+" rad";
                app.BlueTendonmaxAngleLabel.Text = "Blue Tendon Max Angle: "+string(app.current_outer_pulley.maxAngle)+" rad";
                app.BlueTendonminAngleLabel.Text = "Blue Tendon Min Angle: "+string(app.current_outer_pulley.minAngle)+" rad";
            else
                app.RedTendonRelaxedLengthLabel.Text = "Red Tendon Relaxed Length: "+string(app.current_outer_pulley.l_min)+" mm";
                app.RedTendonStrechedLengthLabel.Text = "Red Tendon Streched Length: "+string(app.current_outer_pulley.l_max)+" mm";
                app.BlueTendonRelaxedLengthLabel.Text = "Blue Tendon Relaxed Length: "+string(app.current_inner_pulley.l_min)+" mm";
                app.BlueTendonStrechedLengthLabel.Text = "Blue Tendon Streched Length: "+string(app.current_inner_pulley.l_max)+" mm";

                app.RedTendonmaxAngleLabel.Text = "Red Tendon Max Angle: "+string(app.current_outer_pulley.maxAngle)+" rad";
                app.RedTendonminAngleLabel.Text = "Red Tendon Min Angle: "+string(app.current_outer_pulley.minAngle)+" rad";
                app.BlueTendonmaxAngleLabel.Text = "Blue Tendon Max Angle: "+string(app.current_inner_pulley.maxAngle)+" rad";
                app.BlueTendonminAngleLabel.Text = "Blue Tendon Min Angle: "+string(app.current_inner_pulley.minAngle)+" rad";
            end

            app.InnerPulleyConfigLabel.Text = "Inner Pulley Zero Position Config: "+string(app.current_inner_pulley.zero_config(1))+" "+string(app.current_inner_pulley.zero_config(2));
            app.InnerPulleyRevolutionsLabel.Text = "Inner Pulley Revolutions: "+string(app.current_inner_pulley.revolutions);
            app.OuterPulleyConfigLabel.Text = "Outer Pulley Zero Position Config: "+string(app.current_outer_pulley.zero_config(1))+" "+string(app.current_outer_pulley.zero_config(2));
            app.OuterPulleyRevolutionsLabel.Text = "Outer Pulley Revolutions: "+string(app.current_outer_pulley.revolutions);


            if app.VisualizeDropDown.Value == "Inner Pulley"
                app.visualizePulley();
            elseif app.VisualizeDropDown.Value == "Outer Pulley"
                app.visualizePulley();
            elseif app.VisualizeDropDown.Value == "Full Setup"
                app.visualizeSetup();
            end
               
        end
        
        function initPulley(app, pulley)
            %app.MaxLengthRatioEditField.Value = app.current_pulley.l_max_ratio;

            %curr_angle = -2*pi/8/2 + 2*pi/8;
            if pulley.pulley_type == 's'
                if pulley.pulley_id == 'i'
                    curr_angle = -2*pi/8/2 + 2*pi/8*3;
                    step = 2*pi/8;
                else
                    curr_angle = -2*pi/8/2 - 2*pi/8*2;
                    step = -2*pi/8;
                end
            elseif pulley.pulley_type == 'a'
                if pulley.pulley_id == 'i'
                    curr_angle = -2*pi/8/2 + 2*pi/8;
                    step = 2*pi/8;
                else
                    curr_angle = -2*pi/8/2;
                    step = -2*pi/8;
                end
                
            end

            offset = 0;
            if pulley.active_top_indicator == "+y"
                offset = 3*pi/2;
            elseif pulley.active_top_indicator == "-x"
                offset = pi;
            elseif pulley.active_top_indicator == "-y"
                offset = pi/2;
            end

            % Evaluate if the pulley is tightened in clockwise or
            % counter-clockwise direction
            x_passive = app.current_side.x_2D_passive_center;
            y_passive = app.current_side.y_2D_passive_center;
            x_active_center = pulley.active_center_pos(1);
            y_active_center = pulley.active_center_pos(2);
            x_active_tendon = pulley.active_touch_pos(1);
            y_active_tendon = pulley.active_touch_pos(2);

            center_slope = pulley.calcSlope(x_passive,y_passive,x_active_center,y_active_center);
            tendon_slope = pulley.calcSlope(x_passive,y_passive,x_active_tendon,y_active_tendon);

            if tendon_slope > center_slope
                if abs(tendon_slope - center_slope) > pi/2
                    pulley.rotation_direction = "cc";
                else
                    pulley.rotation_direction = "c";
                end
            else
                if abs(tendon_slope - center_slope) > pi/2
                    pulley.rotation_direction = "c";
                else
                    pulley.rotation_direction = "cc";
                end
            end

            pulley.pulley_numbers_angles = zeros(8);
            pulley.pulley_numbers_angles_2 = zeros(8);
            for i = 1:8
                %pulley.pulley_numbers_angles_2(i) = curr_angle+offset;
                curr_angle = curr_angle + step;

%                 curr_angle_2 = curr_angle - step
                %pulley_number_angle(i) = curr_angle;

                pulley.pulley_numbers_angles(i) = curr_angle+offset;
                if (pulley.rotation_direction == "cc" && pulley.pulley_id == "o") || (pulley.rotation_direction == "c" && pulley.pulley_id == "i")
                    pulley.pulley_numbers_angles_2(i) = curr_angle-step+offset;
                else
                    pulley.pulley_numbers_angles_2(i) = curr_angle+step+offset;
                end
%                 if i == 8
%                     pulley.pulley_numbers_angles_2(1) = pulley.pulley_numbers_angles(i);
%                 end
%                 if i ~= 1
%                     pulley.pulley_numbers_angles_2(i) = pulley.pulley_numbers_angles(i-1);
%                 end
                
            end
            
            
        end
        
        function visualizeSetup(app)
            %TODO: ADD CODE FOR VISUALIZING FULL SETUP
            %like tendon length visual
            %on active pulley add visualization of active top pos
            cla(app.UIAxes);
            axis(app.UIAxes,'auto')
            axis(app.UIAxes, 'equal')
            axis(app.UIAxes, 'off')

            line_width = 3;
            marker_size = 15;
            font_size = 18;

            passive_circle = nsidedpoly(1000, 'Center', [app.current_side.x_2D_passive_center app.current_side.y_2D_passive_center], 'Radius', (app.current_joint.r_passive+app.current_joint.tendon_diameter/2));
            active_circle = nsidedpoly(1000, 'Center', [app.current_side.x_2D_active_center app.current_side.y_2D_active_center], 'Radius', (app.current_joint.r_active+app.current_joint.tendon_diameter/2));
            plot(app.UIAxes, passive_circle, 'FaceColor', "#D95319",'LineWidth',line_width)
            plot(app.UIAxes, active_circle, 'FaceColor', "#EDB120",'LineWidth',line_width)
            plot(app.UIAxes, app.current_side.x_2D_passive_center, app.current_side.y_2D_passive_center, 'k+','LineWidth',line_width,'MarkerSize',marker_size)
            plot(app.UIAxes, app.current_side.x_2D_active_center, app.current_side.y_2D_active_center, 'k+','LineWidth',line_width,'MarkerSize',marker_size)

            if app.current_joint.number_pulleys == 3
                middle_circle = nsidedpoly(1000, 'Center', [app.current_side.x_2D_middle_center app.current_side.y_2D_middle_center], 'Radius', (app.current_joint.r_middle+app.current_joint.tendon_diameter/2));
                plot(app.UIAxes, middle_circle, 'FaceColor', "#D95319",'LineWidth',line_width)
                plot(app.UIAxes, app.current_side.x_2D_middle_center, app.current_side.y_2D_middle_center, 'k+','LineWidth',line_width,'MarkerSize',marker_size)
            end

            plot(app.UIAxes, app.current_side.x_free_passive_red,app.current_side.y_free_passive_red,'rX','LineWidth',line_width,'MarkerSize',marker_size)
            plot(app.UIAxes, app.current_side.x_free_active_red,app.current_side.y_free_active_red,'rX','LineWidth',line_width,'MarkerSize',marker_size)
            plot(app.UIAxes, app.current_side.x_free_passive_blue,app.current_side.y_free_passive_blue,'bX','LineWidth',line_width,'MarkerSize',marker_size)
            plot(app.UIAxes, app.current_side.x_free_active_blue,app.current_side.y_free_active_blue,'bX','LineWidth',line_width,'MarkerSize',marker_size)           
            plot(app.UIAxes, [app.current_side.x_free_active_red app.current_side.x_free_passive_red], [app.current_side.y_free_active_red app.current_side.y_free_passive_red], "Color",'r','LineWidth',line_width)
            if app.current_joint.number_pulleys == 2
                plot(app.UIAxes, [app.current_side.x_free_active_blue app.current_side.x_free_passive_blue], [app.current_side.y_free_active_blue app.current_side.y_free_passive_blue], "Color",'b','LineWidth',line_width)
            elseif app.current_joint.number_pulleys == 3
                plot(app.UIAxes, app.current_side.x_free_middle_passive_blue, app.current_side.y_free_middle_passive_blue,'bX','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, app.current_side.x_free_middle_active_blue, app.current_side.y_free_middle_active_blue,'bX','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, [app.current_side.x_free_active_blue app.current_side.x_free_middle_active_blue], [app.current_side.y_free_active_blue app.current_side.y_free_middle_active_blue], "Color",'b','LineWidth',line_width)
                plot(app.UIAxes, [app.current_side.x_free_middle_passive_blue app.current_side.x_free_passive_blue], [app.current_side.y_free_middle_passive_blue app.current_side.y_free_passive_blue], "Color",'b','LineWidth',line_width)
            end

            if app.current_joint.number_tendon_attachments == 1
                base_angle_red = app.current_side.alpha_passive_red + app.current_side.theta_red;
                x_tendon_attachment = app.current_side.x_2D_passive_center + (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * cos(base_angle_red);
                y_tendon_attachment = app.current_side.y_2D_passive_center - (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * sin(base_angle_red);
                plot(app.UIAxes, x_tendon_attachment,y_tendon_attachment,'g+','LineWidth',line_width,'MarkerSize',marker_size)
            elseif app.current_joint.number_tendon_attachments == 2
                if app.current_joint.number_pulleys == 2
                    base_angle_red = app.current_side.alpha_passive_red + app.current_side.theta_red;
                elseif app.current_joint.number_pulleys == 3
                    base_angle_red = -app.current_side.alpha_passive_red + app.current_side.theta_red;
                end
                base_angle_blue = app.current_side.alpha_passive_blue+app.current_side.theta_blue;
                x_tendon_upper_attachment = app.current_side.x_2D_passive_center + (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * cos(base_angle_blue);
                y_tendon_upper_attachment = app.current_side.y_2D_passive_center + (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * sin(base_angle_blue);
                x_tendon_lower_attachment = app.current_side.x_2D_passive_center + (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * cos(base_angle_red);
                y_tendon_lower_attachment = app.current_side.y_2D_passive_center - (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * sin(base_angle_red);
                plot(app.UIAxes, x_tendon_upper_attachment,y_tendon_upper_attachment,'b+','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, x_tendon_lower_attachment,y_tendon_lower_attachment,'r+','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, x_tendon_upper_attachment,y_tendon_upper_attachment,'bO','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, x_tendon_lower_attachment,y_tendon_lower_attachment,'rO','LineWidth',line_width,'MarkerSize',marker_size)
            end

            top_pos.x = app.current_pulley.active_top_pos(1);
            top_pos.y = app.current_pulley.active_top_pos(2);
            plot(app.UIAxes, top_pos.x, top_pos.y, 'g+','LineWidth',line_width,'MarkerSize',marker_size)

        end
        
        function updateCurrentPulley(app, pulley)
            %direct assign from calc
            
            pulley.active_center_pos = [app.current_side.x_2D_active_center app.current_side.y_2D_active_center];

            %require if
            %pulley.tendon_id
            if pulley.tendon_id == 'n'
                app.current_inner_pulley.tendon_id = 'r';
                app.current_outer_pulley.tendon_id = 'b';
            end

            if app.current_joint.number_pulleys == 3
                if pulley.tendon_id == 'r'
                    pulley.l_free = app.current_side.l_free_red;
                elseif pulley.tendon_id == 'b'
                    pulley.l_free = app.current_side.l_free_blue;
                end
            else
                pulley.l_free = app.current_side.l_free;
            end

            if pulley.tendon_id == 'r'
                pulley.l_min = app.current_side.red_tendon_length;
                pulley.alpha_passive_zero = app.current_side.alpha_passive_red; %app.current_side.alpha_passive_red or alpha_passive_blue
                pulley.active_touch_pos = [app.current_side.x_free_active_red app.current_side.y_free_active_red];
                if app.current_side.maxAngle_indicator == 'r'
                    pulley.maxAngle = app.current_side.minAngle;
                    pulley.minAngle = app.current_side.maxAngle;
                    if app.current_joint.name == "Shoulder Pitch"
                        pulley.minAngle = app.current_side.maxAngle - app.current_side.alpha_passive_red;
                        pulley.alpha_passive_zero = 0;
                    end
                else
                    pulley.maxAngle = app.current_side.maxAngle;
                    pulley.minAngle = app.current_side.minAngle;
                    if app.current_joint.name == "Shoulder Pitch"
                        pulley.maxAngle = app.current_side.maxAngle - app.current_side.alpha_passive_red;
                        pulley.alpha_passive_zero = 0;
                    end
                end
            elseif pulley.tendon_id == 'b'
                pulley.l_min = app.current_side.blue_tendon_length;
                pulley.alpha_passive_zero = app.current_side.alpha_passive_blue; %app.current_side.alpha_passive_red or alpha_passive_blue
                pulley.active_touch_pos = [app.current_side.x_free_active_blue app.current_side.y_free_active_blue];
                if app.current_side.maxAngle_indicator == 'r'
                    pulley.maxAngle = app.current_side.maxAngle;
                    pulley.minAngle = app.current_side.minAngle;
                else
                    pulley.maxAngle = app.current_side.minAngle;
                    pulley.minAngle = app.current_side.maxAngle;
                end
            end

            %use edit fiel or button
            pulley.l_max_ratio = app.MaxLengthRatioEditField.Value;
            pulley.pretensioning = app.PretensioningEditField.Value;
            pulley.active_top_indicator = app.TopPositionDropDown.Value;
            if app.TopPositionDropDown.Value == "+x"
                pulley.active_top_pos = [pulley.active_center_pos(1)+(app.current_joint.r_active+app.current_joint.tendon_diameter/2) pulley.active_center_pos(2)];
            elseif app.TopPositionDropDown.Value == "-x"
                pulley.active_top_pos = [pulley.active_center_pos(1)-(app.current_joint.r_active+app.current_joint.tendon_diameter/2) pulley.active_center_pos(2)];
            elseif app.TopPositionDropDown.Value == "+y"
                pulley.active_top_pos = [pulley.active_center_pos(1) pulley.active_center_pos(2)+(app.current_joint.r_active+app.current_joint.tendon_diameter/2)];
            elseif app.TopPositionDropDown.Value == "-y"
                pulley.active_top_pos = [pulley.active_center_pos(1) pulley.active_center_pos(2)-(app.current_joint.r_active+app.current_joint.tendon_diameter/2)];
            end

            if app.PulleyTypeDropDown.Value == "Shoulder"
                pulley.pulley_type = 's';
            elseif app.PulleyTypeDropDown.Value == "Arm"
                pulley.pulley_type = 'a';
            end
            
            app.initPulley(pulley);

            if pulley.pulley_id == 'i'
                app.calc_inner_success = pulley.calcActiveZeroAngle();
            elseif pulley.pulley_id == 'o'
                app.calc_outer_success = pulley.calcActiveZeroAngle();
            end
%             if app.calc_success == true
%                 out = "Success!"
%             else
%                 out = "No Success!"
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, joint, joint_side)
            app.MainApp = caller;

            app.current_joint = joint;
            app.current_joint_side = joint_side;
            app.current_side = joint_side.tendons;
            app.current_inner_pulley = joint_side.inner_pulley;
            app.current_outer_pulley = joint_side.outer_pulley;
            app.current_pulley = app.current_inner_pulley;

            app.JointLabel.Text = app.current_joint.name;
            app.JointSideLabel.Text = app.current_side.name+" - Inner Pulley:";

            app.MaxLengthRatioEditField.Value = app.current_pulley.l_max_ratio;
            app.PretensioningEditField.Value = app.current_pulley.pretensioning;
            app.TopPositionDropDown.Value = app.current_pulley.active_top_indicator;
            if app.current_pulley.pulley_type == 's'
                app.PulleyTypeDropDown.Value = "Shoulder";
            else
                app.PulleyTypeDropDown.Value = "Arm";
            end

            %calculate pulley pos
            app.disableEditFields();
            %app.initPulleys();
            app.updatePulleys();
            %app.current_inner_pulley.calcActiveZeroAngle();
            %app.current_outer_pulley.calcActiveZeroAngle();
            app.visualize();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.JointDropDown.Enable = true;
            app.MainApp.ShowTendonLengthsButton.Enable = true;
            app.MainApp.EditButton.Enable = true;
            app.MainApp.JointSideDropDown.Enable = true;
            app.MainApp.ShowPulleyConfigButton.Enable = true;
            delete(app.ManufacturingHelpApp)
            delete(app)
            
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            if app.EditButton.Text == "Edit"
                app.EditButton.Text = "Save";
                app.enableEditFields();
            else
                app.EditButton.Text = "Edit";
                app.disableEditFields();
                app.updatePulleys();
                app.visualize();
            end
        end

        % Button pushed function: SwitchAssignedTendonButton
        function SwitchAssignedTendonButtonPushed(app, event)
            if app.current_inner_pulley.tendon_id == 'r'
                app.current_inner_pulley.tendon_id = 'b';
                app.current_inner_pulley.active_touch_pos = [app.current_side.x_free_active_blue app.current_side.y_free_active_blue];
                app.current_outer_pulley.tendon_id = 'r';
                app.current_outer_pulley.active_touch_pos = [app.current_side.x_free_active_red app.current_side.y_free_active_red];
            elseif app.current_inner_pulley.tendon_id == 'b'
                app.current_inner_pulley.tendon_id = 'r';
                app.current_inner_pulley.active_touch_pos = [app.current_side.x_free_active_red app.current_side.y_free_active_red];
                app.current_outer_pulley.tendon_id = 'b';
                app.current_outer_pulley.active_touch_pos = [app.current_side.x_free_active_blue app.current_side.y_free_active_blue];
            end

            app.updatePulleys();
            app.visualize();
        end

        % Value changed function: VisualizeDropDown
        function VisualizeDropDownValueChanged(app, event)
            value = app.VisualizeDropDown.Value;
            
            if value == "Inner Pulley"
                app.current_pulley = app.current_inner_pulley;
                app.JointSideLabel.Text = app.current_side.name+" - Inner Pulley:";
                app.updatePulleys();
            elseif value == "Outer Pulley"
                app.current_pulley = app.current_outer_pulley;
                app.JointSideLabel.Text = app.current_side.name+" - Outer Pulley:";
                app.updatePulleys();
            elseif value == "Full Setup"
                app.JointSideLabel.Text = app.current_side.name+":";
                
            end

            app.visualize();
        end

        % Button pushed function: ManufactoringHelpButton
        function ManufactoringHelpButtonPushed(app, event)
            
            app.ManufacturingHelpApp = ManufacturingHelp(app, app.current_joint, app.current_joint_side);
        end

        % Button pushed function: SwitchPulleySnapPositionButton
        function SwitchPulleySnapPositionButtonPushed(app, event)
            if app.current_pulley.pulley_numbers_switch == 0
                app.current_pulley.pulley_numbers_switch = 1;
            else
                app.current_pulley.pulley_numbers_switch = 0;
            end

            app.visualize();
        
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {767, 767};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {406, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.JointLabel = uilabel(app.LeftPanel);
            app.JointLabel.Text = 'Upperarm Roll';
            app.JointSideLabel = uilabel(app.LeftPanel);
            app.JointSideLabel.Text = 'Left Side:';
            app.EditButton = uibutton(app.LeftPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.VisualizeDropDownLabel = uilabel(app.LeftPanel);
            app.VisualizeDropDownLabel.Text = 'Visualize: ';
            app.VisualizeDropDown = uidropdown(app.LeftPanel);
            app.VisualizeDropDown.Items = {'Inner Pulley', 'Outer Pulley', 'Full Setup'};
            app.VisualizeDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizeDropDownValueChanged, true);
            app.VisualizeDropDown.Value = 'Inner Pulley';
            app.MaxLengthRatioEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxLengthRatioEditFieldLabel.Text = 'Max Length Ratio:';
            app.MaxLengthRatioEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxLengthRatioEditField.ValueDisplayFormat = '%.3f';
            app.MaxLengthRatioEditField.Value = 1;
            app.TopPositionLabel = uilabel(app.LeftPanel);
            app.TopPositionLabel.Text = 'Top Position:';
            app.TopPositionDropDown = uidropdown(app.LeftPanel);
            app.TopPositionDropDown.Items = {'+x', '-x', '+y', '-y'};
            app.TopPositionDropDown.Value = '+x';
            app.PulleyTypeDropDownLabel = uilabel(app.LeftPanel);
            app.PulleyTypeDropDownLabel.Visible = 'off';
            app.PulleyTypeDropDownLabel.Text = 'Pulley Type:';
            app.PulleyTypeDropDown = uidropdown(app.LeftPanel);
            app.PulleyTypeDropDown.Items = {'Shoulder', 'Arm'};
            app.PulleyTypeDropDown.Visible = 'off';
            app.PulleyTypeDropDown.Value = 'Shoulder';
            app.SwitchAssignedTendonButton = uibutton(app.LeftPanel, 'push');
            app.SwitchAssignedTendonButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchAssignedTendonButtonPushed, true);
            app.SwitchAssignedTendonButton.Enable = 'off';
            app.SwitchAssignedTendonButton.Text = 'Switch Assigned Tendon';
            app.RedTendonRelaxedLengthLabel = uilabel(app.LeftPanel);
            app.RedTendonRelaxedLengthLabel.Text = 'Red Tendon Relaxed Length: 123.44 mm';
            app.RedTendonStrechedLengthLabel = uilabel(app.LeftPanel);
            app.RedTendonStrechedLengthLabel.Text = 'Red Tendon Streched Length: 123.44 mm';
            app.BlueTendonRelaxedLengthLabel = uilabel(app.LeftPanel);
            app.BlueTendonRelaxedLengthLabel.Text = 'Blue Tendon Relaxed Length: 123.44 mm';
            app.BlueTendonStrechedLengthLabel = uilabel(app.LeftPanel);
            app.BlueTendonStrechedLengthLabel.Text = 'Blue Tendon Streched Length: 123.44 mm';
            app.InnerPulleyConfigLabel = uilabel(app.LeftPanel);
            app.InnerPulleyConfigLabel.Text = 'Inner Pulley Zero Position Config: 5 6';
            app.OuterPulleyConfigLabel = uilabel(app.LeftPanel);
            app.OuterPulleyConfigLabel.Text = 'Outer Pulley Zero Position Config: 5 6';
            app.InnerPulleyRevolutionsLabel = uilabel(app.LeftPanel);
            app.InnerPulleyRevolutionsLabel.Text = 'Inner Pulley Revolutions: 0';
            app.OuterPulleyRevolutionsLabel = uilabel(app.LeftPanel);
            app.OuterPulleyRevolutionsLabel.Text = 'Outer Pulley Revolutions: 0';
            app.PretensioningEditFieldLabel = uilabel(app.LeftPanel);
            app.PretensioningEditFieldLabel.Text = 'Pretensioning (%): ';
            app.PretensioningEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PretensioningEditField.ValueDisplayFormat = '%.0f';
            app.RedTendonmaxAngleLabel = uilabel(app.LeftPanel);
            app.RedTendonmaxAngleLabel.Text = 'Red Tendon max Angle: 3.1 rad';
            app.RedTendonminAngleLabel = uilabel(app.LeftPanel);
            app.RedTendonminAngleLabel.Text = 'Red Tendon min Angle: 3.1 rad';
            app.BlueTendonminAngleLabel = uilabel(app.LeftPanel);
            app.BlueTendonminAngleLabel.Text = 'Blue Tendon min Angle: 3.1 rad';
            app.BlueTendonmaxAngleLabel = uilabel(app.LeftPanel);
            app.BlueTendonmaxAngleLabel.Text = 'Blue Tendon max Angle: 3.1 rad';
            app.ManufactoringHelpButton = uibutton(app.LeftPanel, 'push');
            app.ManufactoringHelpButton.ButtonPushedFcn = createCallbackFcn(app, @ManufactoringHelpButtonPushed, true);
            app.ManufactoringHelpButton.Text = 'Manufactoring Help';
            app.SwitchPulleySnapPositionButton = uibutton(app.LeftPanel, 'push');
            app.SwitchPulleySnapPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchPulleySnapPositionButtonPushed, true);
            app.SwitchPulleySnapPositionButton.Enable = 'off';
            app.SwitchPulleySnapPositionButton.Text = 'Switch Pulley Snap Position';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PulleyConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end