classdef TendonLength < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        NegativeTendonDropDown       matlab.ui.control.DropDown
        NegativeTendonDropDownLabel  matlab.ui.control.Label
        PositiveTendonDropDown       matlab.ui.control.DropDown
        PositiveTendonDropDownLabel  matlab.ui.control.Label
        VisualizeTendonAttachmentPositionsfromCADCheckBox  matlab.ui.control.CheckBox
        SwapTendonAttachmentsButton  matlab.ui.control.Button
        JointSideLabel               matlab.ui.control.Label
        JointLabel                   matlab.ui.control.Label
        RedAngle180CheckBox          matlab.ui.control.CheckBox
        BlueAngle180CheckBox         matlab.ui.control.CheckBox
        SwapMaxAngleButton           matlab.ui.control.Button
        BlueTendonLengthLabel        matlab.ui.control.Label
        RedTendonLengthLabel         matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        MainApp % Main App that opens this app
        current_joint % Description
        current_side % Description
        red_tendon_length
        blue_tendon_length
        visualize_true_tendon_pos % Description
    end
    
    methods (Access = private)
        function visualizeData(app)
            cla(app.UIAxes);

            line_width = 3;
            marker_size = 15;
            %font_size = 18;

            passive_circle = nsidedpoly(1000, 'Center', [app.current_side.x_2D_passive_center app.current_side.y_2D_passive_center], 'Radius', (app.current_joint.r_passive+app.current_joint.tendon_diameter/2));
            active_circle = nsidedpoly(1000, 'Center', [app.current_side.x_2D_active_center app.current_side.y_2D_active_center], 'Radius', (app.current_joint.r_active+app.current_joint.tendon_diameter/2));
%             plot(app.UIAxes, passive_circle, 'FaceColor', "#D95319",'LineWidth',line_width)
%             plot(app.UIAxes, active_circle, 'FaceColor', "#EDB120",'LineWidth',line_width)
            plot(app.UIAxes, passive_circle, 'FaceColor', "#66CCED",'LineWidth',line_width)
            plot(app.UIAxes, active_circle, 'FaceColor', "#66CCED",'LineWidth',line_width)
            plot(app.UIAxes, app.current_side.x_2D_passive_center, app.current_side.y_2D_passive_center, 'k+','LineWidth',line_width,'MarkerSize',marker_size)
            plot(app.UIAxes, app.current_side.x_2D_active_center, app.current_side.y_2D_active_center, 'k+','LineWidth',line_width,'MarkerSize',marker_size)
            if app.current_joint.number_pulleys == 3
                middle_circle = nsidedpoly(1000, 'Center', [app.current_side.x_2D_middle_center app.current_side.y_2D_middle_center], 'Radius', (app.current_joint.r_middle+app.current_joint.tendon_diameter/2));
                plot(app.UIAxes, middle_circle, 'FaceColor', "#D95319",'LineWidth',line_width)
                plot(app.UIAxes, app.current_side.x_2D_middle_center, app.current_side.y_2D_middle_center, 'k+','LineWidth',line_width,'MarkerSize',marker_size)
            end

%             plot(app.UIAxes, app.current_side.x_free_passive_red,app.current_side.y_free_passive_red,'rX','LineWidth',line_width,'MarkerSize',marker_size)
%             plot(app.UIAxes, app.current_side.x_free_active_red,app.current_side.y_free_active_red,'rX','LineWidth',line_width,'MarkerSize',marker_size)
%             plot(app.UIAxes, app.current_side.x_free_passive_blue,app.current_side.y_free_passive_blue,'bX','LineWidth',line_width,'MarkerSize',marker_size)
%             plot(app.UIAxes, app.current_side.x_free_active_blue,app.current_side.y_free_active_blue,'bX','LineWidth',line_width,'MarkerSize',marker_size)  
            plot(app.UIAxes, app.current_side.x_free_passive_red,app.current_side.y_free_passive_red,'X','MarkerEdgeColor','#f24c4c','LineWidth',line_width,'MarkerSize',marker_size)
            plot(app.UIAxes, app.current_side.x_free_active_red,app.current_side.y_free_active_red,'X','MarkerEdgeColor','#f24c4c','LineWidth',line_width,'MarkerSize',marker_size)
            plot(app.UIAxes, app.current_side.x_free_passive_blue,app.current_side.y_free_passive_blue,'X','MarkerEdgeColor','#4CF24C','LineWidth',line_width,'MarkerSize',marker_size)
            plot(app.UIAxes, app.current_side.x_free_active_blue,app.current_side.y_free_active_blue,'X','MarkerEdgeColor','#4CF24C','LineWidth',line_width,'MarkerSize',marker_size)
%             plot(app.UIAxes, [app.current_side.x_free_active_red app.current_side.x_free_passive_red], [app.current_side.y_free_active_red app.current_side.y_free_passive_red], "Color",'r','LineWidth',line_width)
            plot(app.UIAxes, [app.current_side.x_free_active_red app.current_side.x_free_passive_red], [app.current_side.y_free_active_red app.current_side.y_free_passive_red], "Color",'#f24c4c','LineWidth',line_width)
            if app.current_joint.number_pulleys == 2
%                 plot(app.UIAxes, [app.current_side.x_free_active_blue app.current_side.x_free_passive_blue], [app.current_side.y_free_active_blue app.current_side.y_free_passive_blue], "Color",'b','LineWidth',line_width)
                plot(app.UIAxes, [app.current_side.x_free_active_blue app.current_side.x_free_passive_blue], [app.current_side.y_free_active_blue app.current_side.y_free_passive_blue], "Color",'#4CF24C','LineWidth',line_width)
            elseif app.current_joint.number_pulleys == 3
                plot(app.UIAxes, app.current_side.x_free_middle_passive_blue, app.current_side.y_free_middle_passive_blue,'bX','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, app.current_side.x_free_middle_active_blue, app.current_side.y_free_middle_active_blue,'bX','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, [app.current_side.x_free_active_blue app.current_side.x_free_middle_active_blue], [app.current_side.y_free_active_blue app.current_side.y_free_middle_active_blue], "Color",'b','LineWidth',line_width)
                plot(app.UIAxes, [app.current_side.x_free_middle_passive_blue app.current_side.x_free_passive_blue], [app.current_side.y_free_middle_passive_blue app.current_side.y_free_passive_blue], "Color",'b','LineWidth',line_width)
            end

            
            if app.current_joint.number_tendon_attachments == 1
                base_angle_red = app.current_side.alpha_passive_red + app.current_side.theta_red;
                base_angle_blue = app.current_side.alpha_passive_blue+app.current_side.theta_blue;
                x_tendon_attachment = app.current_side.x_2D_passive_center + (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * cos(base_angle_red);
                y_tendon_attachment = app.current_side.y_2D_passive_center - (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * sin(base_angle_red);
%                 plot(app.UIAxes, x_tendon_attachment,y_tendon_attachment,'g+','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, x_tendon_attachment,y_tendon_attachment,'bo','LineWidth',line_width,'MarkerSize',marker_size)
                if app.visualize_true_tendon_pos == true
                    plot(app.UIAxes, app.current_side.x_2D_tendon_pos,app.current_side.y_2D_tendon_pos,'g+','LineWidth',line_width,'MarkerSize',marker_size)
                end
                plot(app.UIAxes, [app.current_side.x_2D_passive_center x_tendon_attachment], [app.current_side.y_2D_passive_center y_tendon_attachment], "Color",'k','LineWidth',line_width)
                plot(app.UIAxes, [app.current_side.x_2D_passive_center app.current_side.x_free_passive_red], [app.current_side.y_2D_passive_center app.current_side.y_free_passive_red], "Color",'k','LineWidth',line_width)
                plot(app.UIAxes, [app.current_side.x_2D_passive_center app.current_side.x_free_passive_blue], [app.current_side.y_2D_passive_center app.current_side.y_free_passive_blue], "Color",'k','LineWidth',line_width)
                

                a_p_r = app.current_side.theta_red:pi/90:base_angle_red;
                x_alpha_passive_red = app.current_side.x_2D_passive_center + 20 * cos(a_p_r);
                y_alpha_passive_red = app.current_side.y_2D_passive_center - 20 * sin(a_p_r);
%                 plot(app.UIAxes, x_alpha_passive_red, y_alpha_passive_red, "Color",'r','LineWidth',line_width)
                plot(app.UIAxes, x_alpha_passive_red, y_alpha_passive_red, "Color",'#f24c4c','LineWidth',line_width)
                %text(app.UIAxes, app.current_side.x_2D_passive_center-24, app.current_side.y_2D_passive_center+5, '\alpha_{passive}', 'Color','r','FontSize',font_size)
            elseif app.current_joint.number_tendon_attachments == 2
                base_angle_red = app.current_side.alpha_passive_red + app.current_side.theta_red;
                base_angle_blue = app.current_side.alpha_passive_blue+app.current_side.theta_blue;
                x_tendon_upper_attachment = app.current_side.x_2D_passive_center + (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * cos(base_angle_blue);
                y_tendon_upper_attachment = app.current_side.y_2D_passive_center + (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * sin(base_angle_blue);
                x_tendon_lower_attachment = app.current_side.x_2D_passive_center + (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * cos(base_angle_red);
                y_tendon_lower_attachment = app.current_side.y_2D_passive_center - (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * sin(base_angle_red);
                
                if app.current_joint.number_pulleys == 2
                    a_p_r = app.current_side.theta_red:pi/90:base_angle_red;
                    x_alpha_passive_red = app.current_side.x_2D_passive_center + 25 * cos(a_p_r);
                    y_alpha_passive_red = app.current_side.y_2D_passive_center - 25 * sin(a_p_r);
                    plot(app.UIAxes, x_alpha_passive_red, y_alpha_passive_red, "Color",'r','LineWidth',line_width)
                    
                elseif app.current_joint.number_pulleys == 3
                    base_angle_red = -app.current_side.alpha_passive_red + app.current_side.theta_red;
                    base_angle_middle = app.current_side.theta_middle + app.current_side.alpha_middle_blue;

                    x_tendon_lower_attachment = app.current_side.x_2D_passive_center + (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * cos(base_angle_red);
                    y_tendon_lower_attachment = app.current_side.y_2D_passive_center - (app.current_joint.r_passive+app.current_joint.tendon_diameter/2) * sin(base_angle_red);

                    plot(app.UIAxes, [app.current_side.x_2D_middle_center app.current_side.x_free_middle_active_blue], [app.current_side.y_2D_middle_center app.current_side.y_free_middle_active_blue], "Color",'k','LineWidth',line_width)
                    plot(app.UIAxes, [app.current_side.x_2D_middle_center app.current_side.x_free_middle_passive_blue], [app.current_side.y_2D_middle_center app.current_side.y_free_middle_passive_blue], "Color",'k','LineWidth',line_width)
    
                    a_p_r = app.current_side.theta_red:-pi/90:base_angle_red;
                    x_alpha_passive_red = app.current_side.x_2D_passive_center + 20 * cos(a_p_r);
                    y_alpha_passive_red = app.current_side.y_2D_passive_center - 20 * sin(a_p_r);
                    plot(app.UIAxes, x_alpha_passive_red, y_alpha_passive_red, "Color",'r','LineWidth',line_width)
                    %text(app.UIAxes, app.current_side.x_2D_passive_center-24, app.current_side.y_2D_passive_center+5, '\alpha_{passive}', 'Color','r','FontSize',font_size)
    
                    a_p_m = app.current_side.theta_middle:pi/90:base_angle_middle;
                    x_alpha_middle_blue = app.current_side.x_2D_middle_center + 5 * cos(a_p_m);
                    y_alpha_middle_blue = app.current_side.y_2D_middle_center + 5 * sin(a_p_m);
                    plot(app.UIAxes, x_alpha_middle_blue, y_alpha_middle_blue, "Color",'b','LineWidth',line_width)
                end
                
                if app.visualize_true_tendon_pos == true
                    if app.current_side.tendon_attachment_indicator == 'n'
                        plot(app.UIAxes, app.current_side.x_2D_tendon_upper_pos,app.current_side.y_2D_tendon_upper_pos,'bO','LineWidth',line_width,'MarkerSize',marker_size)
                        plot(app.UIAxes, app.current_side.x_2D_tendon_lower_pos,app.current_side.y_2D_tendon_lower_pos,'rO','LineWidth',line_width,'MarkerSize',marker_size)
                        
                    elseif app.current_side.tendon_attachment_indicator == 'r'
                        plot(app.UIAxes, app.current_side.x_2D_tendon_lower_pos,app.current_side.y_2D_tendon_lower_pos,'bO','LineWidth',line_width,'MarkerSize',marker_size)
                        plot(app.UIAxes, app.current_side.x_2D_tendon_upper_pos,app.current_side.y_2D_tendon_upper_pos,'rO','LineWidth',line_width,'MarkerSize',marker_size)
                    end
                end
                plot(app.UIAxes, x_tendon_upper_attachment,y_tendon_upper_attachment,'b+','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, x_tendon_lower_attachment,y_tendon_lower_attachment,'r+','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, x_tendon_upper_attachment,y_tendon_upper_attachment,'bO','LineWidth',line_width,'MarkerSize',marker_size)
                plot(app.UIAxes, x_tendon_lower_attachment,y_tendon_lower_attachment,'rO','LineWidth',line_width,'MarkerSize',marker_size)

                plot(app.UIAxes, [app.current_side.x_2D_passive_center x_tendon_upper_attachment], [app.current_side.y_2D_passive_center y_tendon_upper_attachment], "Color",'k','LineWidth',line_width)
                plot(app.UIAxes, [app.current_side.x_2D_passive_center x_tendon_lower_attachment], [app.current_side.y_2D_passive_center y_tendon_lower_attachment], "Color",'k','LineWidth',line_width)
                plot(app.UIAxes, [app.current_side.x_2D_passive_center app.current_side.x_free_passive_red], [app.current_side.y_2D_passive_center app.current_side.y_free_passive_red], "Color",'k','LineWidth',line_width)
                plot(app.UIAxes, [app.current_side.x_2D_passive_center app.current_side.x_free_passive_blue], [app.current_side.y_2D_passive_center app.current_side.y_free_passive_blue], "Color",'k','LineWidth',line_width)
            end


            a_p_b = app.current_side.theta_blue:pi/90:base_angle_blue;
            x_alpha_passive_blue = app.current_side.x_2D_passive_center + 20 * cos(a_p_b);
            y_alpha_passive_blue = app.current_side.y_2D_passive_center + 20 * sin(a_p_b);
%             plot(app.UIAxes, x_alpha_passive_blue, y_alpha_passive_blue, "Color",'b','LineWidth',line_width)
            plot(app.UIAxes, x_alpha_passive_blue, y_alpha_passive_blue, "Color",'#4CF24C','LineWidth',line_width)

            if app.current_side.maxAngle_indicator == 'r'
                red_inc = app.current_side.maxAngle;
                blue_inc = -app.current_side.minAngle;
            elseif app.current_side.maxAngle_indicator == 'b'
                red_inc = -app.current_side.minAngle; 
                blue_inc = app.current_side.maxAngle;
            end

            x_red_max = app.current_side.x_2D_passive_center + (app.current_side.r_passive+app.current_side.tendon_diameter/2) * cos(base_angle_red+red_inc);
            y_red_max = app.current_side.y_2D_passive_center - (app.current_side.r_passive+app.current_side.tendon_diameter/2) * sin(base_angle_red+red_inc);
            x_blue_max = app.current_side.x_2D_passive_center + (app.current_side.r_passive+app.current_side.tendon_diameter/2) * cos(base_angle_blue+blue_inc);
            y_blue_max = app.current_side.y_2D_passive_center + (app.current_side.r_passive+app.current_side.tendon_diameter/2) * sin(base_angle_blue+blue_inc);

%             plot(app.UIAxes, x_red_max,y_red_max,'r+','LineWidth',line_width,'MarkerSize',marker_size)
%             plot(app.UIAxes, x_blue_max, y_blue_max,'b+','LineWidth',line_width,'MarkerSize',marker_size)

            % THESIS VISUALIZATION
%             a_p_b = base_angle_blue:pi/90:base_angle_blue+blue_inc;
%             x_q_max_passive_blue = app.current_side.x_2D_passive_center + 20 * cos(a_p_b);
%             y_q_max_passive_blue = app.current_side.y_2D_passive_center + 20 * sin(a_p_b);
%             plot(app.UIAxes, x_q_max_passive_blue, y_q_max_passive_blue, "Color",'b','LineWidth',line_width, 'LineStyle','-')
%             x_blue_active_zero = app.current_side.x_2D_active_center + (app.current_side.r_active+app.current_side.tendon_diameter/2) * cos(0);
%             y_blue_active_zero = app.current_side.y_2D_active_center + (app.current_side.r_active+app.current_side.tendon_diameter/2) * sin(0);
%             x_a = x_blue_active_zero - app.current_side.x_2D_active_center;
%             y_a = y_blue_active_zero - app.current_side.y_2D_active_center;
%             x_b = app.current_side.x_free_active_blue - app.current_side.x_2D_active_center;
%             y_b = app.current_side.y_free_active_blue - app.current_side.y_2D_active_center;
%             base_active_angle_blue = acos((x_a*x_b+y_a*y_b)/(sqrt(x_a^2+y_a^2)*sqrt(x_b^2+y_b^2)));
%             plot(app.UIAxes, [app.current_side.x_2D_passive_center x_blue_max], [app.current_side.y_2D_passive_center y_blue_max], "Color",'k','LineWidth',line_width)
%             x_blue_active_max = app.current_side.x_2D_active_center + (app.current_side.r_active+app.current_side.tendon_diameter/2) * cos(base_active_angle_blue-pi/2);
%             y_blue_active_max = app.current_side.y_2D_active_center + (app.current_side.r_active+app.current_side.tendon_diameter/2) * sin(base_active_angle_blue-pi/2);
%             plot(app.UIAxes, x_blue_active_max, y_blue_active_max,'b+','LineWidth',line_width,'MarkerSize',marker_size)
%             a_p_b = base_active_angle_blue-pi/2:pi/90:base_active_angle_blue;
%             x_alpha_active_blue = app.current_side.x_2D_active_center + 10 * cos(a_p_b);
%             y_alpha_active_blue = app.current_side.y_2D_active_center + 10 * sin(a_p_b);
%             plot(app.UIAxes, x_alpha_active_blue, y_alpha_active_blue, "Color",'b','LineWidth',line_width)
%             plot(app.UIAxes, [app.current_side.x_2D_active_center app.current_side.x_free_active_blue], [app.current_side.y_2D_active_center app.current_side.y_free_active_blue], "Color",'k','LineWidth',line_width)
%             plot(app.UIAxes, [app.current_side.x_2D_active_center x_blue_active_max], [app.current_side.y_2D_active_center y_blue_active_max], "Color",'k','LineWidth',line_width)
%             plot(app.UIAxes, [app.current_side.x_2D_passive_center x_tendon_attachment], [app.current_side.y_2D_passive_center y_tendon_attachment], "Color",'k','LineWidth',line_width)

%             a_p_r = base_angle_red:pi/90:base_angle_red+red_inc;
%             x_q_max_passive_red = app.current_side.x_2D_passive_center + 20 * cos(a_p_r);
%             y_q_max_passive_red = app.current_side.y_2D_passive_center - 20 * sin(a_p_r);
%             plot(app.UIAxes, x_q_max_passive_red, y_q_max_passive_red, "Color",'r','LineWidth',line_width, 'LineStyle','-')
%             x_red_active_zero = app.current_side.x_2D_active_center + (app.current_side.r_active+app.current_side.tendon_diameter/2) * cos(0);
%             y_red_active_zero = app.current_side.y_2D_active_center - (app.current_side.r_active+app.current_side.tendon_diameter/2) * sin(0);
%             x_a = x_red_active_zero - app.current_side.x_2D_active_center;
%             y_a = y_red_active_zero - app.current_side.y_2D_active_center;
%             x_b = app.current_side.x_free_active_red - app.current_side.x_2D_active_center;
%             y_b = app.current_side.y_free_active_red - app.current_side.y_2D_active_center;
%             base_active_angle_red = acos((x_a*x_b+y_a*y_b)/(sqrt(x_a^2+y_a^2)*sqrt(x_b^2+y_b^2)));
%             plot(app.UIAxes, [app.current_side.x_2D_passive_center x_red_max], [app.current_side.y_2D_passive_center y_red_max], "Color",'k','LineWidth',line_width)
%             x_red_active_max = app.current_side.x_2D_active_center + (app.current_side.r_active+app.current_side.tendon_diameter/2) * cos(base_active_angle_red-pi/2);
%             y_red_active_max = app.current_side.y_2D_active_center - (app.current_side.r_active+app.current_side.tendon_diameter/2) * sin(base_active_angle_red-pi/2);
%             plot(app.UIAxes, x_red_active_max, y_red_active_max,'r+','LineWidth',line_width,'MarkerSize',marker_size)
%             a_p_r = base_active_angle_red-pi/2:pi/90:base_active_angle_red;
%             x_alpha_active_red = app.current_side.x_2D_active_center + 10 * cos(a_p_r);
%             y_alpha_active_red = app.current_side.y_2D_active_center - 10 * sin(a_p_r);
%             plot(app.UIAxes, x_alpha_active_red, y_alpha_active_red, "Color",'r','LineWidth',line_width)
%             plot(app.UIAxes, [app.current_side.x_2D_active_center app.current_side.x_free_active_red], [app.current_side.y_2D_active_center app.current_side.y_free_active_red], "Color",'k','LineWidth',line_width)
%             plot(app.UIAxes, [app.current_side.x_2D_active_center x_red_active_max], [app.current_side.y_2D_active_center y_red_active_max], "Color",'k','LineWidth',line_width)
%             plot(app.UIAxes, [app.current_side.x_2D_passive_center x_tendon_attachment], [app.current_side.y_2D_passive_center y_tendon_attachment], "Color",'k','LineWidth',line_width)


            axis(app.UIAxes, 'equal')
            axis(app.UIAxes, 'off')
%             app.UIPanel.BackgroundColor = 'black';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, joint, joint_side)
            app.MainApp = caller;

            app.current_joint = joint;
            app.current_side = joint_side.tendons;

            app.JointLabel.Text = app.current_joint.name;
            app.JointSideLabel.Text = app.current_side.name;

            if app.current_joint.name == "Upperarm Roll"
                app.SwapTendonAttachmentsButton.Visible = true;
            else
                app.SwapTendonAttachmentsButton.Visible = false;
            end
            app.BlueAngle180CheckBox.Value = app.current_side.blue_alpha_passive_oversized;
            app.RedAngle180CheckBox.Value = app.current_side.red_alpha_passive_oversized;

            if app.current_side.pos_tendon == 'r'
                app.PositiveTendonDropDown.Value = "Red";
                app.NegativeTendonDropDown.Value = "Blue";
            elseif app.current_side.pos_tendon == 'b'
                app.PositiveTendonDropDown.Value = "Blue";
                app.NegativeTendonDropDown.Value = "Red";
            end

            [app.red_tendon_length, app.blue_tendon_length] = app.current_side.calcTendonLength();
%             app.current_joint.red_tendon_length = app.red_tendon_length;
%             app.current_joint.blue_tendon_length = app.blue_tendon_length;

            app.RedTendonLengthLabel.Text = "Red Tendon Length: "+string(app.red_tendon_length)+" mm";
            app.BlueTendonLengthLabel.Text = "Blue Tendon Length: "+string(app.blue_tendon_length)+" mm";

            app.visualizeData();
        end

        % Button pushed function: SwapMaxAngleButton
        function SwapMaxAngleButtonPushed(app, event)
            if app.current_side.maxAngle_indicator == 'b'
                app.current_side.maxAngle_indicator = 'r';
            else
                app.current_side.maxAngle_indicator = 'b';
            end
            
            [app.red_tendon_length, app.blue_tendon_length] = app.current_side.calcTendonLength();

            app.RedTendonLengthLabel.Text = "Red Tendon Length: "+string(app.red_tendon_length)+" mm";
            app.BlueTendonLengthLabel.Text = "Blue Tendon Length: "+string(app.blue_tendon_length)+" mm";

            app.visualizeData();
        end

        % Value changed function: BlueAngle180CheckBox
        function BlueAngle180CheckBoxValueChanged(app, event)
            value = app.BlueAngle180CheckBox.Value;
            app.current_side.blue_alpha_passive_oversized = value;

            [app.red_tendon_length, app.blue_tendon_length] = app.current_side.calcTendonLength();

            app.RedTendonLengthLabel.Text = "Red Tendon Length: "+string(app.red_tendon_length)+" mm";
            app.BlueTendonLengthLabel.Text = "Blue Tendon Length: "+string(app.blue_tendon_length)+" mm";

            app.visualizeData();
        end

        % Value changed function: RedAngle180CheckBox
        function RedAngle180CheckBoxValueChanged(app, event)
            value = app.RedAngle180CheckBox.Value;
            app.current_side.red_alpha_passive_oversized = value;

            [app.red_tendon_length, app.blue_tendon_length] = app.current_side.calcTendonLength();

            app.RedTendonLengthLabel.Text = "Red Tendon Length: "+string(app.red_tendon_length)+" mm";
            app.BlueTendonLengthLabel.Text = "Blue Tendon Length: "+string(app.blue_tendon_length)+" mm";

            app.visualizeData();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.update_file();

            app.MainApp.JointDropDown.Enable = true;
            app.MainApp.ShowTendonLengthsButton.Enable = true;
            app.MainApp.EditButton.Enable = true;
            app.MainApp.JointSideDropDown.Enable = true;
            delete(app)
            
        end

        % Button pushed function: SwapTendonAttachmentsButton
        function SwapTendonAttachmentsButtonPushed(app, event)
            if app.current_side.tendon_attachment_indicator == 'n'
                app.current_side.tendon_attachment_indicator = 'r';
            elseif app.current_side.tendon_attachment_indicator == 'r'
                app.current_side.tendon_attachment_indicator = 'n';
            end

            [app.red_tendon_length, app.blue_tendon_length] = app.current_side.calcTendonLength();

            app.RedTendonLengthLabel.Text = "Red Tendon Length: "+string(app.red_tendon_length)+" mm";
            app.BlueTendonLengthLabel.Text = "Blue Tendon Length: "+string(app.blue_tendon_length)+" mm";

            app.visualizeData();
        end

        % Value changed function: 
        % VisualizeTendonAttachmentPositionsfromCADCheckBox
        function VisualizeTendonAttachmentPositionsfromCADCheckBoxValueChanged(app, event)
            value = app.VisualizeTendonAttachmentPositionsfromCADCheckBox.Value;

            app.visualize_true_tendon_pos = value;

            app.visualizeData();
        end

        % Value changed function: PositiveTendonDropDown
        function PositiveTendonDropDownValueChanged(app, event)
            value = app.PositiveTendonDropDown.Value;
            
            if value == "Red"
                app.current_side.pos_tendon = 'r';
                app.current_side.neg_tendon = 'b';
                app.NegativeTendonDropDown.Value = "Blue";
            elseif value == "Blue"
                app.current_side.pos_tendon = 'b';
                app.current_side.neg_tendon = 'r';
                app.NegativeTendonDropDown.Value = "Red";
            end

        end

        % Value changed function: NegativeTendonDropDown
        function NegativeTendonDropDownValueChanged(app, event)
            value = app.NegativeTendonDropDown.Value;
            
            if value == "Red"
                app.current_side.pos_tendon = 'b';
                app.current_side.neg_tendon = 'r';
                app.PositiveTendonDropDown.Value = "Blue";
            elseif value == "Blue"
                app.current_side.pos_tendon = 'r';
                app.current_side.neg_tendon = 'b';
                app.PositiveTendonDropDown.Value = "Red";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {695, 695};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {350, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RedTendonLengthLabel = uilabel(app.LeftPanel);
            app.RedTendonLengthLabel.Text = 'Red Tendon Length: ';
            app.BlueTendonLengthLabel = uilabel(app.LeftPanel);
            app.BlueTendonLengthLabel.Text = 'Blue Tendon Length: ';
            app.SwapMaxAngleButton = uibutton(app.LeftPanel, 'push');
            app.SwapMaxAngleButton.ButtonPushedFcn = createCallbackFcn(app, @SwapMaxAngleButtonPushed, true);
            app.SwapMaxAngleButton.Text = 'Swap Max Angle';
            app.BlueAngle180CheckBox = uicheckbox(app.LeftPanel);
            app.BlueAngle180CheckBox.ValueChangedFcn = createCallbackFcn(app, @BlueAngle180CheckBoxValueChanged, true);
            app.BlueAngle180CheckBox.Text = 'Blue Angle > 180°';
            app.RedAngle180CheckBox = uicheckbox(app.LeftPanel);
            app.RedAngle180CheckBox.ValueChangedFcn = createCallbackFcn(app, @RedAngle180CheckBoxValueChanged, true);
            app.RedAngle180CheckBox.Text = 'Red Angle > 180°';
            app.JointLabel = uilabel(app.LeftPanel);
            app.JointLabel.Text = 'Upperarm Roll';
            app.JointSideLabel = uilabel(app.LeftPanel);
            app.JointSideLabel.Text = 'Left Side:';
            app.SwapTendonAttachmentsButton = uibutton(app.LeftPanel, 'push');
            app.SwapTendonAttachmentsButton.ButtonPushedFcn = createCallbackFcn(app, @SwapTendonAttachmentsButtonPushed, true);
            app.SwapTendonAttachmentsButton.Text = 'Swap Tendon Attachments';
            app.VisualizeTendonAttachmentPositionsfromCADCheckBox = uicheckbox(app.LeftPanel);
            app.VisualizeTendonAttachmentPositionsfromCADCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizeTendonAttachmentPositionsfromCADCheckBoxValueChanged, true);
            app.VisualizeTendonAttachmentPositionsfromCADCheckBox.Text = 'Visualize Tendon Attachment Positions from CAD';
            app.PositiveTendonDropDownLabel = uilabel(app.LeftPanel);
            app.PositiveTendonDropDownLabel.Text = 'Positive Tendon:';
            app.PositiveTendonDropDown = uidropdown(app.LeftPanel);
            app.PositiveTendonDropDown.Items = {'Red', 'Blue'};
            app.PositiveTendonDropDown.ValueChangedFcn = createCallbackFcn(app, @PositiveTendonDropDownValueChanged, true);
            app.PositiveTendonDropDown.Value = 'Red';
            app.NegativeTendonDropDownLabel = uilabel(app.LeftPanel);
            app.NegativeTendonDropDownLabel.Text = 'Negative Tendon:';
            app.NegativeTendonDropDown = uidropdown(app.LeftPanel);
            app.NegativeTendonDropDown.Items = {'Red', 'Blue'};
            app.NegativeTendonDropDown.ValueChangedFcn = createCallbackFcn(app, @NegativeTendonDropDownValueChanged, true);
            app.NegativeTendonDropDown.Value = 'Blue';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TendonLength(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end