classdef tendon_pulley < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CreateSimConfigFileButton     matlab.ui.control.Button
        RadiusMiddlePulleyLabel       matlab.ui.control.Label
        RadiusMiddlePulleyEditField   matlab.ui.control.NumericEditField
        RadiusMiddlePulleyEditFieldLabel  matlab.ui.control.Label
        TendonDiameterEditField       matlab.ui.control.NumericEditField
        TendonDiameterEditFieldLabel  matlab.ui.control.Label
        TendonDiameterLabel           matlab.ui.control.Label
        maxAngleEditField             matlab.ui.control.NumericEditField
        maxAngleEditFieldLabel        matlab.ui.control.Label
        maxAngleLabel                 matlab.ui.control.Label
        minAngleEditField             matlab.ui.control.NumericEditField
        minAngleEditFieldLabel        matlab.ui.control.Label
        minAngleLabel                 matlab.ui.control.Label
        RadiusPassivePulleyEditField  matlab.ui.control.NumericEditField
        RadiusPassivePulleyEditFieldLabel  matlab.ui.control.Label
        RadiusPassivePulleyLabel      matlab.ui.control.Label
        PulleyPositionPanel           matlab.ui.container.Panel
        MiddlePulleyZEditField        matlab.ui.control.NumericEditField
        MiddlePulleyZEditFieldLabel   matlab.ui.control.Label
        MiddlePulleyYEditField        matlab.ui.control.NumericEditField
        MiddlePulleyYEditFieldLabel   matlab.ui.control.Label
        MiddlePulleyXEditField        matlab.ui.control.NumericEditField
        MiddlePulleyXEditFieldLabel   matlab.ui.control.Label
        MiddlePulleyZLabel            matlab.ui.control.Label
        MiddlePulleyYLabel            matlab.ui.control.Label
        MiddlePulleyXLabel            matlab.ui.control.Label
        MiddlePulleyPositionLabel     matlab.ui.control.Label
        TendonLowerZLabel             matlab.ui.control.Label
        TendonLowerYLabel             matlab.ui.control.Label
        TendonLowerXLabel             matlab.ui.control.Label
        TendonUpperZLabel             matlab.ui.control.Label
        TendonUpperYLabel             matlab.ui.control.Label
        TendonUpperXLabel             matlab.ui.control.Label
        PassivePulleyZLabel           matlab.ui.control.Label
        PassivePulleyYLabel           matlab.ui.control.Label
        PassivePulleyXLabel           matlab.ui.control.Label
        ActivePulleyZLabel            matlab.ui.control.Label
        ActivePulleyYLabel            matlab.ui.control.Label
        ActivePulleyXLabel            matlab.ui.control.Label
        ycoordinateidDropDown         matlab.ui.control.DropDown
        ycoordinateidDropDownLabel    matlab.ui.control.Label
        xcoordinateidDropDown         matlab.ui.control.DropDown
        xcoordinateidDropDownLabel    matlab.ui.control.Label
        AttachmentPositionsDropDown   matlab.ui.control.DropDown
        AttachmentPositionsDropDownLabel  matlab.ui.control.Label
        TendonLowerZEditField         matlab.ui.control.NumericEditField
        TendonLowerZEditFieldLabel    matlab.ui.control.Label
        TendonLowerYEditField         matlab.ui.control.NumericEditField
        TendonLowerYEditFieldLabel    matlab.ui.control.Label
        TendonLowerXEditField         matlab.ui.control.NumericEditField
        TendonLowerXEditFieldLabel    matlab.ui.control.Label
        TendonUpperZEditField         matlab.ui.control.NumericEditField
        TendonUpperZEditFieldLabel    matlab.ui.control.Label
        TendonUpperYEditField         matlab.ui.control.NumericEditField
        TendonUpperYEditFieldLabel    matlab.ui.control.Label
        TendonUpperXEditField         matlab.ui.control.NumericEditField
        TendonUpperXEditFieldLabel    matlab.ui.control.Label
        TendonLowerAttachmentPositionLabel  matlab.ui.control.Label
        TendonUpperAttachmentPositionLabel  matlab.ui.control.Label
        PassivePulleyZEditField       matlab.ui.control.NumericEditField
        PassivePulleyZEditFieldLabel  matlab.ui.control.Label
        PassivePulleyYEditField       matlab.ui.control.NumericEditField
        PassivePulleyYEditFieldLabel  matlab.ui.control.Label
        PassivePulleyXEditField       matlab.ui.control.NumericEditField
        PassivePulleyXEditFieldLabel  matlab.ui.control.Label
        PassivePulleyPositionLabel    matlab.ui.control.Label
        JointSideDropDown             matlab.ui.control.DropDown
        JointSideDropDownLabel        matlab.ui.control.Label
        ActivePulleyZEditField        matlab.ui.control.NumericEditField
        ActivePulleyZEditFieldLabel   matlab.ui.control.Label
        ActivePulleyYEditField        matlab.ui.control.NumericEditField
        ActivePulleyYEditFieldLabel   matlab.ui.control.Label
        ActivePulleyXEditField        matlab.ui.control.NumericEditField
        ActivePulleyXEditFieldLabel   matlab.ui.control.Label
        ActivePulleyPositionLabel     matlab.ui.control.Label
        ShowPulleyConfigButton        matlab.ui.control.Button
        ShowTendonLengthsButton       matlab.ui.control.Button
        RadiusActivePulleyLabel       matlab.ui.control.Label
        RadiusActivePulleyEditField   matlab.ui.control.NumericEditField
        RadiusActivePulleyLabel_2     matlab.ui.control.Label
        EditButton                    matlab.ui.control.Button
        JointDropDown                 matlab.ui.control.DropDown
        JointDropDownLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        appdata % Description
        shoulder_yaw % Description
        shoulder_roll % Description
        shoulder_pitch
        upperarm_roll
        elbow
        wrist_pitch
        current_joint % Description
        current_side % Description
        TendonLengthApp % Description
        PulleyConfigApp % Description
        ExportConfigApp % Description
    end
    
    methods (Access = private)
        
        function FillElements(app)
            %FUNCTION - Fills Label Components according to the values in
            %the EditField Components
            app.RadiusActivePulleyEditField.Value = app.current_joint.r_active;
            app.RadiusActivePulleyLabel.Text = string(app.RadiusActivePulleyEditField.Value)+" mm";
            app.RadiusPassivePulleyEditField.Value = app.current_joint.r_passive;
            app.RadiusPassivePulleyLabel.Text = string(app.RadiusPassivePulleyEditField.Value)+" mm";
            app.minAngleEditField.Value = app.current_joint.minAngle;
            app.minAngleLabel.Text = string(app.minAngleEditField.Value)+" rad";
            app.maxAngleEditField.Value = app.current_joint.maxAngle;
            app.maxAngleLabel.Text = string(app.maxAngleEditField.Value)+" rad";
            app.TendonDiameterEditField.Value = app.current_joint.tendon_diameter;
            app.TendonDiameterLabel.Text = string(app.TendonDiameterEditField.Value)+" mm";         

            if app.current_joint.number_pulleys == 3
                app.RadiusMiddlePulleyEditField.Value = app.current_joint.r_middle; 
            else
                app.RadiusMiddlePulleyEditField.Value = 0;
            end
            app.RadiusMiddlePulleyLabel.Text = string(app.RadiusMiddlePulleyEditField.Value)+" mm";

            app.fillSideElements();
        end

        function fillSideElements(app)
            %FUNCTION - Fills Label Components according to the values in
            %the EditField Components in the Joint Side Panel

            app.ActivePulleyXEditField.Value = app.current_side.tendons.x_active_pos;
            app.ActivePulleyYEditField.Value = app.current_side.tendons.y_active_pos;
            app.ActivePulleyZEditField.Value = app.current_side.tendons.z_active_pos;
            app.PassivePulleyXEditField.Value = app.current_side.tendons.x_passive_pos;
            app.PassivePulleyYEditField.Value = app.current_side.tendons.y_passive_pos;
            app.PassivePulleyZEditField.Value = app.current_side.tendons.z_passive_pos;

            app.ActivePulleyXLabel.Text = string(app.ActivePulleyXEditField.Value)+" mm";
            app.ActivePulleyYLabel.Text = string(app.ActivePulleyYEditField.Value)+" mm";
            app.ActivePulleyZLabel.Text = string(app.ActivePulleyZEditField.Value)+" mm";
            app.PassivePulleyXLabel.Text = string(app.PassivePulleyXEditField.Value)+" mm";
            app.PassivePulleyYLabel.Text = string(app.PassivePulleyYEditField.Value)+" mm";
            app.PassivePulleyZLabel.Text = string(app.PassivePulleyZEditField.Value)+" mm";

            if app.current_joint.number_tendon_attachments == 1
                app.TendonUpperXEditField.Value = app.current_side.tendons.x_tendon_pos;
                app.TendonUpperYEditField.Value = app.current_side.tendons.y_tendon_pos;
                app.TendonUpperZEditField.Value = app.current_side.tendons.z_tendon_pos;
                app.TendonLowerXEditField.Value = 0.000;
                app.TendonLowerYEditField.Value = 0.000;
                app.TendonLowerZEditField.Value = 0.000;
            elseif app.current_joint.number_tendon_attachments == 2
                app.TendonUpperXEditField.Value = app.current_side.tendons.x_tendon_upper_pos;
                app.TendonUpperYEditField.Value = app.current_side.tendons.y_tendon_upper_pos;
                app.TendonUpperZEditField.Value = app.current_side.tendons.z_tendon_upper_pos;
                app.TendonLowerXEditField.Value = app.current_side.tendons.x_tendon_lower_pos;
                app.TendonLowerYEditField.Value = app.current_side.tendons.y_tendon_lower_pos;
                app.TendonLowerZEditField.Value = app.current_side.tendons.z_tendon_lower_pos;
            end

            if app.current_joint.number_pulleys == 3
                app.MiddlePulleyXEditField.Value = app.current_side.tendons.x_middle_pos;
                app.MiddlePulleyYEditField.Value = app.current_side.tendons.y_middle_pos;
                app.MiddlePulleyZEditField.Value = app.current_side.tendons.z_middle_pos;
            else
                app.MiddlePulleyXEditField.Value = 0.000;
                app.MiddlePulleyYEditField.Value = 0.000;
                app.MiddlePulleyZEditField.Value = 0.000;
            end

            app.TendonUpperXLabel.Text = string(app.TendonUpperXEditField.Value)+" mm";
            app.TendonUpperYLabel.Text = string(app.TendonUpperYEditField.Value)+" mm";
            app.TendonUpperZLabel.Text = string(app.TendonUpperZEditField.Value)+" mm";
            app.TendonLowerXLabel.Text = string(app.TendonLowerXEditField.Value)+" mm";
            app.TendonLowerYLabel.Text = string(app.TendonLowerYEditField.Value)+" mm";
            app.TendonLowerZLabel.Text = string(app.TendonLowerZEditField.Value)+" mm";
            app.MiddlePulleyXLabel.Text = string(app.MiddlePulleyXEditField.Value)+" mm";
            app.MiddlePulleyYLabel.Text = string(app.MiddlePulleyYEditField.Value)+" mm";
            app.MiddlePulleyZLabel.Text = string(app.MiddlePulleyZEditField.Value)+" mm";

            app.xcoordinateidDropDown.Value = app.current_side.tendons.x_coordinate_id;
            app.ycoordinateidDropDown.Value = app.current_side.tendons.y_coordinate_id;
            
        end
        
        function save_data(app)
            %FUNCTION - Saves the current Component Values into the
            %appropriate Class Objects of the currently selected Joint

            app.current_joint.r_active = app.RadiusActivePulleyEditField.Value;
            app.current_joint.r_passive = app.RadiusPassivePulleyEditField.Value;
            app.current_joint.minAngle = app.minAngleEditField.Value;
            app.current_joint.maxAngle = app.maxAngleEditField.Value;
            app.current_joint.tendon_diameter = app.TendonDiameterEditField.Value;

            if app.current_joint.number_pulleys == 3
                app.current_joint.r_middle = app.RadiusMiddlePulleyEditField.Value;
            end

            app.save_joint_side();
        end

        function save_to_file(app)
            %FUNCTION - Saves the Class Object Properties of the current
            %joint in the .json configuration file
            joint_data.minAngle = app.current_joint.minAngle;
            joint_data.maxAngle = app.current_joint.maxAngle;
            joint_data.r_active = app.current_joint.r_active;
            joint_data.r_passive = app.current_joint.r_passive;
            joint_data.tendon_diameter = app.current_joint.tendon_diameter;
            joint_data.number_tendon_attachments = app.current_joint.number_tendon_attachments;
            joint_data.number_pulleys = app.current_joint.number_pulleys;
            joint_data.right_pos_actuator_id = app.current_joint.right_pos_actuator_id;
            joint_data.right_neg_actuator_id = app.current_joint.right_neg_actuator_id;
            joint_data.left_pos_actuator_id = app.current_joint.left_pos_actuator_id;
            joint_data.left_neg_actuator_id = app.current_joint.left_neg_actuator_id;
            joint_data.inner_active_mounting_length = app.current_joint.inner_active_mounting_length;
            joint_data.outer_active_mounting_length = app.current_joint.outer_active_mounting_length;
            joint_data.passive_mounting_length = app.current_joint.passive_mounting_length;
            joint_data.sleeve_assembly_extra = app.current_joint.sleeve_assembly_extra;
            joint_data.sleeve_cutting_ratio = app.current_joint.sleeve_cutting_ratio;
            
            joint_data.right.tendons.x_active_pos = app.current_joint.right.tendons.x_active_pos;
            joint_data.right.tendons.y_active_pos = app.current_joint.right.tendons.y_active_pos;
            joint_data.right.tendons.z_active_pos = app.current_joint.right.tendons.z_active_pos;
            joint_data.right.tendons.x_passive_pos = app.current_joint.right.tendons.x_passive_pos;
            joint_data.right.tendons.y_passive_pos = app.current_joint.right.tendons.y_passive_pos;
            joint_data.right.tendons.z_passive_pos = app.current_joint.right.tendons.z_passive_pos;
            joint_data.right.tendons.x_coordinate_id = app.current_joint.right.tendons.x_coordinate_id;
            joint_data.right.tendons.y_coordinate_id = app.current_joint.right.tendons.y_coordinate_id;
            joint_data.right.tendons.maxAngle_indicator = app.current_joint.right.tendons.maxAngle_indicator;
            joint_data.right.tendons.blue_alpha_passive_oversized = app.current_joint.right.tendons.blue_alpha_passive_oversized;
            joint_data.right.tendons.red_alpha_passive_oversized = app.current_joint.right.tendons.red_alpha_passive_oversized;
            joint_data.right.tendons.pos_tendon = app.current_joint.right.tendons.pos_tendon;
            joint_data.right.tendons.neg_tendon = app.current_joint.right.tendons.neg_tendon;

            joint_data.left.tendons.x_active_pos = app.current_joint.left.tendons.x_active_pos;
            joint_data.left.tendons.y_active_pos = app.current_joint.left.tendons.y_active_pos;
            joint_data.left.tendons.z_active_pos = app.current_joint.left.tendons.z_active_pos;
            joint_data.left.tendons.x_passive_pos = app.current_joint.left.tendons.x_passive_pos;
            joint_data.left.tendons.y_passive_pos = app.current_joint.left.tendons.y_passive_pos;
            joint_data.left.tendons.z_passive_pos = app.current_joint.left.tendons.z_passive_pos;
            joint_data.left.tendons.x_coordinate_id = app.current_joint.left.tendons.x_coordinate_id;
            joint_data.left.tendons.y_coordinate_id = app.current_joint.left.tendons.y_coordinate_id;
            joint_data.left.tendons.maxAngle_indicator = app.current_joint.left.tendons.maxAngle_indicator;
            joint_data.left.tendons.blue_alpha_passive_oversized = app.current_joint.left.tendons.blue_alpha_passive_oversized;
            joint_data.left.tendons.red_alpha_passive_oversized = app.current_joint.left.tendons.red_alpha_passive_oversized;
            joint_data.left.tendons.pos_tendon = app.current_joint.left.tendons.pos_tendon;
            joint_data.left.tendons.neg_tendon = app.current_joint.left.tendons.neg_tendon;

            if app.current_joint.number_tendon_attachments == 1
                joint_data.right.tendons.x_tendon_pos = app.current_joint.right.tendons.x_tendon_pos;
                joint_data.right.tendons.y_tendon_pos = app.current_joint.right.tendons.y_tendon_pos;
                joint_data.right.tendons.z_tendon_pos = app.current_joint.right.tendons.z_tendon_pos;

                joint_data.left.tendons.x_tendon_pos = app.current_joint.left.tendons.x_tendon_pos;
                joint_data.left.tendons.y_tendon_pos = app.current_joint.left.tendons.y_tendon_pos;
                joint_data.left.tendons.z_tendon_pos = app.current_joint.left.tendons.z_tendon_pos;
            elseif app.current_joint.number_tendon_attachments == 2
                joint_data.right.tendons.x_tendon_upper_pos = app.current_joint.right.tendons.x_tendon_upper_pos;
                joint_data.right.tendons.y_tendon_upper_pos = app.current_joint.right.tendons.y_tendon_upper_pos;
                joint_data.right.tendons.z_tendon_upper_pos = app.current_joint.right.tendons.z_tendon_upper_pos;
                joint_data.right.tendons.x_tendon_lower_pos = app.current_joint.right.tendons.x_tendon_lower_pos;
                joint_data.right.tendons.y_tendon_lower_pos = app.current_joint.right.tendons.y_tendon_lower_pos;
                joint_data.right.tendons.z_tendon_lower_pos = app.current_joint.right.tendons.z_tendon_lower_pos;

                joint_data.left.tendons.x_tendon_upper_pos = app.current_joint.left.tendons.x_tendon_upper_pos;
                joint_data.left.tendons.y_tendon_upper_pos = app.current_joint.left.tendons.y_tendon_upper_pos;
                joint_data.left.tendons.z_tendon_upper_pos = app.current_joint.left.tendons.z_tendon_upper_pos;
                joint_data.left.tendons.x_tendon_lower_pos = app.current_joint.left.tendons.x_tendon_lower_pos;
                joint_data.left.tendons.y_tendon_lower_pos = app.current_joint.left.tendons.y_tendon_lower_pos;
                joint_data.left.tendons.z_tendon_lower_pos = app.current_joint.left.tendons.z_tendon_lower_pos;

                joint_data.right.tendons.tendon_attachment_indicator = app.current_joint.right.tendons.tendon_attachment_indicator;
                joint_data.left.tendons.tendon_attachment_indicator = app.current_joint.left.tendons.tendon_attachment_indicator;
            end

            if app.current_joint.number_pulleys == 3
                joint_data.r_middle = app.current_joint.r_middle;

                joint_data.right.tendons.x_middle_pos = app.current_joint.right.tendons.x_middle_pos;
                joint_data.right.tendons.y_middle_pos = app.current_joint.right.tendons.y_middle_pos;
                joint_data.right.tendons.z_middle_pos = app.current_joint.right.tendons.z_middle_pos;

                joint_data.left.tendons.x_middle_pos = app.current_joint.left.tendons.x_middle_pos;
                joint_data.left.tendons.y_middle_pos = app.current_joint.left.tendons.y_middle_pos;
                joint_data.left.tendons.z_middle_pos = app.current_joint.left.tendons.z_middle_pos;
            end

            joint_data.right.inner_pulley.l_max_ratio = app.current_joint.right.inner_pulley.l_max_ratio;
            joint_data.right.inner_pulley.pretensioning = app.current_joint.right.inner_pulley.pretensioning;
            joint_data.right.inner_pulley.tendon_id = app.current_joint.right.inner_pulley.tendon_id;
            joint_data.right.inner_pulley.active_top_indicator = app.current_joint.right.inner_pulley.active_top_indicator;
            joint_data.right.inner_pulley.pulley_numbers_switch = app.current_joint.right.inner_pulley.pulley_numbers_switch;
            joint_data.right.outer_pulley.l_max_ratio = app.current_joint.right.outer_pulley.l_max_ratio;
            joint_data.right.outer_pulley.pretensioning = app.current_joint.right.outer_pulley.pretensioning;
            joint_data.right.outer_pulley.tendon_id = app.current_joint.right.outer_pulley.tendon_id;
            joint_data.right.outer_pulley.active_top_indicator = app.current_joint.right.outer_pulley.active_top_indicator;
            joint_data.right.outer_pulley.pulley_numbers_switch = app.current_joint.right.outer_pulley.pulley_numbers_switch;
            joint_data.left.inner_pulley.l_max_ratio = app.current_joint.left.inner_pulley.l_max_ratio;
            joint_data.left.inner_pulley.pretensioning = app.current_joint.left.inner_pulley.pretensioning;
            joint_data.left.inner_pulley.tendon_id = app.current_joint.left.inner_pulley.tendon_id;
            joint_data.left.inner_pulley.active_top_indicator = app.current_joint.left.inner_pulley.active_top_indicator;
            joint_data.left.inner_pulley.pulley_numbers_switch = app.current_joint.left.inner_pulley.pulley_numbers_switch;
            joint_data.left.outer_pulley.l_max_ratio = app.current_joint.left.outer_pulley.l_max_ratio;
            joint_data.left.outer_pulley.pretensioning = app.current_joint.left.outer_pulley.pretensioning;
            joint_data.left.outer_pulley.tendon_id = app.current_joint.left.outer_pulley.tendon_id;
            joint_data.left.outer_pulley.active_top_indicator = app.current_joint.left.outer_pulley.active_top_indicator;
            joint_data.left.outer_pulley.pulley_numbers_switch = app.current_joint.left.outer_pulley.pulley_numbers_switch;

            if app.JointDropDown.Value == "Shoulder Yaw"
                app.appdata.shoulder_yaw = joint_data;
            elseif app.JointDropDown.Value == "Shoulder Roll"
                app.appdata.shoulder_roll = joint_data;
            elseif app.JointDropDown.Value == "Shoulder Pitch"
                app.appdata.shoulder_pitch = joint_data;
            elseif app.JointDropDown.Value == "Upperarm Roll"
                app.appdata.upperarm_roll = joint_data;
            elseif app.JointDropDown.Value == "Elbow"
                app.appdata.elbow = joint_data;
            elseif app.JointDropDown.Value == "Wrist Pitch"
                app.appdata.wrist_pitch = joint_data;
            end

            jsonText = jsonencode(app.appdata, "PrettyPrint",true);

            fid = fopen('app_config/AppData.json', 'w');
            fprintf(fid, '%s', jsonText);
            fclose(fid);

        end
        
        function save_joint_side(app)
            %FUNCTION - Saves the current Component Values into the
            %appropriate Class Objects of the currently selected Joint Side
            app.current_side.tendons.r_active = app.RadiusActivePulleyEditField.Value;
            app.current_side.tendons.r_passive = app.RadiusPassivePulleyEditField.Value;
            app.current_side.tendons.minAngle = app.minAngleEditField.Value;
            app.current_side.tendons.maxAngle = app.maxAngleEditField.Value;
            app.current_side.tendons.tendon_diameter = app.TendonDiameterEditField.Value;

            app.current_side.tendons.x_active_pos = app.ActivePulleyXEditField.Value;
            app.current_side.tendons.y_active_pos = app.ActivePulleyYEditField.Value;
            app.current_side.tendons.z_active_pos = app.ActivePulleyZEditField.Value;
            app.current_side.tendons.x_passive_pos = app.PassivePulleyXEditField.Value;
            app.current_side.tendons.y_passive_pos = app.PassivePulleyYEditField.Value;
            app.current_side.tendons.z_passive_pos = app.PassivePulleyZEditField.Value;

            app.current_side.tendons.x_coordinate_id = app.xcoordinateidDropDown.Value;
            app.current_side.tendons.y_coordinate_id = app.ycoordinateidDropDown.Value;

            if app.current_joint.number_tendon_attachments == 1
                app.current_side.tendons.x_tendon_pos = app.TendonUpperXEditField.Value;
                app.current_side.tendons.y_tendon_pos = app.TendonUpperYEditField.Value;
                app.current_side.tendons.z_tendon_pos = app.TendonUpperZEditField.Value;
            elseif app.current_joint.number_tendon_attachments == 2
                app.current_side.tendons.x_tendon_upper_pos = app.TendonUpperXEditField.Value;
                app.current_side.tendons.y_tendon_upper_pos = app.TendonUpperYEditField.Value;
                app.current_side.tendons.z_tendon_upper_pos = app.TendonUpperZEditField.Value;
                app.current_side.tendons.x_tendon_lower_pos = app.TendonLowerXEditField.Value;
                app.current_side.tendons.y_tendon_lower_pos = app.TendonLowerYEditField.Value;
                app.current_side.tendons.z_tendon_lower_pos = app.TendonLowerZEditField.Value;
            end
            
            if app.current_joint.number_pulleys == 3
                app.current_side.tendons.r_middle = app.RadiusMiddlePulleyEditField.Value;

                app.current_side.tendons.x_middle_pos = app.MiddlePulleyXEditField.Value;
                app.current_side.tendons.y_middle_pos = app.MiddlePulleyYEditField.Value;
                app.current_side.tendons.z_middle_pos = app.MiddlePulleyZEditField.Value;
            end
        end
        
        function CreateDataStruct(app)
            %FUNCTION - Uses the configuration data from the .json file and
            %instantiates Class Objects for each joint of the arm (Shoulder
            %Yaw, Shoulder Roll, Shoulder Pitch, Upperarm Roll, Elbow,
            %Wrist Pitch)

            % Shoulder Yaw Joint
            app.shoulder_yaw = JointClass;
            app.shoulder_yaw.name = "Shoulder Yaw";
            app.shoulder_yaw.minAngle = app.appdata.shoulder_yaw.minAngle;
            app.shoulder_yaw.maxAngle = app.appdata.shoulder_yaw.maxAngle;
            app.shoulder_yaw.r_active = app.appdata.shoulder_yaw.r_active;
            app.shoulder_yaw.r_passive = app.appdata.shoulder_yaw.r_passive;
            app.shoulder_yaw.tendon_diameter = app.appdata.shoulder_yaw.tendon_diameter;
            app.shoulder_yaw.number_tendon_attachments = app.appdata.shoulder_yaw.number_tendon_attachments;
            app.shoulder_yaw.number_pulleys = app.appdata.shoulder_yaw.number_pulleys;
            app.shoulder_yaw.right_pos_actuator_id = app.appdata.shoulder_yaw.right_pos_actuator_id;
            app.shoulder_yaw.right_neg_actuator_id = app.appdata.shoulder_yaw.right_neg_actuator_id;
            app.shoulder_yaw.left_pos_actuator_id = app.appdata.shoulder_yaw.left_pos_actuator_id;
            app.shoulder_yaw.left_neg_actuator_id = app.appdata.shoulder_yaw.left_neg_actuator_id;
            app.shoulder_yaw.inner_active_mounting_length = app.appdata.shoulder_yaw.inner_active_mounting_length;
            app.shoulder_yaw.outer_active_mounting_length = app.appdata.shoulder_yaw.outer_active_mounting_length;
            app.shoulder_yaw.passive_mounting_length = app.appdata.shoulder_yaw.passive_mounting_length;
            app.shoulder_yaw.sleeve_assembly_extra = app.appdata.shoulder_yaw.sleeve_assembly_extra;
            app.shoulder_yaw.sleeve_cutting_ratio = app.appdata.shoulder_yaw.sleeve_cutting_ratio;
            
            app.shoulder_yaw.addNormalSides()

            app.shoulder_yaw.right.tendons.x_active_pos = app.appdata.shoulder_yaw.right.tendons.x_active_pos;
            app.shoulder_yaw.right.tendons.y_active_pos = app.appdata.shoulder_yaw.right.tendons.y_active_pos;
            app.shoulder_yaw.right.tendons.z_active_pos = app.appdata.shoulder_yaw.right.tendons.z_active_pos;
            app.shoulder_yaw.right.tendons.x_passive_pos = app.appdata.shoulder_yaw.right.tendons.x_passive_pos;
            app.shoulder_yaw.right.tendons.y_passive_pos = app.appdata.shoulder_yaw.right.tendons.y_passive_pos;
            app.shoulder_yaw.right.tendons.z_passive_pos = app.appdata.shoulder_yaw.right.tendons.z_passive_pos;
            app.shoulder_yaw.right.tendons.x_tendon_pos = app.appdata.shoulder_yaw.right.tendons.x_tendon_pos;
            app.shoulder_yaw.right.tendons.y_tendon_pos = app.appdata.shoulder_yaw.right.tendons.y_tendon_pos;
            app.shoulder_yaw.right.tendons.z_tendon_pos = app.appdata.shoulder_yaw.right.tendons.z_tendon_pos;
            app.shoulder_yaw.right.tendons.x_coordinate_id = app.appdata.shoulder_yaw.right.tendons.x_coordinate_id;
            app.shoulder_yaw.right.tendons.y_coordinate_id = app.appdata.shoulder_yaw.right.tendons.y_coordinate_id;
            app.shoulder_yaw.right.tendons.maxAngle_indicator = app.appdata.shoulder_yaw.right.tendons.maxAngle_indicator;
            app.shoulder_yaw.right.tendons.blue_alpha_passive_oversized = app.appdata.shoulder_yaw.right.tendons.blue_alpha_passive_oversized;
            app.shoulder_yaw.right.tendons.red_alpha_passive_oversized = app.appdata.shoulder_yaw.right.tendons.red_alpha_passive_oversized;
            app.shoulder_yaw.right.tendons.pos_tendon = app.appdata.shoulder_yaw.right.tendons.pos_tendon;
            app.shoulder_yaw.right.tendons.neg_tendon = app.appdata.shoulder_yaw.right.tendons.neg_tendon;

            app.shoulder_yaw.left.tendons.x_active_pos = app.appdata.shoulder_yaw.left.tendons.x_active_pos;
            app.shoulder_yaw.left.tendons.y_active_pos = app.appdata.shoulder_yaw.left.tendons.y_active_pos;
            app.shoulder_yaw.left.tendons.z_active_pos = app.appdata.shoulder_yaw.left.tendons.z_active_pos;
            app.shoulder_yaw.left.tendons.x_passive_pos = app.appdata.shoulder_yaw.left.tendons.x_passive_pos;
            app.shoulder_yaw.left.tendons.y_passive_pos = app.appdata.shoulder_yaw.left.tendons.y_passive_pos;
            app.shoulder_yaw.left.tendons.z_passive_pos = app.appdata.shoulder_yaw.left.tendons.z_passive_pos;
            app.shoulder_yaw.left.tendons.x_tendon_pos = app.appdata.shoulder_yaw.left.tendons.x_tendon_pos;
            app.shoulder_yaw.left.tendons.y_tendon_pos = app.appdata.shoulder_yaw.left.tendons.y_tendon_pos;
            app.shoulder_yaw.left.tendons.z_tendon_pos = app.appdata.shoulder_yaw.left.tendons.z_tendon_pos;
            app.shoulder_yaw.left.tendons.x_coordinate_id = app.appdata.shoulder_yaw.left.tendons.x_coordinate_id;
            app.shoulder_yaw.left.tendons.y_coordinate_id = app.appdata.shoulder_yaw.left.tendons.y_coordinate_id;
            app.shoulder_yaw.left.tendons.maxAngle_indicator = app.appdata.shoulder_yaw.left.tendons.maxAngle_indicator;
            app.shoulder_yaw.left.tendons.blue_alpha_passive_oversized = app.appdata.shoulder_yaw.left.tendons.blue_alpha_passive_oversized;
            app.shoulder_yaw.left.tendons.red_alpha_passive_oversized = app.appdata.shoulder_yaw.left.tendons.red_alpha_passive_oversized;
            app.shoulder_yaw.left.tendons.pos_tendon = app.appdata.shoulder_yaw.left.tendons.pos_tendon;
            app.shoulder_yaw.left.tendons.neg_tendon = app.appdata.shoulder_yaw.left.tendons.neg_tendon;

            app.shoulder_yaw.right.inner_pulley.l_max_ratio = app.appdata.shoulder_yaw.right.inner_pulley.l_max_ratio;
            app.shoulder_yaw.right.inner_pulley.tendon_id = app.appdata.shoulder_yaw.right.inner_pulley.tendon_id;
            app.shoulder_yaw.right.inner_pulley.active_top_indicator = app.appdata.shoulder_yaw.right.inner_pulley.active_top_indicator;
            app.shoulder_yaw.right.inner_pulley.pulley_numbers_switch = app.appdata.shoulder_yaw.right.inner_pulley.pulley_numbers_switch;
            app.shoulder_yaw.right.outer_pulley.l_max_ratio = app.appdata.shoulder_yaw.right.outer_pulley.l_max_ratio;
            app.shoulder_yaw.right.outer_pulley.tendon_id = app.appdata.shoulder_yaw.right.outer_pulley.tendon_id;
            app.shoulder_yaw.right.outer_pulley.active_top_indicator = app.appdata.shoulder_yaw.right.outer_pulley.active_top_indicator;
            app.shoulder_yaw.right.outer_pulley.pulley_numbers_switch = app.appdata.shoulder_yaw.right.outer_pulley.pulley_numbers_switch;
            app.shoulder_yaw.left.inner_pulley.l_max_ratio = app.appdata.shoulder_yaw.left.inner_pulley.l_max_ratio;
            app.shoulder_yaw.left.inner_pulley.tendon_id = app.appdata.shoulder_yaw.left.inner_pulley.tendon_id;
            app.shoulder_yaw.left.inner_pulley.active_top_indicator = app.appdata.shoulder_yaw.left.inner_pulley.active_top_indicator;
            app.shoulder_yaw.left.inner_pulley.pulley_numbers_switch = app.appdata.shoulder_yaw.left.inner_pulley.pulley_numbers_switch;
            app.shoulder_yaw.left.outer_pulley.l_max_ratio = app.appdata.shoulder_yaw.left.outer_pulley.l_max_ratio;
            app.shoulder_yaw.left.outer_pulley.tendon_id = app.appdata.shoulder_yaw.left.outer_pulley.tendon_id;
            app.shoulder_yaw.left.outer_pulley.active_top_indicator = app.appdata.shoulder_yaw.left.outer_pulley.active_top_indicator;
            app.shoulder_yaw.left.outer_pulley.pulley_numbers_switch = app.appdata.shoulder_yaw.left.outer_pulley.pulley_numbers_switch;
            app.shoulder_yaw.right.inner_pulley.pretensioning = app.appdata.shoulder_yaw.right.inner_pulley.pretensioning;
            app.shoulder_yaw.right.outer_pulley.pretensioning = app.appdata.shoulder_yaw.right.outer_pulley.pretensioning;
            app.shoulder_yaw.left.inner_pulley.pretensioning = app.appdata.shoulder_yaw.left.inner_pulley.pretensioning;
            app.shoulder_yaw.left.outer_pulley.pretensioning = app.appdata.shoulder_yaw.left.outer_pulley.pretensioning;

            % Shoulder Roll Joint
            app.shoulder_roll = JointClass;
            app.shoulder_roll.name = "Shoulder Roll";
            app.shoulder_roll.minAngle = app.appdata.shoulder_roll.minAngle;
            app.shoulder_roll.maxAngle = app.appdata.shoulder_roll.maxAngle;
            app.shoulder_roll.r_active = app.appdata.shoulder_roll.r_active;
            app.shoulder_roll.r_passive = app.appdata.shoulder_roll.r_passive;
            app.shoulder_roll.tendon_diameter = app.appdata.shoulder_roll.tendon_diameter;
            app.shoulder_roll.number_tendon_attachments = app.appdata.shoulder_roll.number_tendon_attachments;
            app.shoulder_roll.number_pulleys = app.appdata.shoulder_roll.number_pulleys;
            app.shoulder_roll.right_pos_actuator_id = app.appdata.shoulder_roll.right_pos_actuator_id;
            app.shoulder_roll.right_neg_actuator_id = app.appdata.shoulder_roll.right_neg_actuator_id;
            app.shoulder_roll.left_pos_actuator_id = app.appdata.shoulder_roll.left_pos_actuator_id;
            app.shoulder_roll.left_neg_actuator_id = app.appdata.shoulder_roll.left_neg_actuator_id;
            app.shoulder_roll.inner_active_mounting_length = app.appdata.shoulder_roll.inner_active_mounting_length;
            app.shoulder_roll.outer_active_mounting_length = app.appdata.shoulder_roll.outer_active_mounting_length;
            app.shoulder_roll.passive_mounting_length = app.appdata.shoulder_roll.passive_mounting_length;
            app.shoulder_roll.sleeve_assembly_extra = app.appdata.shoulder_roll.sleeve_assembly_extra;
            app.shoulder_roll.sleeve_cutting_ratio = app.appdata.shoulder_roll.sleeve_cutting_ratio;
            
            app.shoulder_roll.addNormalSides()

            app.shoulder_roll.right.tendons.x_active_pos = app.appdata.shoulder_roll.right.tendons.x_active_pos;
            app.shoulder_roll.right.tendons.y_active_pos = app.appdata.shoulder_roll.right.tendons.y_active_pos;
            app.shoulder_roll.right.tendons.z_active_pos = app.appdata.shoulder_roll.right.tendons.z_active_pos;
            app.shoulder_roll.right.tendons.x_passive_pos = app.appdata.shoulder_roll.right.tendons.x_passive_pos;
            app.shoulder_roll.right.tendons.y_passive_pos = app.appdata.shoulder_roll.right.tendons.y_passive_pos;
            app.shoulder_roll.right.tendons.z_passive_pos = app.appdata.shoulder_roll.right.tendons.z_passive_pos;
            app.shoulder_roll.right.tendons.x_tendon_pos = app.appdata.shoulder_roll.right.tendons.x_tendon_pos;
            app.shoulder_roll.right.tendons.y_tendon_pos = app.appdata.shoulder_roll.right.tendons.y_tendon_pos;
            app.shoulder_roll.right.tendons.z_tendon_pos = app.appdata.shoulder_roll.right.tendons.z_tendon_pos;
            app.shoulder_roll.right.tendons.x_coordinate_id = app.appdata.shoulder_roll.right.tendons.x_coordinate_id;
            app.shoulder_roll.right.tendons.y_coordinate_id = app.appdata.shoulder_roll.right.tendons.y_coordinate_id;
            app.shoulder_roll.right.tendons.maxAngle_indicator = app.appdata.shoulder_roll.right.tendons.maxAngle_indicator;
            app.shoulder_roll.right.tendons.blue_alpha_passive_oversized = app.appdata.shoulder_roll.right.tendons.blue_alpha_passive_oversized;
            app.shoulder_roll.right.tendons.red_alpha_passive_oversized = app.appdata.shoulder_roll.right.tendons.red_alpha_passive_oversized;
            app.shoulder_roll.right.tendons.pos_tendon = app.appdata.shoulder_roll.right.tendons.pos_tendon;
            app.shoulder_roll.right.tendons.neg_tendon = app.appdata.shoulder_roll.right.tendons.neg_tendon;

            app.shoulder_roll.left.tendons.x_active_pos = app.appdata.shoulder_roll.left.tendons.x_active_pos;
            app.shoulder_roll.left.tendons.y_active_pos = app.appdata.shoulder_roll.left.tendons.y_active_pos;
            app.shoulder_roll.left.tendons.z_active_pos = app.appdata.shoulder_roll.left.tendons.z_active_pos;
            app.shoulder_roll.left.tendons.x_passive_pos = app.appdata.shoulder_roll.left.tendons.x_passive_pos;
            app.shoulder_roll.left.tendons.y_passive_pos = app.appdata.shoulder_roll.left.tendons.y_passive_pos;
            app.shoulder_roll.left.tendons.z_passive_pos = app.appdata.shoulder_roll.left.tendons.z_passive_pos;
            app.shoulder_roll.left.tendons.x_tendon_pos = app.appdata.shoulder_roll.left.tendons.x_tendon_pos;
            app.shoulder_roll.left.tendons.y_tendon_pos = app.appdata.shoulder_roll.left.tendons.y_tendon_pos;
            app.shoulder_roll.left.tendons.z_tendon_pos = app.appdata.shoulder_roll.left.tendons.z_tendon_pos;
            app.shoulder_roll.left.tendons.x_coordinate_id = app.appdata.shoulder_roll.left.tendons.x_coordinate_id;
            app.shoulder_roll.left.tendons.y_coordinate_id = app.appdata.shoulder_roll.left.tendons.y_coordinate_id;
            app.shoulder_roll.left.tendons.maxAngle_indicator = app.appdata.shoulder_roll.left.tendons.maxAngle_indicator;
            app.shoulder_roll.left.tendons.blue_alpha_passive_oversized = app.appdata.shoulder_roll.left.tendons.blue_alpha_passive_oversized;
            app.shoulder_roll.left.tendons.red_alpha_passive_oversized = app.appdata.shoulder_roll.left.tendons.red_alpha_passive_oversized;
            app.shoulder_roll.left.tendons.pos_tendon = app.appdata.shoulder_roll.left.tendons.pos_tendon;
            app.shoulder_roll.left.tendons.neg_tendon = app.appdata.shoulder_roll.left.tendons.neg_tendon;

            app.shoulder_roll.right.inner_pulley.l_max_ratio = app.appdata.shoulder_roll.right.inner_pulley.l_max_ratio;
            app.shoulder_roll.right.inner_pulley.tendon_id = app.appdata.shoulder_roll.right.inner_pulley.tendon_id;
            app.shoulder_roll.right.inner_pulley.active_top_indicator = app.appdata.shoulder_roll.right.inner_pulley.active_top_indicator;
            app.shoulder_roll.right.outer_pulley.l_max_ratio = app.appdata.shoulder_roll.right.outer_pulley.l_max_ratio;
            app.shoulder_roll.right.outer_pulley.tendon_id = app.appdata.shoulder_roll.right.outer_pulley.tendon_id;
            app.shoulder_roll.right.outer_pulley.active_top_indicator = app.appdata.shoulder_roll.right.outer_pulley.active_top_indicator;
            app.shoulder_roll.left.inner_pulley.l_max_ratio = app.appdata.shoulder_roll.left.inner_pulley.l_max_ratio;
            app.shoulder_roll.left.inner_pulley.tendon_id = app.appdata.shoulder_roll.left.inner_pulley.tendon_id;
            app.shoulder_roll.left.inner_pulley.active_top_indicator = app.appdata.shoulder_roll.left.inner_pulley.active_top_indicator;
            app.shoulder_roll.left.outer_pulley.l_max_ratio = app.appdata.shoulder_roll.left.outer_pulley.l_max_ratio;
            app.shoulder_roll.left.outer_pulley.tendon_id = app.appdata.shoulder_roll.left.outer_pulley.tendon_id;
            app.shoulder_roll.left.outer_pulley.active_top_indicator = app.appdata.shoulder_roll.left.outer_pulley.active_top_indicator;
            app.shoulder_roll.right.inner_pulley.pretensioning = app.appdata.shoulder_roll.right.inner_pulley.pretensioning;
            app.shoulder_roll.right.outer_pulley.pretensioning = app.appdata.shoulder_roll.right.outer_pulley.pretensioning;
            app.shoulder_roll.left.inner_pulley.pretensioning = app.appdata.shoulder_roll.left.inner_pulley.pretensioning;
            app.shoulder_roll.left.outer_pulley.pretensioning = app.appdata.shoulder_roll.left.outer_pulley.pretensioning;
            app.shoulder_roll.right.inner_pulley.pulley_numbers_switch = app.appdata.shoulder_roll.right.inner_pulley.pulley_numbers_switch;
            app.shoulder_roll.right.outer_pulley.pulley_numbers_switch = app.appdata.shoulder_roll.right.outer_pulley.pulley_numbers_switch;
            app.shoulder_roll.left.inner_pulley.pulley_numbers_switch = app.appdata.shoulder_roll.left.inner_pulley.pulley_numbers_switch;
            app.shoulder_roll.left.outer_pulley.pulley_numbers_switch = app.appdata.shoulder_roll.left.outer_pulley.pulley_numbers_switch;

            % Shoulder Pitch Joint
            app.shoulder_pitch = JointClass;
            app.shoulder_pitch.name = "Shoulder Pitch";
            app.shoulder_pitch.minAngle = app.appdata.shoulder_pitch.minAngle;
            app.shoulder_pitch.maxAngle = app.appdata.shoulder_pitch.maxAngle;
            app.shoulder_pitch.r_active = app.appdata.shoulder_pitch.r_active;
            app.shoulder_pitch.r_passive = app.appdata.shoulder_pitch.r_passive;
            app.shoulder_pitch.r_middle = app.appdata.shoulder_pitch.r_middle;
            app.shoulder_pitch.tendon_diameter = app.appdata.shoulder_pitch.tendon_diameter;
            app.shoulder_pitch.number_tendon_attachments = app.appdata.shoulder_pitch.number_tendon_attachments;
            app.shoulder_pitch.number_pulleys = app.appdata.shoulder_pitch.number_pulleys;
            app.shoulder_pitch.right_pos_actuator_id = app.appdata.shoulder_pitch.right_pos_actuator_id;
            app.shoulder_pitch.right_neg_actuator_id = app.appdata.shoulder_pitch.right_neg_actuator_id;
            app.shoulder_pitch.left_pos_actuator_id = app.appdata.shoulder_pitch.left_pos_actuator_id;
            app.shoulder_pitch.left_neg_actuator_id = app.appdata.shoulder_pitch.left_neg_actuator_id;
            app.shoulder_pitch.inner_active_mounting_length = app.appdata.shoulder_pitch.inner_active_mounting_length;
            app.shoulder_pitch.outer_active_mounting_length = app.appdata.shoulder_pitch.outer_active_mounting_length;
            app.shoulder_pitch.passive_mounting_length = app.appdata.shoulder_pitch.passive_mounting_length;
            app.shoulder_pitch.sleeve_assembly_extra = app.appdata.shoulder_pitch.sleeve_assembly_extra;
            app.shoulder_pitch.sleeve_cutting_ratio = app.appdata.shoulder_pitch.sleeve_cutting_ratio;

            app.shoulder_pitch.addShoulderPitchSides();

            app.shoulder_pitch.right.tendons.x_active_pos = app.appdata.shoulder_pitch.right.tendons.x_active_pos;
            app.shoulder_pitch.right.tendons.y_active_pos = app.appdata.shoulder_pitch.right.tendons.y_active_pos;
            app.shoulder_pitch.right.tendons.z_active_pos = app.appdata.shoulder_pitch.right.tendons.z_active_pos;
            app.shoulder_pitch.right.tendons.x_passive_pos = app.appdata.shoulder_pitch.right.tendons.x_passive_pos;
            app.shoulder_pitch.right.tendons.y_passive_pos = app.appdata.shoulder_pitch.right.tendons.y_passive_pos;
            app.shoulder_pitch.right.tendons.z_passive_pos = app.appdata.shoulder_pitch.right.tendons.z_passive_pos;
            app.shoulder_pitch.right.tendons.x_middle_pos = app.appdata.shoulder_pitch.right.tendons.x_middle_pos;
            app.shoulder_pitch.right.tendons.y_middle_pos = app.appdata.shoulder_pitch.right.tendons.y_middle_pos;
            app.shoulder_pitch.right.tendons.z_middle_pos = app.appdata.shoulder_pitch.right.tendons.z_middle_pos;
            app.shoulder_pitch.right.tendons.x_tendon_upper_pos = app.appdata.shoulder_pitch.right.tendons.x_tendon_upper_pos;
            app.shoulder_pitch.right.tendons.y_tendon_upper_pos = app.appdata.shoulder_pitch.right.tendons.y_tendon_upper_pos;
            app.shoulder_pitch.right.tendons.z_tendon_upper_pos = app.appdata.shoulder_pitch.right.tendons.z_tendon_upper_pos;
            app.shoulder_pitch.right.tendons.x_tendon_lower_pos = app.appdata.shoulder_pitch.right.tendons.x_tendon_lower_pos;
            app.shoulder_pitch.right.tendons.y_tendon_lower_pos = app.appdata.shoulder_pitch.right.tendons.y_tendon_lower_pos;
            app.shoulder_pitch.right.tendons.z_tendon_lower_pos = app.appdata.shoulder_pitch.right.tendons.z_tendon_lower_pos;
            app.shoulder_pitch.right.tendons.x_coordinate_id = app.appdata.shoulder_pitch.right.tendons.x_coordinate_id;
            app.shoulder_pitch.right.tendons.y_coordinate_id = app.appdata.shoulder_pitch.right.tendons.y_coordinate_id;
            app.shoulder_pitch.right.tendons.maxAngle_indicator = app.appdata.shoulder_pitch.right.tendons.maxAngle_indicator;
            app.shoulder_pitch.right.tendons.blue_alpha_passive_oversized = app.appdata.shoulder_pitch.right.tendons.blue_alpha_passive_oversized;
            app.shoulder_pitch.right.tendons.red_alpha_passive_oversized = app.appdata.shoulder_pitch.right.tendons.red_alpha_passive_oversized;
            app.shoulder_pitch.right.tendons.pos_tendon = app.appdata.shoulder_pitch.right.tendons.pos_tendon;
            app.shoulder_pitch.right.tendons.neg_tendon = app.appdata.shoulder_pitch.right.tendons.neg_tendon;

            app.shoulder_pitch.left.tendons.x_active_pos = app.appdata.shoulder_pitch.left.tendons.x_active_pos;
            app.shoulder_pitch.left.tendons.y_active_pos = app.appdata.shoulder_pitch.left.tendons.y_active_pos;
            app.shoulder_pitch.left.tendons.z_active_pos = app.appdata.shoulder_pitch.left.tendons.z_active_pos;
            app.shoulder_pitch.left.tendons.x_passive_pos = app.appdata.shoulder_pitch.left.tendons.x_passive_pos;
            app.shoulder_pitch.left.tendons.y_passive_pos = app.appdata.shoulder_pitch.left.tendons.y_passive_pos;
            app.shoulder_pitch.left.tendons.z_passive_pos = app.appdata.shoulder_pitch.left.tendons.z_passive_pos;
            app.shoulder_pitch.left.tendons.x_middle_pos = app.appdata.shoulder_pitch.left.tendons.x_middle_pos;
            app.shoulder_pitch.left.tendons.y_middle_pos = app.appdata.shoulder_pitch.left.tendons.y_middle_pos;
            app.shoulder_pitch.left.tendons.z_middle_pos = app.appdata.shoulder_pitch.left.tendons.z_middle_pos;
            app.shoulder_pitch.left.tendons.x_tendon_upper_pos = app.appdata.shoulder_pitch.left.tendons.x_tendon_upper_pos;
            app.shoulder_pitch.left.tendons.y_tendon_upper_pos = app.appdata.shoulder_pitch.left.tendons.y_tendon_upper_pos;
            app.shoulder_pitch.left.tendons.z_tendon_upper_pos = app.appdata.shoulder_pitch.left.tendons.z_tendon_upper_pos;
            app.shoulder_pitch.left.tendons.x_tendon_lower_pos = app.appdata.shoulder_pitch.left.tendons.x_tendon_lower_pos;
            app.shoulder_pitch.left.tendons.y_tendon_lower_pos = app.appdata.shoulder_pitch.left.tendons.y_tendon_lower_pos;
            app.shoulder_pitch.left.tendons.z_tendon_lower_pos = app.appdata.shoulder_pitch.left.tendons.z_tendon_lower_pos;
            app.shoulder_pitch.left.tendons.x_coordinate_id = app.appdata.shoulder_pitch.left.tendons.x_coordinate_id;
            app.shoulder_pitch.left.tendons.y_coordinate_id = app.appdata.shoulder_pitch.left.tendons.y_coordinate_id;
            app.shoulder_pitch.left.tendons.maxAngle_indicator = app.appdata.shoulder_pitch.left.tendons.maxAngle_indicator;
            app.shoulder_pitch.left.tendons.blue_alpha_passive_oversized = app.appdata.shoulder_pitch.left.tendons.blue_alpha_passive_oversized;
            app.shoulder_pitch.left.tendons.red_alpha_passive_oversized = app.appdata.shoulder_pitch.left.tendons.red_alpha_passive_oversized;
            app.shoulder_pitch.left.tendons.pos_tendon = app.appdata.shoulder_pitch.left.tendons.pos_tendon;
            app.shoulder_pitch.left.tendons.neg_tendon = app.appdata.shoulder_pitch.left.tendons.neg_tendon;

            app.shoulder_pitch.right.inner_pulley.l_max_ratio = app.appdata.shoulder_pitch.right.inner_pulley.l_max_ratio;
            app.shoulder_pitch.right.inner_pulley.tendon_id = app.appdata.shoulder_pitch.right.inner_pulley.tendon_id;
            app.shoulder_pitch.right.inner_pulley.active_top_indicator = app.appdata.shoulder_pitch.right.inner_pulley.active_top_indicator;
            app.shoulder_pitch.right.outer_pulley.l_max_ratio = app.appdata.shoulder_pitch.right.outer_pulley.l_max_ratio;
            app.shoulder_pitch.right.outer_pulley.tendon_id = app.appdata.shoulder_pitch.right.outer_pulley.tendon_id;
            app.shoulder_pitch.right.outer_pulley.active_top_indicator = app.appdata.shoulder_pitch.right.outer_pulley.active_top_indicator;
            app.shoulder_pitch.left.inner_pulley.l_max_ratio = app.appdata.shoulder_pitch.left.inner_pulley.l_max_ratio;
            app.shoulder_pitch.left.inner_pulley.tendon_id = app.appdata.shoulder_pitch.left.inner_pulley.tendon_id;
            app.shoulder_pitch.left.inner_pulley.active_top_indicator = app.appdata.shoulder_pitch.left.inner_pulley.active_top_indicator;
            app.shoulder_pitch.left.outer_pulley.l_max_ratio = app.appdata.shoulder_pitch.left.outer_pulley.l_max_ratio;
            app.shoulder_pitch.left.outer_pulley.tendon_id = app.appdata.shoulder_pitch.left.outer_pulley.tendon_id;
            app.shoulder_pitch.left.outer_pulley.active_top_indicator = app.appdata.shoulder_pitch.left.outer_pulley.active_top_indicator;
            app.shoulder_pitch.right.inner_pulley.pretensioning = app.appdata.shoulder_pitch.right.inner_pulley.pretensioning;
            app.shoulder_pitch.right.outer_pulley.pretensioning = app.appdata.shoulder_pitch.right.outer_pulley.pretensioning;
            app.shoulder_pitch.left.inner_pulley.pretensioning = app.appdata.shoulder_pitch.left.inner_pulley.pretensioning;
            app.shoulder_pitch.left.outer_pulley.pretensioning = app.appdata.shoulder_pitch.left.outer_pulley.pretensioning;
            app.shoulder_pitch.right.inner_pulley.pulley_numbers_switch = app.appdata.shoulder_pitch.right.inner_pulley.pulley_numbers_switch;
            app.shoulder_pitch.right.outer_pulley.pulley_numbers_switch = app.appdata.shoulder_pitch.right.outer_pulley.pulley_numbers_switch;
            app.shoulder_pitch.left.inner_pulley.pulley_numbers_switch = app.appdata.shoulder_pitch.left.inner_pulley.pulley_numbers_switch;
            app.shoulder_pitch.left.outer_pulley.pulley_numbers_switch = app.appdata.shoulder_pitch.left.outer_pulley.pulley_numbers_switch;

            % Upperarm Roll Joint
            app.upperarm_roll = JointClass;
            app.upperarm_roll.name = "Upperarm Roll";
            app.upperarm_roll.minAngle = app.appdata.upperarm_roll.minAngle;
            app.upperarm_roll.maxAngle = app.appdata.upperarm_roll.maxAngle;
            app.upperarm_roll.r_active = app.appdata.upperarm_roll.r_active;
            app.upperarm_roll.r_passive = app.appdata.upperarm_roll.r_passive;
            app.upperarm_roll.tendon_diameter = app.appdata.upperarm_roll.tendon_diameter;
            app.upperarm_roll.number_tendon_attachments = app.appdata.upperarm_roll.number_tendon_attachments;
            app.upperarm_roll.number_pulleys = app.appdata.upperarm_roll.number_pulleys;
            app.upperarm_roll.right_pos_actuator_id = app.appdata.upperarm_roll.right_pos_actuator_id;
            app.upperarm_roll.right_neg_actuator_id = app.appdata.upperarm_roll.right_neg_actuator_id;
            app.upperarm_roll.left_pos_actuator_id = app.appdata.upperarm_roll.left_pos_actuator_id;
            app.upperarm_roll.left_neg_actuator_id = app.appdata.upperarm_roll.left_neg_actuator_id;
            app.upperarm_roll.inner_active_mounting_length = app.appdata.upperarm_roll.inner_active_mounting_length;
            app.upperarm_roll.outer_active_mounting_length = app.appdata.upperarm_roll.outer_active_mounting_length;
            app.upperarm_roll.passive_mounting_length = app.appdata.upperarm_roll.passive_mounting_length;
            app.upperarm_roll.sleeve_assembly_extra = app.appdata.upperarm_roll.sleeve_assembly_extra;
            app.upperarm_roll.sleeve_cutting_ratio = app.appdata.upperarm_roll.sleeve_cutting_ratio;

            app.upperarm_roll.addUpperarmRollSides();

            app.upperarm_roll.right.tendons.x_active_pos = app.appdata.upperarm_roll.right.tendons.x_active_pos;
            app.upperarm_roll.right.tendons.y_active_pos = app.appdata.upperarm_roll.right.tendons.y_active_pos;
            app.upperarm_roll.right.tendons.z_active_pos = app.appdata.upperarm_roll.right.tendons.z_active_pos;
            app.upperarm_roll.right.tendons.x_passive_pos = app.appdata.upperarm_roll.right.tendons.x_passive_pos;
            app.upperarm_roll.right.tendons.y_passive_pos = app.appdata.upperarm_roll.right.tendons.y_passive_pos;
            app.upperarm_roll.right.tendons.z_passive_pos = app.appdata.upperarm_roll.right.tendons.z_passive_pos;
            app.upperarm_roll.right.tendons.x_tendon_upper_pos = app.appdata.upperarm_roll.right.tendons.x_tendon_upper_pos;
            app.upperarm_roll.right.tendons.y_tendon_upper_pos = app.appdata.upperarm_roll.right.tendons.y_tendon_upper_pos;
            app.upperarm_roll.right.tendons.z_tendon_upper_pos = app.appdata.upperarm_roll.right.tendons.z_tendon_upper_pos;
            app.upperarm_roll.right.tendons.x_tendon_lower_pos = app.appdata.upperarm_roll.right.tendons.x_tendon_lower_pos;
            app.upperarm_roll.right.tendons.y_tendon_lower_pos = app.appdata.upperarm_roll.right.tendons.y_tendon_lower_pos;
            app.upperarm_roll.right.tendons.z_tendon_lower_pos = app.appdata.upperarm_roll.right.tendons.z_tendon_lower_pos;
            app.upperarm_roll.right.tendons.x_coordinate_id = app.appdata.upperarm_roll.right.tendons.x_coordinate_id;
            app.upperarm_roll.right.tendons.y_coordinate_id = app.appdata.upperarm_roll.right.tendons.y_coordinate_id;
            app.upperarm_roll.right.tendons.maxAngle_indicator = app.appdata.upperarm_roll.right.tendons.maxAngle_indicator;
            app.upperarm_roll.right.tendons.blue_alpha_passive_oversized = app.appdata.upperarm_roll.right.tendons.blue_alpha_passive_oversized;
            app.upperarm_roll.right.tendons.red_alpha_passive_oversized = app.appdata.upperarm_roll.right.tendons.red_alpha_passive_oversized;
            app.upperarm_roll.right.tendons.tendon_attachment_indicator = app.appdata.upperarm_roll.right.tendons.tendon_attachment_indicator;
            app.upperarm_roll.right.tendons.pos_tendon = app.appdata.upperarm_roll.right.tendons.pos_tendon;
            app.upperarm_roll.right.tendons.neg_tendon = app.appdata.upperarm_roll.right.tendons.neg_tendon;

            app.upperarm_roll.left.tendons.x_active_pos = app.appdata.upperarm_roll.left.tendons.x_active_pos;
            app.upperarm_roll.left.tendons.y_active_pos = app.appdata.upperarm_roll.left.tendons.y_active_pos;
            app.upperarm_roll.left.tendons.z_active_pos = app.appdata.upperarm_roll.left.tendons.z_active_pos;
            app.upperarm_roll.left.tendons.x_passive_pos = app.appdata.upperarm_roll.left.tendons.x_passive_pos;
            app.upperarm_roll.left.tendons.y_passive_pos = app.appdata.upperarm_roll.left.tendons.y_passive_pos;
            app.upperarm_roll.left.tendons.z_passive_pos = app.appdata.upperarm_roll.left.tendons.z_passive_pos;
            app.upperarm_roll.left.tendons.x_tendon_upper_pos = app.appdata.upperarm_roll.left.tendons.x_tendon_upper_pos;
            app.upperarm_roll.left.tendons.y_tendon_upper_pos = app.appdata.upperarm_roll.left.tendons.y_tendon_upper_pos;
            app.upperarm_roll.left.tendons.z_tendon_upper_pos = app.appdata.upperarm_roll.left.tendons.z_tendon_upper_pos;
            app.upperarm_roll.left.tendons.x_tendon_lower_pos = app.appdata.upperarm_roll.left.tendons.x_tendon_lower_pos;
            app.upperarm_roll.left.tendons.y_tendon_lower_pos = app.appdata.upperarm_roll.left.tendons.y_tendon_lower_pos;
            app.upperarm_roll.left.tendons.z_tendon_lower_pos = app.appdata.upperarm_roll.left.tendons.z_tendon_lower_pos;
            app.upperarm_roll.left.tendons.x_coordinate_id = app.appdata.upperarm_roll.left.tendons.x_coordinate_id;
            app.upperarm_roll.left.tendons.y_coordinate_id = app.appdata.upperarm_roll.left.tendons.y_coordinate_id;
            app.upperarm_roll.left.tendons.maxAngle_indicator = app.appdata.upperarm_roll.left.tendons.maxAngle_indicator;
            app.upperarm_roll.left.tendons.blue_alpha_passive_oversized = app.appdata.upperarm_roll.left.tendons.blue_alpha_passive_oversized;
            app.upperarm_roll.left.tendons.red_alpha_passive_oversized = app.appdata.upperarm_roll.left.tendons.red_alpha_passive_oversized;
            app.upperarm_roll.left.tendons.tendon_attachment_indicator = app.appdata.upperarm_roll.left.tendons.tendon_attachment_indicator;
            app.upperarm_roll.left.tendons.pos_tendon = app.appdata.upperarm_roll.left.tendons.pos_tendon;
            app.upperarm_roll.left.tendons.neg_tendon = app.appdata.upperarm_roll.left.tendons.neg_tendon;

            app.upperarm_roll.right.inner_pulley.l_max_ratio = app.appdata.upperarm_roll.right.inner_pulley.l_max_ratio;
            app.upperarm_roll.right.inner_pulley.tendon_id = app.appdata.upperarm_roll.right.inner_pulley.tendon_id;
            app.upperarm_roll.right.inner_pulley.active_top_indicator = app.appdata.upperarm_roll.right.inner_pulley.active_top_indicator;
            app.upperarm_roll.right.outer_pulley.l_max_ratio = app.appdata.upperarm_roll.right.outer_pulley.l_max_ratio;
            app.upperarm_roll.right.outer_pulley.tendon_id = app.appdata.upperarm_roll.right.outer_pulley.tendon_id;
            app.upperarm_roll.right.outer_pulley.active_top_indicator = app.appdata.upperarm_roll.right.outer_pulley.active_top_indicator;
            app.upperarm_roll.left.inner_pulley.l_max_ratio = app.appdata.upperarm_roll.left.inner_pulley.l_max_ratio;
            app.upperarm_roll.left.inner_pulley.tendon_id = app.appdata.upperarm_roll.left.inner_pulley.tendon_id;
            app.upperarm_roll.left.inner_pulley.active_top_indicator = app.appdata.upperarm_roll.left.inner_pulley.active_top_indicator;
            app.upperarm_roll.left.outer_pulley.l_max_ratio = app.appdata.upperarm_roll.left.outer_pulley.l_max_ratio;
            app.upperarm_roll.left.outer_pulley.tendon_id = app.appdata.upperarm_roll.left.outer_pulley.tendon_id;
            app.upperarm_roll.left.outer_pulley.active_top_indicator = app.appdata.upperarm_roll.left.outer_pulley.active_top_indicator;
            app.upperarm_roll.right.inner_pulley.pretensioning = app.appdata.upperarm_roll.right.inner_pulley.pretensioning;
            app.upperarm_roll.right.outer_pulley.pretensioning = app.appdata.upperarm_roll.right.outer_pulley.pretensioning;
            app.upperarm_roll.left.inner_pulley.pretensioning = app.appdata.upperarm_roll.left.inner_pulley.pretensioning;
            app.upperarm_roll.left.outer_pulley.pretensioning = app.appdata.upperarm_roll.left.outer_pulley.pretensioning;
            app.upperarm_roll.right.inner_pulley.pulley_numbers_switch = app.appdata.upperarm_roll.right.inner_pulley.pulley_numbers_switch;
            app.upperarm_roll.right.outer_pulley.pulley_numbers_switch = app.appdata.upperarm_roll.right.outer_pulley.pulley_numbers_switch;
            app.upperarm_roll.left.inner_pulley.pulley_numbers_switch = app.appdata.upperarm_roll.left.inner_pulley.pulley_numbers_switch;
            app.upperarm_roll.left.outer_pulley.pulley_numbers_switch = app.appdata.upperarm_roll.left.outer_pulley.pulley_numbers_switch;

            %Elbow
            app.elbow = JointClass;
            app.elbow.name = "Elbow";
            app.elbow.minAngle = app.appdata.elbow.minAngle;
            app.elbow.maxAngle = app.appdata.elbow.maxAngle;
            app.elbow.r_active = app.appdata.elbow.r_active;
            app.elbow.r_passive = app.appdata.elbow.r_passive;
            app.elbow.tendon_diameter = app.appdata.elbow.tendon_diameter;
            app.elbow.number_tendon_attachments = app.appdata.elbow.number_tendon_attachments;
            app.elbow.number_pulleys = app.appdata.elbow.number_pulleys;
            app.elbow.right_pos_actuator_id = app.appdata.elbow.right_pos_actuator_id;
            app.elbow.right_neg_actuator_id = app.appdata.elbow.right_neg_actuator_id;
            app.elbow.left_pos_actuator_id = app.appdata.elbow.left_pos_actuator_id;
            app.elbow.left_neg_actuator_id = app.appdata.elbow.left_neg_actuator_id;
            app.elbow.inner_active_mounting_length = app.appdata.elbow.inner_active_mounting_length;
            app.elbow.outer_active_mounting_length = app.appdata.elbow.outer_active_mounting_length;
            app.elbow.passive_mounting_length = app.appdata.elbow.passive_mounting_length;
            app.elbow.sleeve_assembly_extra = app.appdata.elbow.sleeve_assembly_extra;
            app.elbow.sleeve_cutting_ratio = app.appdata.elbow.sleeve_cutting_ratio;
            
            app.elbow.addNormalSides()

            app.elbow.right.tendons.x_active_pos = app.appdata.elbow.right.tendons.x_active_pos;
            app.elbow.right.tendons.y_active_pos = app.appdata.elbow.right.tendons.y_active_pos;
            app.elbow.right.tendons.z_active_pos = app.appdata.elbow.right.tendons.z_active_pos;
            app.elbow.right.tendons.x_passive_pos = app.appdata.elbow.right.tendons.x_passive_pos;
            app.elbow.right.tendons.y_passive_pos = app.appdata.elbow.right.tendons.y_passive_pos;
            app.elbow.right.tendons.z_passive_pos = app.appdata.elbow.right.tendons.z_passive_pos;
            app.elbow.right.tendons.x_tendon_pos = app.appdata.elbow.right.tendons.x_tendon_pos;
            app.elbow.right.tendons.y_tendon_pos = app.appdata.elbow.right.tendons.y_tendon_pos;
            app.elbow.right.tendons.z_tendon_pos = app.appdata.elbow.right.tendons.z_tendon_pos;
            app.elbow.right.tendons.x_coordinate_id = app.appdata.elbow.right.tendons.x_coordinate_id;
            app.elbow.right.tendons.y_coordinate_id = app.appdata.elbow.right.tendons.y_coordinate_id;
            app.elbow.right.tendons.maxAngle_indicator = app.appdata.elbow.right.tendons.maxAngle_indicator;
            app.elbow.right.tendons.blue_alpha_passive_oversized = app.appdata.elbow.right.tendons.blue_alpha_passive_oversized;
            app.elbow.right.tendons.red_alpha_passive_oversized = app.appdata.elbow.right.tendons.red_alpha_passive_oversized;
            app.elbow.right.tendons.pos_tendon = app.appdata.elbow.right.tendons.pos_tendon;
            app.elbow.right.tendons.neg_tendon = app.appdata.elbow.right.tendons.neg_tendon;

            app.elbow.left.tendons.x_active_pos = app.appdata.elbow.left.tendons.x_active_pos;
            app.elbow.left.tendons.y_active_pos = app.appdata.elbow.left.tendons.y_active_pos;
            app.elbow.left.tendons.z_active_pos = app.appdata.elbow.left.tendons.z_active_pos;
            app.elbow.left.tendons.x_passive_pos = app.appdata.elbow.left.tendons.x_passive_pos;
            app.elbow.left.tendons.y_passive_pos = app.appdata.elbow.left.tendons.y_passive_pos;
            app.elbow.left.tendons.z_passive_pos = app.appdata.elbow.left.tendons.z_passive_pos;
            app.elbow.left.tendons.x_tendon_pos = app.appdata.elbow.left.tendons.x_tendon_pos;
            app.elbow.left.tendons.y_tendon_pos = app.appdata.elbow.left.tendons.y_tendon_pos;
            app.elbow.left.tendons.z_tendon_pos = app.appdata.elbow.left.tendons.z_tendon_pos;
            app.elbow.left.tendons.x_coordinate_id = app.appdata.elbow.left.tendons.x_coordinate_id;
            app.elbow.left.tendons.y_coordinate_id = app.appdata.elbow.left.tendons.y_coordinate_id;
            app.elbow.left.tendons.maxAngle_indicator = app.appdata.elbow.left.tendons.maxAngle_indicator;
            app.elbow.left.tendons.blue_alpha_passive_oversized = app.appdata.elbow.left.tendons.blue_alpha_passive_oversized;
            app.elbow.left.tendons.red_alpha_passive_oversized = app.appdata.elbow.left.tendons.red_alpha_passive_oversized;
            app.elbow.left.tendons.pos_tendon = app.appdata.elbow.left.tendons.pos_tendon;
            app.elbow.left.tendons.neg_tendon = app.appdata.elbow.left.tendons.neg_tendon;

            app.elbow.right.inner_pulley.l_max_ratio = app.appdata.elbow.right.inner_pulley.l_max_ratio;
            app.elbow.right.inner_pulley.tendon_id = app.appdata.elbow.right.inner_pulley.tendon_id;
            app.elbow.right.inner_pulley.active_top_indicator = app.appdata.elbow.right.inner_pulley.active_top_indicator;
            app.elbow.right.outer_pulley.l_max_ratio = app.appdata.elbow.right.outer_pulley.l_max_ratio;
            app.elbow.right.outer_pulley.tendon_id = app.appdata.elbow.right.outer_pulley.tendon_id;
            app.elbow.right.outer_pulley.active_top_indicator = app.appdata.elbow.right.outer_pulley.active_top_indicator;
            app.elbow.left.inner_pulley.l_max_ratio = app.appdata.elbow.left.inner_pulley.l_max_ratio;
            app.elbow.left.inner_pulley.tendon_id = app.appdata.elbow.left.inner_pulley.tendon_id;
            app.elbow.left.inner_pulley.active_top_indicator = app.appdata.elbow.left.inner_pulley.active_top_indicator;
            app.elbow.left.outer_pulley.l_max_ratio = app.appdata.elbow.left.outer_pulley.l_max_ratio;
            app.elbow.left.outer_pulley.tendon_id = app.appdata.elbow.left.outer_pulley.tendon_id;
            app.elbow.left.outer_pulley.active_top_indicator = app.appdata.elbow.left.outer_pulley.active_top_indicator;
            app.elbow.right.inner_pulley.pretensioning = app.appdata.elbow.right.inner_pulley.pretensioning;
            app.elbow.right.outer_pulley.pretensioning = app.appdata.elbow.right.outer_pulley.pretensioning;
            app.elbow.left.inner_pulley.pretensioning = app.appdata.elbow.left.inner_pulley.pretensioning;
            app.elbow.left.outer_pulley.pretensioning = app.appdata.elbow.left.outer_pulley.pretensioning;
            app.elbow.right.inner_pulley.pulley_numbers_switch = app.appdata.elbow.right.inner_pulley.pulley_numbers_switch;
            app.elbow.right.outer_pulley.pulley_numbers_switch = app.appdata.elbow.right.outer_pulley.pulley_numbers_switch;
            app.elbow.left.inner_pulley.pulley_numbers_switch = app.appdata.elbow.left.inner_pulley.pulley_numbers_switch;
            app.elbow.left.outer_pulley.pulley_numbers_switch = app.appdata.elbow.left.outer_pulley.pulley_numbers_switch;


            %Wrist Pitch
            app.wrist_pitch = JointClass;
            app.wrist_pitch.name = "Wrist Pitch";
            app.wrist_pitch.minAngle = app.appdata.wrist_pitch.minAngle;
            app.wrist_pitch.maxAngle = app.appdata.wrist_pitch.maxAngle;
            app.wrist_pitch.r_active = app.appdata.wrist_pitch.r_active;
            app.wrist_pitch.r_passive = app.appdata.wrist_pitch.r_passive;
            app.wrist_pitch.tendon_diameter = app.appdata.wrist_pitch.tendon_diameter;
            app.wrist_pitch.number_tendon_attachments = app.appdata.wrist_pitch.number_tendon_attachments;
            app.wrist_pitch.number_pulleys = app.appdata.wrist_pitch.number_pulleys;
            app.wrist_pitch.right_pos_actuator_id = app.appdata.wrist_pitch.right_pos_actuator_id;
            app.wrist_pitch.right_neg_actuator_id = app.appdata.wrist_pitch.right_neg_actuator_id;
            app.wrist_pitch.left_pos_actuator_id = app.appdata.wrist_pitch.left_pos_actuator_id;
            app.wrist_pitch.left_neg_actuator_id = app.appdata.wrist_pitch.left_neg_actuator_id;
            app.wrist_pitch.inner_active_mounting_length = app.appdata.wrist_pitch.inner_active_mounting_length;
            app.wrist_pitch.outer_active_mounting_length = app.appdata.wrist_pitch.outer_active_mounting_length;
            app.wrist_pitch.passive_mounting_length = app.appdata.wrist_pitch.passive_mounting_length;
            app.wrist_pitch.sleeve_assembly_extra = app.appdata.wrist_pitch.sleeve_assembly_extra;
            app.wrist_pitch.sleeve_cutting_ratio = app.appdata.wrist_pitch.sleeve_cutting_ratio;
            
            app.wrist_pitch.addNormalSides()

            app.wrist_pitch.right.tendons.x_active_pos = app.appdata.wrist_pitch.right.tendons.x_active_pos;
            app.wrist_pitch.right.tendons.y_active_pos = app.appdata.wrist_pitch.right.tendons.y_active_pos;
            app.wrist_pitch.right.tendons.z_active_pos = app.appdata.wrist_pitch.right.tendons.z_active_pos;
            app.wrist_pitch.right.tendons.x_passive_pos = app.appdata.wrist_pitch.right.tendons.x_passive_pos;
            app.wrist_pitch.right.tendons.y_passive_pos = app.appdata.wrist_pitch.right.tendons.y_passive_pos;
            app.wrist_pitch.right.tendons.z_passive_pos = app.appdata.wrist_pitch.right.tendons.z_passive_pos;
            app.wrist_pitch.right.tendons.x_tendon_pos = app.appdata.wrist_pitch.right.tendons.x_tendon_pos;
            app.wrist_pitch.right.tendons.y_tendon_pos = app.appdata.wrist_pitch.right.tendons.y_tendon_pos;
            app.wrist_pitch.right.tendons.z_tendon_pos = app.appdata.wrist_pitch.right.tendons.z_tendon_pos;
            app.wrist_pitch.right.tendons.x_coordinate_id = app.appdata.wrist_pitch.right.tendons.x_coordinate_id;
            app.wrist_pitch.right.tendons.y_coordinate_id = app.appdata.wrist_pitch.right.tendons.y_coordinate_id;
            app.wrist_pitch.right.tendons.maxAngle_indicator = app.appdata.wrist_pitch.right.tendons.maxAngle_indicator;
            app.wrist_pitch.right.tendons.blue_alpha_passive_oversized = app.appdata.wrist_pitch.right.tendons.blue_alpha_passive_oversized;
            app.wrist_pitch.right.tendons.red_alpha_passive_oversized = app.appdata.wrist_pitch.right.tendons.red_alpha_passive_oversized;
            app.wrist_pitch.right.tendons.pos_tendon = app.appdata.wrist_pitch.right.tendons.pos_tendon;
            app.wrist_pitch.right.tendons.neg_tendon = app.appdata.wrist_pitch.right.tendons.neg_tendon;

            app.wrist_pitch.left.tendons.x_active_pos = app.appdata.wrist_pitch.left.tendons.x_active_pos;
            app.wrist_pitch.left.tendons.y_active_pos = app.appdata.wrist_pitch.left.tendons.y_active_pos;
            app.wrist_pitch.left.tendons.z_active_pos = app.appdata.wrist_pitch.left.tendons.z_active_pos;
            app.wrist_pitch.left.tendons.x_passive_pos = app.appdata.wrist_pitch.left.tendons.x_passive_pos;
            app.wrist_pitch.left.tendons.y_passive_pos = app.appdata.wrist_pitch.left.tendons.y_passive_pos;
            app.wrist_pitch.left.tendons.z_passive_pos = app.appdata.wrist_pitch.left.tendons.z_passive_pos;
            app.wrist_pitch.left.tendons.x_tendon_pos = app.appdata.wrist_pitch.left.tendons.x_tendon_pos;
            app.wrist_pitch.left.tendons.y_tendon_pos = app.appdata.wrist_pitch.left.tendons.y_tendon_pos;
            app.wrist_pitch.left.tendons.z_tendon_pos = app.appdata.wrist_pitch.left.tendons.z_tendon_pos;
            app.wrist_pitch.left.tendons.x_coordinate_id = app.appdata.wrist_pitch.left.tendons.x_coordinate_id;
            app.wrist_pitch.left.tendons.y_coordinate_id = app.appdata.wrist_pitch.left.tendons.y_coordinate_id;
            app.wrist_pitch.left.tendons.maxAngle_indicator = app.appdata.wrist_pitch.left.tendons.maxAngle_indicator;
            app.wrist_pitch.left.tendons.blue_alpha_passive_oversized = app.appdata.wrist_pitch.left.tendons.blue_alpha_passive_oversized;
            app.wrist_pitch.left.tendons.red_alpha_passive_oversized = app.appdata.wrist_pitch.left.tendons.red_alpha_passive_oversized;
            app.wrist_pitch.left.tendons.pos_tendon = app.appdata.wrist_pitch.left.tendons.pos_tendon;
            app.wrist_pitch.left.tendons.neg_tendon = app.appdata.wrist_pitch.left.tendons.neg_tendon;

            app.wrist_pitch.right.inner_pulley.l_max_ratio = app.appdata.wrist_pitch.right.inner_pulley.l_max_ratio;
            app.wrist_pitch.right.inner_pulley.tendon_id = app.appdata.wrist_pitch.right.inner_pulley.tendon_id;
            app.wrist_pitch.right.inner_pulley.active_top_indicator = app.appdata.wrist_pitch.right.inner_pulley.active_top_indicator;
            app.wrist_pitch.right.outer_pulley.l_max_ratio = app.appdata.wrist_pitch.right.outer_pulley.l_max_ratio;
            app.wrist_pitch.right.outer_pulley.tendon_id = app.appdata.wrist_pitch.right.outer_pulley.tendon_id;
            app.wrist_pitch.right.outer_pulley.active_top_indicator = app.appdata.wrist_pitch.right.outer_pulley.active_top_indicator;
            app.wrist_pitch.left.inner_pulley.l_max_ratio = app.appdata.wrist_pitch.left.inner_pulley.l_max_ratio;
            app.wrist_pitch.left.inner_pulley.tendon_id = app.appdata.wrist_pitch.left.inner_pulley.tendon_id;
            app.wrist_pitch.left.inner_pulley.active_top_indicator = app.appdata.wrist_pitch.left.inner_pulley.active_top_indicator;
            app.wrist_pitch.left.outer_pulley.l_max_ratio = app.appdata.wrist_pitch.left.outer_pulley.l_max_ratio;
            app.wrist_pitch.left.outer_pulley.tendon_id = app.appdata.wrist_pitch.left.outer_pulley.tendon_id;
            app.wrist_pitch.left.outer_pulley.active_top_indicator = app.appdata.wrist_pitch.left.outer_pulley.active_top_indicator;
            app.wrist_pitch.right.inner_pulley.pretensioning = app.appdata.wrist_pitch.right.inner_pulley.pretensioning;
            app.wrist_pitch.right.outer_pulley.pretensioning = app.appdata.wrist_pitch.right.outer_pulley.pretensioning;
            app.wrist_pitch.left.inner_pulley.pretensioning = app.appdata.wrist_pitch.left.inner_pulley.pretensioning;
            app.wrist_pitch.left.outer_pulley.pretensioning = app.appdata.wrist_pitch.left.outer_pulley.pretensioning;
            app.wrist_pitch.right.inner_pulley.pulley_numbers_switch = app.appdata.wrist_pitch.right.inner_pulley.pulley_numbers_switch;
            app.wrist_pitch.right.outer_pulley.pulley_numbers_switch = app.appdata.wrist_pitch.right.outer_pulley.pulley_numbers_switch;
            app.wrist_pitch.left.inner_pulley.pulley_numbers_switch = app.appdata.wrist_pitch.left.inner_pulley.pulley_numbers_switch;
            app.wrist_pitch.left.outer_pulley.pulley_numbers_switch = app.appdata.wrist_pitch.left.outer_pulley.pulley_numbers_switch;
        end
        
        function results = activateEditMode(app)
            %FUNCTION - Takes care of setting Enable and Visible for all
            %Components when entering Edit Mode

            app.JointDropDown.Enable = false;
            app.ShowTendonLengthsButton.Enable = false;
            app.ShowPulleyConfigButton.Enable = false;
            %app.CreateSimConfigFileButton.Enable = false;
            app.current_joint.left_pulley_config = false;
            app.current_joint.right_pulley_config = false;

            app.RadiusActivePulleyEditField.Visible = true;
            app.RadiusActivePulleyEditField.Editable = true;
            app.RadiusActivePulleyLabel.Visible = false;
    
            app.RadiusPassivePulleyEditField.Visible = true;
            app.RadiusPassivePulleyEditField.Editable = true;
            app.RadiusPassivePulleyLabel.Visible = false;
    
            app.minAngleEditField.Visible = true;
            app.minAngleEditField.Editable = true;
            app.minAngleLabel.Visible = false;
    
            app.maxAngleEditField.Visible = true;
            app.maxAngleEditField.Editable = true;
            app.maxAngleLabel.Visible = false;
    
            app.TendonDiameterEditField.Visible = true;
            app.TendonDiameterEditField.Editable = true;
            app.TendonDiameterLabel.Visible = false;
    
            %app.AttachmentPositionsDropDown.Enable = true;
            app.xcoordinateidDropDown.Enable = true;
            app.ycoordinateidDropDown.Enable = true;
    
            app.ActivePulleyXEditField.Visible = true;
            app.ActivePulleyXEditField.Editable = true;
            app.ActivePulleyXLabel.Visible = false;
            app.ActivePulleyYEditField.Visible = true;
            app.ActivePulleyYEditField.Editable = true;
            app.ActivePulleyYLabel.Visible = false;
            app.ActivePulleyZEditField.Visible = true;
            app.ActivePulleyZEditField.Editable = true;
            app.ActivePulleyZLabel.Visible = false;
    
            app.PassivePulleyXEditField.Visible = true;
            app.PassivePulleyXEditField.Editable = true;
            app.PassivePulleyXLabel.Visible = false;
            app.PassivePulleyYEditField.Visible = true;
            app.PassivePulleyYEditField.Editable = true;
            app.PassivePulleyYLabel.Visible = false;
            app.PassivePulleyZEditField.Visible = true;
            app.PassivePulleyZEditField.Editable = true;
            app.PassivePulleyZLabel.Visible = false;
    
            app.TendonUpperXEditField.Visible = true;
            app.TendonUpperXEditField.Editable = true;
            app.TendonUpperXLabel.Visible = false;
            app.TendonUpperYEditField.Visible = true;
            app.TendonUpperYEditField.Editable = true;
            app.TendonUpperYLabel.Visible = false;
            app.TendonUpperZEditField.Visible = true;
            app.TendonUpperZEditField.Editable = true;
            app.TendonUpperZLabel.Visible = false;

            if app.current_joint.number_tendon_attachments == 2
                app.TendonLowerXEditField.Visible = true;
                app.TendonLowerXEditField.Editable = true;
                app.TendonLowerXLabel.Visible = false;
                app.TendonLowerYEditField.Visible = true;
                app.TendonLowerYEditField.Editable = true;
                app.TendonLowerYLabel.Visible = false;
                app.TendonLowerZEditField.Visible = true;
                app.TendonLowerZEditField.Editable = true;
                app.TendonLowerZLabel.Visible = false;
            end

            if app.current_joint.number_pulleys == 3
                app.RadiusMiddlePulleyEditField.Visible = true;
                app.RadiusMiddlePulleyEditField.Editable = true;
                app.RadiusMiddlePulleyLabel.Visible = false;

                app.MiddlePulleyXEditField.Visible = true;
                app.MiddlePulleyXEditField.Editable = true;
                app.MiddlePulleyXLabel.Visible = false;
                app.MiddlePulleyYEditField.Visible = true;
                app.MiddlePulleyYEditField.Editable = true;
                app.MiddlePulleyYLabel.Visible = false;
                app.MiddlePulleyZEditField.Visible = true;
                app.MiddlePulleyZEditField.Editable = true;
                app.MiddlePulleyZLabel.Visible = false;
            end


        end
        
        function results = activateViewMode(app)
            %FUNCTION - Takes care of setting Enable and Visible for all
            %Components when entering View Mode

            app.JointDropDown.Enable = true;
            app.ShowTendonLengthsButton.Enable = true;
            app.ShowPulleyConfigButton.Enable = false;
            %app.CreateSimConfigFileButton.Enable = false;
            app.current_joint.left_pulley_config = false;
            app.current_joint.right_pulley_config = false;

            app.RadiusActivePulleyEditField.Visible = false;
            app.RadiusActivePulleyEditField.Editable = false;
            app.RadiusActivePulleyLabel.Visible = true;

            app.RadiusPassivePulleyEditField.Visible = false;
            app.RadiusPassivePulleyEditField.Editable = false;
            app.RadiusPassivePulleyLabel.Visible = true;

            app.minAngleEditField.Visible = false;
            app.minAngleEditField.Editable = false;
            app.minAngleLabel.Visible = true;

            app.maxAngleEditField.Visible = false;
            app.maxAngleEditField.Editable = false;
            app.maxAngleLabel.Visible = true;

            app.TendonDiameterEditField.Visible = false;
            app.TendonDiameterEditField.Editable = false;
            app.TendonDiameterLabel.Visible = true;

            app.AttachmentPositionsDropDown.Enable = false;
            app.AttachmentPositionsDropDown.Visible = false;
            app.xcoordinateidDropDown.Enable = false;
            app.ycoordinateidDropDown.Enable = false;

            app.ActivePulleyXEditField.Visible = false;
            app.ActivePulleyXEditField.Editable = false;
            app.ActivePulleyXLabel.Visible = true;
            app.ActivePulleyYEditField.Visible = false;
            app.ActivePulleyYEditField.Editable = false;
            app.ActivePulleyYLabel.Visible = true;
            app.ActivePulleyZEditField.Visible = false;
            app.ActivePulleyZEditField.Editable = false;
            app.ActivePulleyZLabel.Visible = true;

            app.PassivePulleyXEditField.Visible = false;
            app.PassivePulleyXEditField.Editable = false;
            app.PassivePulleyXLabel.Visible = true;
            app.PassivePulleyYEditField.Visible = false;
            app.PassivePulleyYEditField.Editable = false;
            app.PassivePulleyYLabel.Visible = true;
            app.PassivePulleyZEditField.Visible = false;
            app.PassivePulleyZEditField.Editable = false;
            app.PassivePulleyZLabel.Visible = true;

            app.TendonUpperXEditField.Visible = false;
            app.TendonUpperXEditField.Editable = false;
            app.TendonUpperXLabel.Visible = true;
            app.TendonUpperYEditField.Visible = false;
            app.TendonUpperYEditField.Editable = false;
            app.TendonUpperYLabel.Visible = true;
            app.TendonUpperZEditField.Visible = false;
            app.TendonUpperZEditField.Editable = false;
            app.TendonUpperZLabel.Visible = true;

            app.TendonLowerXEditField.Visible = false;
            app.TendonLowerXEditField.Editable = false;
            app.TendonLowerXLabel.Visible = true;
            app.TendonLowerYEditField.Visible = false;
            app.TendonLowerYEditField.Editable = false;
            app.TendonLowerYLabel.Visible = true;
            app.TendonLowerZEditField.Visible = false;
            app.TendonLowerZEditField.Editable = false;
            app.TendonLowerZLabel.Visible = true;
            if app.current_joint.number_tendon_attachments == 1
                app.TendonUpperAttachmentPositionLabel.Text = "Tendon Attachment Position:";
                app.TendonLowerAttachmentPositionLabel.Visible = false;
                
                app.TendonLowerXEditFieldLabel.Visible = false;
                app.TendonLowerYEditFieldLabel.Visible = false;
                app.TendonLowerZEditFieldLabel.Visible = false;
                app.TendonLowerXLabel.Visible = false;
                app.TendonLowerYLabel.Visible = false;
                app.TendonLowerZLabel.Visible = false;
            elseif app.current_joint.number_tendon_attachments == 2
                app.TendonUpperAttachmentPositionLabel.Text = "Tendon Upper Attachment Position:";
                app.TendonLowerAttachmentPositionLabel.Visible = true;

                app.TendonLowerXEditFieldLabel.Visible = true;
                app.TendonLowerYEditFieldLabel.Visible = true;
                app.TendonLowerZEditFieldLabel.Visible = true;
            end

            app.RadiusMiddlePulleyEditField.Visible = false;
            app.RadiusMiddlePulleyEditField.Editable = false;
            app.RadiusMiddlePulleyLabel.Visible = true;

            app.MiddlePulleyXEditField.Visible = false;
            app.MiddlePulleyXEditField.Editable = false;
            app.MiddlePulleyXLabel.Visible = true;
            app.MiddlePulleyYEditField.Visible = false;
            app.MiddlePulleyYEditField.Editable = false;
            app.MiddlePulleyYLabel.Visible = true;
            app.MiddlePulleyZEditField.Visible = false;
            app.MiddlePulleyZEditField.Editable = false;
            app.MiddlePulleyZLabel.Visible = true;

            if app.current_joint.number_pulleys == 2
                app.RadiusMiddlePulleyEditFieldLabel.Visible = false;
                app.RadiusMiddlePulleyLabel.Visible = false;
                app.MiddlePulleyPositionLabel.Visible = false;

                app.MiddlePulleyXLabel.Visible = false;
                app.MiddlePulleyYLabel.Visible = false;
                app.MiddlePulleyZLabel.Visible = false;
                app.MiddlePulleyXEditFieldLabel.Visible = false;
                app.MiddlePulleyYEditFieldLabel.Visible = false;
                app.MiddlePulleyZEditFieldLabel.Visible = false;
            elseif app.current_joint.number_pulleys == 3
                app.RadiusMiddlePulleyEditFieldLabel.Visible = true;
                app.MiddlePulleyPositionLabel.Visible = true;

                app.MiddlePulleyXEditFieldLabel.Visible = true;
                app.MiddlePulleyYEditFieldLabel.Visible = true;
                app.MiddlePulleyZEditFieldLabel.Visible = true;
            end
            

        end
        
        
    end
    
    methods (Access = public)
        
        function update_file(app)
            app.save_to_file();
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function LoadData(app)
            fname = 'app_config/AppData.json'; 
            fid = fopen(fname); 
            raw = fread(fid,inf); 
            str = char(raw'); 
            fclose(fid); 
            app.appdata = jsondecode(str);
            app.CreateDataStruct();

            app.JointDropDownValueChanged();
%             app.current_joint = app.shoulder_yaw;
%             app.current_side = app.current_joint.right;

            app.FillElements();
            app.activateViewMode();
            
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            if app.EditButton.Text == "Edit"
                app.EditButton.Text = "Save";

                app.activateEditMode();
                %app.ToggleEditable();
                %app.ShowTendonLengthsButton.Enable = false;
                %app.ShowPulleyConfigButton.Enable = false;
                
            else
                app.EditButton.Text = "Edit";

                %app.ToggleEditable();
                app.save_data();
                app.save_to_file();
                app.FillElements();
                app.activateViewMode();

                %app.ShowTendonLengthsButton.Enable = true;

%                 app.RadiusActivePulleyLabel.Text = string(app.RadiusActivePulleyEditField.Value);
%                 app.RadiusActivePulleyLabel.Visible = true;
%                 app.RadiusActivePulleyEditField.Editable = false;
%                 app.RadiusActivePulleyEditField.Visible = false;
% 
%                 app.TendonDiameterLabel.Text = string(app.TendonDiameterEditField.Value)+" mm";
%                 app.TendonDiameterLabel.Visible = true;
%                 app.TendonDiameterEditField.Editable = false;
%                 app.TendonDiameterEditField.Visible = false;
            end
        end

        % Value changed function: AttachmentPositionsDropDown
        function AttachmentPositionsDropDownValueChanged(app, event)
            value = app.AttachmentPositionsDropDown.Value;
            if value == "1"
                app.TendonUpperAttachmentPositionLabel.Text = "Tendon Attachment Position:";
                app.TendonLowerAttachmentPositionLabel.Visible = false;

                app.TendonLowerXEditFieldLabel.Visible = false;
                app.TendonLowerYEditFieldLabel.Visible = false;
                app.TendonLowerZEditFieldLabel.Visible = false;
                app.TendonLowerXEditField.Visible = false;
                app.TendonLowerYEditField.Visible = false;
                app.TendonLowerZEditField.Visible = false;
                app.TendonLowerXEditField.Enable = false;
                app.TendonLowerYEditField.Enable = false;
                app.TendonLowerZEditField.Enable = false;
                app.TendonLowerXLabel.Visible = false;
                app.TendonLowerYLabel.Visible = false;
                app.TendonLowerZLabel.Visible = false;
            elseif value == "2"
                app.TendonUpperAttachmentPositionLabel.Text = "Tendon Upper Attachment Position:";
                app.TendonLowerAttachmentPositionLabel.Visible = true;

                app.TendonLowerXEditFieldLabel.Visible = true;
                app.TendonLowerYEditFieldLabel.Visible = true;
                app.TendonLowerZEditFieldLabel.Visible = true;
                app.TendonLowerXEditField.Visible = true;
                app.TendonLowerYEditField.Visible = true;
                app.TendonLowerZEditField.Visible = true;
                app.TendonLowerXEditField.Enable = true;
                app.TendonLowerYEditField.Enable = true;
                app.TendonLowerZEditField.Enable = true;
                app.TendonLowerXLabel.Visible = false;
                app.TendonLowerYLabel.Visible = false;
                app.TendonLowerZLabel.Visible = false;

            end
        end

        % Value changed function: JointDropDown
        function JointDropDownValueChanged(app, event)
            %app.save_to_file();
            
            value = app.JointDropDown.Value;
            if app.JointDropDown.Value == "Shoulder Yaw"
                app.current_joint = app.shoulder_yaw;
            elseif app.JointDropDown.Value == "Shoulder Roll"
                app.current_joint = app.shoulder_roll;
            elseif app.JointDropDown.Value == "Shoulder Pitch"
                app.current_joint = app.shoulder_pitch;
            elseif app.JointDropDown.Value == "Upperarm Roll"
                app.current_joint = app.upperarm_roll;
            elseif app.JointDropDown.Value == "Elbow"
                app.current_joint = app.elbow;
            elseif app.JointDropDown.Value == "Wrist Pitch"
                app.current_joint = app.wrist_pitch;
            end

            if app.JointSideDropDown.Value == "Right"
                app.current_side = app.current_joint.right;
            else
                app.current_side = app.current_joint.left;
            end
            
            app.FillElements();
            app.activateViewMode();

            app.ShowPulleyConfigButton.Enable = false;
            app.current_joint.left_pulley_config = false;
            app.current_joint.right_pulley_config = false;
        end

        % Value changed function: JointSideDropDown
        function JointSideDropDownValueChanged(app, event)
            prev_value = event.PreviousValue;
            value = app.JointSideDropDown.Value;
            
            app.ShowPulleyConfigButton.Enable = false;

            app.save_joint_side();
            
            if value == "Left"
                app.current_side = app.current_joint.left;
            else
                app.current_side = app.current_joint.right;
            end
            
            app.fillSideElements();
            
        end

        % Button pushed function: ShowTendonLengthsButton
        function ShowTendonLengthsButtonPushed(app, event)
            app.JointDropDown.Enable = false;
            app.ShowTendonLengthsButton.Enable = false;
            app.EditButton.Enable = false;
            app.JointSideDropDown.Enable = false;
            app.TendonLengthApp = TendonLength(app, app.current_joint, app.current_side);
            app.ShowPulleyConfigButton.Enable = true;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.TendonLengthApp);
            delete(app.PulleyConfigApp);
            delete(app.ExportConfigApp);
            app.save_to_file();
            delete(app)
            
        end

        % Button pushed function: ShowPulleyConfigButton
        function ShowPulleyConfigButtonPushed(app, event)
            app.JointDropDown.Enable = false;
            app.ShowTendonLengthsButton.Enable = false;
            app.EditButton.Enable = false;
            app.JointSideDropDown.Enable = false;
            app.ShowPulleyConfigButton.Enable = false;
            app.PulleyConfigApp = PulleyConfig(app, app.current_joint, app.current_side);
            if app.JointSideDropDown.Value == "Right"
                app.current_joint.right_pulley_config = true;
            elseif app.JointSideDropDown.Value == "Left"
                app.current_joint.left_pulley_config = true;
            end

%             if app.current_joint.right_pulley_config == true && app.current_joint.left_pulley_config == true
%                 app.CreateSimConfigFileButton.Enable = true;
%             end
        end

        % Button pushed function: CreateSimConfigFileButton
        function CreateSimConfigFileButtonPushed(app, event)
            if app.current_joint.left_pulley_config == true && app.current_joint.right_pulley_config == true
                %result = app.CreateSimConfigFile();
                app.ExportConfigApp = ExportConfig(app, app.current_joint);
                %if result == true
                 %   f = msgbox("The operation has been successfully completed","Success");
                %end
            else
                msgbox("Make sure to first calculate the pulley configurations for both joint sides","Error","error");

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.JointDropDownLabel = uilabel(app.UIFigure);
            app.JointDropDownLabel.Text = 'Joint:';
            app.JointDropDown = uidropdown(app.UIFigure);
            app.JointDropDown.Items = {'Shoulder Yaw', 'Shoulder Roll', 'Shoulder Pitch', 'Upperarm Roll', 'Elbow', 'Wrist Pitch'};
            app.JointDropDown.ValueChangedFcn = createCallbackFcn(app, @JointDropDownValueChanged, true);
            app.JointDropDown.Value = 'Shoulder Yaw';
            app.EditButton = uibutton(app.UIFigure, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.RadiusActivePulleyLabel_2 = uilabel(app.UIFigure);
            app.RadiusActivePulleyLabel_2.Text = 'Radius Active Pulley:';
            app.RadiusActivePulleyEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusActivePulleyEditField.Editable = 'off';
            app.RadiusActivePulleyEditField.Visible = 'off';
            app.RadiusActivePulleyEditField.Value = 99.52;
            app.RadiusActivePulleyLabel = uilabel(app.UIFigure);
            app.RadiusActivePulleyLabel.Text = '99.52';
            app.ShowTendonLengthsButton = uibutton(app.UIFigure, 'push');
            app.ShowTendonLengthsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTendonLengthsButtonPushed, true);
            app.ShowTendonLengthsButton.Text = 'Show Tendon Lengths';
            app.ShowPulleyConfigButton = uibutton(app.UIFigure, 'push');
            app.ShowPulleyConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPulleyConfigButtonPushed, true);
            app.ShowPulleyConfigButton.Text = 'Show Pulley Config';
            app.PulleyPositionPanel = uipanel(app.UIFigure);
            app.ActivePulleyPositionLabel = uilabel(app.PulleyPositionPanel);
            app.ActivePulleyPositionLabel.Text = 'Active Pulley Position:';
            app.ActivePulleyXEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.ActivePulleyXEditFieldLabel.Text = 'x';
            app.ActivePulleyXEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.ActivePulleyXEditField.ValueDisplayFormat = '%.3f';
            app.ActivePulleyXEditField.Editable = 'off';
            app.ActivePulleyXEditField.Value = 128.267;
            app.ActivePulleyYEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.ActivePulleyYEditFieldLabel.Text = 'y';
            app.ActivePulleyYEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.ActivePulleyYEditField.ValueDisplayFormat = '%.3f';
            app.ActivePulleyYEditField.Editable = 'off';
            app.ActivePulleyYEditField.Value = 100.02;
            app.ActivePulleyZEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.ActivePulleyZEditFieldLabel.Text = 'z';
            app.ActivePulleyZEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.ActivePulleyZEditField.ValueDisplayFormat = '%.3f';
            app.ActivePulleyZEditField.Editable = 'off';
            app.JointSideDropDownLabel = uilabel(app.PulleyPositionPanel);
            app.JointSideDropDownLabel.Text = 'Joint Side:';
            app.JointSideDropDown = uidropdown(app.PulleyPositionPanel);
            app.JointSideDropDown.Items = {'Right', 'Left'};
            app.JointSideDropDown.ValueChangedFcn = createCallbackFcn(app, @JointSideDropDownValueChanged, true);
            app.JointSideDropDown.Value = 'Right';
            app.PassivePulleyPositionLabel = uilabel(app.PulleyPositionPanel);
            app.PassivePulleyPositionLabel.Text = 'Passive Pulley Position:';
            app.PassivePulleyXEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.PassivePulleyXEditFieldLabel.Text = 'x';
            app.PassivePulleyXEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.PassivePulleyXEditField.ValueDisplayFormat = '%.3f';
            app.PassivePulleyXEditField.Editable = 'off';
            app.PassivePulleyYEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.PassivePulleyYEditFieldLabel.Text = 'y';
            app.PassivePulleyYEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.PassivePulleyYEditField.ValueDisplayFormat = '%.3f';
            app.PassivePulleyYEditField.Editable = 'off';
            app.PassivePulleyZEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.PassivePulleyZEditFieldLabel.Text = 'z';
            app.PassivePulleyZEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.PassivePulleyZEditField.ValueDisplayFormat = '%.3f';
            app.PassivePulleyZEditField.Editable = 'off';
            app.TendonUpperAttachmentPositionLabel = uilabel(app.PulleyPositionPanel);
            app.TendonUpperAttachmentPositionLabel.Text = 'Tendon Upper Attachment Position:';
            app.TendonLowerAttachmentPositionLabel = uilabel(app.PulleyPositionPanel);
            app.TendonLowerAttachmentPositionLabel.Text = 'Tendon Lower Attachment Position:';
            app.TendonUpperXEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.TendonUpperXEditFieldLabel.Text = 'x';
            app.TendonUpperXEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.TendonUpperXEditField.ValueDisplayFormat = '%.3f';
            app.TendonUpperXEditField.Editable = 'off';
            app.TendonUpperYEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.TendonUpperYEditFieldLabel.Text = 'y';
            app.TendonUpperYEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.TendonUpperYEditField.ValueDisplayFormat = '%.3f';
            app.TendonUpperYEditField.Editable = 'off';
            app.TendonUpperZEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.TendonUpperZEditFieldLabel.Text = 'z';
            app.TendonUpperZEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.TendonUpperZEditField.ValueDisplayFormat = '%.3f';
            app.TendonUpperZEditField.Editable = 'off';
            app.TendonLowerXEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.TendonLowerXEditFieldLabel.Text = 'x';
            app.TendonLowerXEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.TendonLowerXEditField.ValueDisplayFormat = '%.3f';
            app.TendonLowerXEditField.Editable = 'off';
            app.TendonLowerYEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.TendonLowerYEditFieldLabel.Text = 'y';
            app.TendonLowerYEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.TendonLowerYEditField.ValueDisplayFormat = '%.3f';
            app.TendonLowerYEditField.Editable = 'off';
            app.TendonLowerZEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.TendonLowerZEditFieldLabel.Text = 'z';
            app.TendonLowerZEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.TendonLowerZEditField.ValueDisplayFormat = '%.3f';
            app.TendonLowerZEditField.Editable = 'off';
            app.AttachmentPositionsDropDownLabel = uilabel(app.PulleyPositionPanel);
            app.AttachmentPositionsDropDownLabel.Visible = 'off';
            app.AttachmentPositionsDropDownLabel.Text = 'Attachment Positions:';
            app.AttachmentPositionsDropDown = uidropdown(app.PulleyPositionPanel);
            app.AttachmentPositionsDropDown.Items = {'1', '2'};
            app.AttachmentPositionsDropDown.ValueChangedFcn = createCallbackFcn(app, @AttachmentPositionsDropDownValueChanged, true);
            app.AttachmentPositionsDropDown.Enable = 'off';
            app.AttachmentPositionsDropDown.Value = '1';
            app.xcoordinateidDropDownLabel = uilabel(app.PulleyPositionPanel);
            app.xcoordinateidDropDownLabel.Text = 'x coordinate id:';
            app.xcoordinateidDropDown = uidropdown(app.PulleyPositionPanel);
            app.xcoordinateidDropDown.Items = {'x', 'y', 'z', '-x', '-y', '-z'};
            app.xcoordinateidDropDown.Enable = 'off';
            app.xcoordinateidDropDown.Value = '-x';
            app.ycoordinateidDropDownLabel = uilabel(app.PulleyPositionPanel);
            app.ycoordinateidDropDownLabel.Text = 'y coordinate id:';
            app.ycoordinateidDropDown = uidropdown(app.PulleyPositionPanel);
            app.ycoordinateidDropDown.Items = {'x', 'y', 'z', '-x', '-y', '-z'};
            app.ycoordinateidDropDown.Enable = 'off';
            app.ycoordinateidDropDown.Value = 'y';
            app.ActivePulleyXLabel = uilabel(app.PulleyPositionPanel);
            app.ActivePulleyXLabel.Text = '128.267';
            app.ActivePulleyYLabel = uilabel(app.PulleyPositionPanel);
            app.ActivePulleyYLabel.Text = '100.020';
            app.ActivePulleyZLabel = uilabel(app.PulleyPositionPanel);
            app.ActivePulleyZLabel.Text = '0.000';
            app.PassivePulleyXLabel = uilabel(app.PulleyPositionPanel);
            app.PassivePulleyXLabel.Text = '0.000';
            app.PassivePulleyYLabel = uilabel(app.PulleyPositionPanel);
            app.PassivePulleyYLabel.Text = '0.000';
            app.PassivePulleyZLabel = uilabel(app.PulleyPositionPanel);
            app.PassivePulleyZLabel.Text = '0.000';
            app.TendonUpperXLabel = uilabel(app.PulleyPositionPanel);
            app.TendonUpperXLabel.Text = '0.000';
            app.TendonUpperYLabel = uilabel(app.PulleyPositionPanel);
            app.TendonUpperYLabel.Text = '0.000';
            app.TendonUpperZLabel = uilabel(app.PulleyPositionPanel);
            app.TendonUpperZLabel.Text = '0.000';
            app.TendonLowerXLabel = uilabel(app.PulleyPositionPanel);
            app.TendonLowerXLabel.Text = '0.000';
            app.TendonLowerYLabel = uilabel(app.PulleyPositionPanel);
            app.TendonLowerYLabel.Text = '0.000';
            app.TendonLowerZLabel = uilabel(app.PulleyPositionPanel);
            app.TendonLowerZLabel.Text = '0.000';
            app.MiddlePulleyPositionLabel = uilabel(app.PulleyPositionPanel);
            app.MiddlePulleyPositionLabel.Text = 'Middle Pulley Position:';
            app.MiddlePulleyXLabel = uilabel(app.PulleyPositionPanel);
            app.MiddlePulleyXLabel.Text = '0.000';
            app.MiddlePulleyYLabel = uilabel(app.PulleyPositionPanel);
            app.MiddlePulleyYLabel.Text = '0.000';
            app.MiddlePulleyZLabel = uilabel(app.PulleyPositionPanel);
            app.MiddlePulleyZLabel.Text = '0.000';
            app.MiddlePulleyXEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.MiddlePulleyXEditFieldLabel.Text = 'x';
            app.MiddlePulleyXEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.MiddlePulleyXEditField.ValueDisplayFormat = '%.3f';
            app.MiddlePulleyXEditField.Editable = 'off';
            app.MiddlePulleyYEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.MiddlePulleyYEditFieldLabel.Text = 'y';
            app.MiddlePulleyYEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.MiddlePulleyYEditField.ValueDisplayFormat = '%.3f';
            app.MiddlePulleyYEditField.Editable = 'off';
            app.MiddlePulleyZEditFieldLabel = uilabel(app.PulleyPositionPanel);
            app.MiddlePulleyZEditFieldLabel.Text = 'z';
            app.MiddlePulleyZEditField = uieditfield(app.PulleyPositionPanel, 'numeric');
            app.MiddlePulleyZEditField.ValueDisplayFormat = '%.3f';
            app.MiddlePulleyZEditField.Editable = 'off';
            app.RadiusPassivePulleyLabel = uilabel(app.UIFigure);
            app.RadiusPassivePulleyLabel.Text = '99.52';
            app.RadiusPassivePulleyEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusPassivePulleyEditFieldLabel.Text = 'Radius Passive Pulley:';
            app.RadiusPassivePulleyEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusPassivePulleyEditField.Editable = 'off';
            app.RadiusPassivePulleyEditField.Visible = 'off';
            app.RadiusPassivePulleyEditField.Value = 99.52;
            app.minAngleLabel = uilabel(app.UIFigure);
            app.minAngleLabel.Text = '99.52';
            app.minAngleEditFieldLabel = uilabel(app.UIFigure);
            app.minAngleEditFieldLabel.Text = 'minAngle:';
            app.minAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.minAngleEditField.Editable = 'off';
            app.minAngleEditField.Visible = 'off';
            app.minAngleEditField.Value = 99.52;
            app.maxAngleLabel = uilabel(app.UIFigure);
            app.maxAngleLabel.Text = '99.52';
            app.maxAngleEditFieldLabel = uilabel(app.UIFigure);
            app.maxAngleEditFieldLabel.Text = 'maxAngle:';
            app.maxAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxAngleEditField.Editable = 'off';
            app.maxAngleEditField.Visible = 'off';
            app.maxAngleEditField.Value = 99.52;
            app.TendonDiameterLabel = uilabel(app.UIFigure);
            app.TendonDiameterLabel.Text = '3 mm';
            app.TendonDiameterEditFieldLabel = uilabel(app.UIFigure);
            app.TendonDiameterEditFieldLabel.Text = 'Tendon Diameter:';
            app.TendonDiameterEditField = uieditfield(app.UIFigure, 'numeric');
            app.TendonDiameterEditField.Editable = 'off';
            app.TendonDiameterEditField.Visible = 'off';
            app.TendonDiameterEditField.Value = 3;
            app.RadiusMiddlePulleyEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusMiddlePulleyEditFieldLabel.Text = 'Radius Middle Pulley:';
            app.RadiusMiddlePulleyEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusMiddlePulleyEditField.Value = 3;
            app.RadiusMiddlePulleyLabel = uilabel(app.UIFigure);
            app.RadiusMiddlePulleyLabel.Text = '3 mm';
            app.CreateSimConfigFileButton = uibutton(app.UIFigure, 'push');
            app.CreateSimConfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSimConfigFileButtonPushed, true);
            app.CreateSimConfigFileButton.Text = 'Create Sim Config File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tendon_pulley

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @LoadData)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end